classdef CeuImgProcApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadimageMenu                   matlab.ui.container.Menu
        UpdateimagelistMenu             matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        LecturesMenu                    matlab.ui.container.Menu
        Lecture02DigitalimagesMenu      matlab.ui.container.Menu
        SeparateRGBchannelsMenu         matlab.ui.container.Menu
        MergeRGBchannelsMenu            matlab.ui.container.Menu
        ShowcolormapMenu                matlab.ui.container.Menu
        InspectpixelvaluesMenu          matlab.ui.container.Menu
        Lecture03ImagehistogramsMenu    matlab.ui.container.Menu
        ComputeintensityMenu            matlab.ui.container.Menu
        HistogramMenu                   matlab.ui.container.Menu
        PDFandCDFMenu                   matlab.ui.container.Menu
        ShowintensitylayersMenu         matlab.ui.container.Menu
        PDFselectorMenu                 matlab.ui.container.Menu
        QuantizationMenu                matlab.ui.container.Menu
        Lecture04PointprocessMenu       matlab.ui.container.Menu
        ImagesubtractionMenu            matlab.ui.container.Menu
        IncreaseordecreasecontrastMenu  matlab.ui.container.Menu
        IncreaseordecreasebrightnessMenu  matlab.ui.container.Menu
        IncreaseordecreasegammaMenu     matlab.ui.container.Menu
        AnalyticalfunctionMenu          matlab.ui.container.Menu
        FunctionperchannelMenu          matlab.ui.container.Menu
        HistogramequalizationMenu       matlab.ui.container.Menu
        HistogrammatchingMenu           matlab.ui.container.Menu
        RGBdisplaypatternMenu           matlab.ui.container.Menu
        Lecture05ColorperceptionMenu    matlab.ui.container.Menu
        IncreaseDecreasesaturationMenu  matlab.ui.container.Menu
        PixelizationbychannelMenu       matlab.ui.container.Menu
        CMYKprintpatternsMenu           matlab.ui.container.Menu
        FoveatransformMenu              matlab.ui.container.Menu
        OpticalIllusionsMenu            matlab.ui.container.Menu
        Lecture06ColorcorrectionMenu    matlab.ui.container.Menu
        ColorcubeexplorerMenu           matlab.ui.container.Menu
        HSVConversionMenu               matlab.ui.container.Menu
        ColorcorrectionMenu             matlab.ui.container.Menu
        Lecture07FouriertransformMenu   matlab.ui.container.Menu
        DWavesMenu                      matlab.ui.container.Menu
        PlotFTMenu                      matlab.ui.container.Menu
        PlotFTandnoiseMenu              matlab.ui.container.Menu
        Lecture08ConvolutionMenu        matlab.ui.container.Menu
        SpatialconvolutiondefinitionMenu  matlab.ui.container.Menu
        SpatialconvolutionMenu          matlab.ui.container.Menu
        EdgedetectionMenu               matlab.ui.container.Menu
        BlurEdgedetectionMenu           matlab.ui.container.Menu
        Lecture09FrequencyfiltersMenu   matlab.ui.container.Menu
        IdealfilterMenu                 matlab.ui.container.Menu
        GaussianfilterMenu              matlab.ui.container.Menu
        SharpeningbyconvolutionMenu     matlab.ui.container.Menu
        Lecture10SharpeningMenu         matlab.ui.container.Menu
        DirectionalderivativeMenu       matlab.ui.container.Menu
        SharpeningandnoiseMenu          matlab.ui.container.Menu
        SharpeninginFourierMenu         matlab.ui.container.Menu
        Lecture11PixelationandquantizationMenu  matlab.ui.container.Menu
        PixelationMenu                  matlab.ui.container.Menu
        SteganographyMenu               matlab.ui.container.Menu
        DitheringMenu                   matlab.ui.container.Menu
        Lecture12SamplingandaliasingMenu  matlab.ui.container.Menu
        DownsamplingMenu                matlab.ui.container.Menu
        Lecture13ImageresizingMenu      matlab.ui.container.Menu
        ReinterpolationMenu             matlab.ui.container.Menu
        Lecture14WarpingandrotationMenu  matlab.ui.container.Menu
        AffinetransformMenu             matlab.ui.container.Menu
        FisheyetransformMenu            matlab.ui.container.Menu
        WarpingMenu                     matlab.ui.container.Menu
        Lecture15UncorrelatednoiseMenu  matlab.ui.container.Menu
        NoiseexplorerMenu               matlab.ui.container.Menu
        PlotFTandnoiseMenu_2            matlab.ui.container.Menu
        ImageformationMenu              matlab.ui.container.Menu
        WienerfilterMenu                matlab.ui.container.Menu
        Lecture16CorrelatednoiseMenu    matlab.ui.container.Menu
        NotchfilterMenu                 matlab.ui.container.Menu
        Lecture17MedianfiltersMenu      matlab.ui.container.Menu
        MedianfilterMenu                matlab.ui.container.Menu
        ColormedianfilterMenu           matlab.ui.container.Menu
        Lecture18BinarymorphologyMenu   matlab.ui.container.Menu
        DilationErosionOpeningClosingMenu_2  matlab.ui.container.Menu
        ReconstructionMenu              matlab.ui.container.Menu
        SkeletonMenu                    matlab.ui.container.Menu
        Lecture19GraymorphologyMenu     matlab.ui.container.Menu
        DilationErosionOpeningClosingMenu  matlab.ui.container.Menu
        TopHatandBotHatMenu             matlab.ui.container.Menu
        ReconstructionMenu_2            matlab.ui.container.Menu
        Lecture20JpegcompressionMenu    matlab.ui.container.Menu
        QualitycomparisonMenu           matlab.ui.container.Menu
        JpegcompressionMenu             matlab.ui.container.Menu
        SelectimageDropDownLabel        matlab.ui.control.Label
        SelectimageDropDown             matlab.ui.control.DropDown
    end

    
    methods (Access = public)
        function updateImageList(app)
            figHandles = findobj('Type', 'figure');
            if length(figHandles)>0
                app.SelectimageDropDown.Items = {};
                for i=1:length(figHandles)
                    app.SelectimageDropDown.Items{i} = figHandles(i).Name;
                end
            end
        end
    end
    
    methods (Access = public)
        function h = getFigureHandler(app)
            h= app.getFigureHandlerByName(app.SelectimageDropDown.Value);
        end
    end
    
    methods (Access = public)
        
        function h = getFigureHandlerByName(app, selectedName)
            figHandles = findobj('Type', 'figure');
            for i=1:length(figHandles)
                if strcmp(figHandles(i).Name,selectedName)
                    h=figHandles(i);
                    return
                end
            end
            h = -1;
            return            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadimageMenu
        function LoadimageMenuSelected(app, event)
            [file,path] = uigetfile('*.*','Select an Image');
            if ~isequal(file,0)
               fn=fullfile(path,file);
               [I,map] = imread(fn);
               figure('Name',file);
               imshow(I,map);
               
               app.updateImageList();
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            close all
            closereq
        end

        % Menu selected function: SeparateRGBchannelsMenu
        function SeparateRGBchannelsMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_SplitRGBChannelsApp(app,app.getFigureHandler());
            end
        end

        % Menu selected function: UpdateimagelistMenu
        function UpdateimagelistMenuSelected(app, event)
            app.updateImageList();
        end

        % Menu selected function: MergeRGBchannelsMenu
        function MergeRGBchannelsMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_MergeRGBChannelsApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: ComputeintensityMenu
        function ComputeintensityMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_ComputeIntensityApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: PDFandCDFMenu
        function PDFandCDFMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_ComputePdfAndCdfApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: IncreaseordecreasecontrastMenu
        function IncreaseordecreasecontrastMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_IncreaseDecreaseContrastApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: ShowcolormapMenu
        function ShowcolormapMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_ShowColormapApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: ShowintensitylayersMenu
        function ShowintensitylayersMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_ShowIntensityLayersApp(app,app.getFigureHandler());
            end                                    
        end

        % Menu selected function: SpatialconvolutiondefinitionMenu
        function SpatialconvolutiondefinitionMenuSelected(app, event)
            CeuL8_KernelSpatialConvolutionApp(app);
        end

        % Menu selected function: HistogrammatchingMenu
        function HistogrammatchingMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_HistogramMatchingApp(app,app.getFigureHandler());
            end                                                
        end

        % Menu selected function: PixelationMenu
        function PixelationMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL11_PixelizationApp(app,app.getFigureHandler());
            end                                                
        end

        % Menu selected function: PDFselectorMenu
        function PDFselectorMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_2ValuesMappingApp(app,app.getFigureHandler());
            end                                                            
        end

        % Menu selected function: MedianfilterMenu
        function MedianfilterMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL17_MedianFilterApp(app,app.getFigureHandler());
            end                                                                        
        end

        % Menu selected function: QuantizationMenu
        function QuantizationMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_QuantizeApp(app,app.getFigureHandler());
            end                                                                                    
        end

        % Menu selected function: HSVConversionMenu
        function HSVConversionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL6_HSVconversionApp(app,app.getFigureHandler());
            end                                                                                    
        end

        % Menu selected function: TopHatandBotHatMenu
        function TopHatandBotHatMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL19_bothat_tophatApp(app,app.getFigureHandler());
            end                                                                                    
        end

        % Menu selected function: IncreaseDecreasesaturationMenu
        function IncreaseDecreasesaturationMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL5_SaturationChangesApp(app,app.getFigureHandler());
            end                                                                                                
        end

        % Menu selected function: ReconstructionMenu
        function ReconstructionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL18_BinaryReconstructionApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: SharpeningbyconvolutionMenu
        function SharpeningbyconvolutionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL9_SharpeningbyconvolutionApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: GaussianfilterMenu
        function GaussianfilterMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL9_GaussianFilterApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: PlotFTMenu
        function PlotFTMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL7_PlotFTImage(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: ImagesubtractionMenu
        function ImagesubtractionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_ComputeDifferencesBetweenImgsApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: FisheyetransformMenu
        function FisheyetransformMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL14_ImageFishEyeRotationApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: QualitycomparisonMenu
        function QualitycomparisonMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_JPEG100vs0qualityApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: AffinetransformMenu
        function AffinetransformMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL14_affine2DtransformationApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: DirectionalderivativeMenu
        function DirectionalderivativeMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL10_DirectionalDerivativeApp(app,app.getFigureHandler());
            end            
        end

        % Menu selected function: HistogramequalizationMenu
        function HistogramequalizationMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_HistogramEqualizationApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: SpatialconvolutionMenu
        function SpatialconvolutionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL8_SpatialConvolutionByShiftingMultiplyAddApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: SharpeningandnoiseMenu
        function SharpeningandnoiseMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL10_SharpeningAndNoiseApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: DownsamplingMenu
        function DownsamplingMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL12_DownsampleApp(app,app.getFigureHandler());
            end                        
        end

        % Menu selected function: PlotFTandnoiseMenu, 
        % PlotFTandnoiseMenu_2
        function PlotFTandnoiseMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL14_PowerSpectraApp(app,app.getFigureHandler());
            end                        
        end

        % Callback function
        function PlotFTandnoiseMenu_2Selected(app, event)
            
        end

        % Menu selected function: IncreaseordecreasegammaMenu
        function IncreaseordecreasegammaMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_IncreaseDecreaseGamma(app,app.getFigureHandler());
            end  
        end

        % Menu selected function: NoiseexplorerMenu
        function NoiseexplorerMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL15_MultipleNoiseApp(app,app.getFigureHandler());
            end  
        end

        % Menu selected function: RGBdisplaypatternMenu
        function RGBdisplaypatternMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_RGBDisplayPatternsApp(app,app.getFigureHandler());
            end              
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL3_HistogramComputationforGrayandColorimagesApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: SteganographyMenu
        function SteganographyMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL11_SteganographyApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: InspectpixelvaluesMenu
        function InspectpixelvaluesMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_InspectPixelValuesApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: DitheringMenu
        function DitheringMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL11_DitheringApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: PixelizationbychannelMenu
        function PixelizationbychannelMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL5_PixelizationChannelsApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: JpegcompressionMenu
        function JpegcompressionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL20_JPEGatDifferentQualitiesApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: EdgedetectionMenu
        function EdgedetectionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL2_EdgesApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: ColorcubeexplorerMenu
        function ColorcubeexplorerMenuSelected(app, event)
            CeuL6_ColorCubeApp(app);
        end

        % Menu selected function: AnalyticalfunctionMenu
        function AnalyticalfunctionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_AnalyticalFunctionLUTApp(app,app.getFigureHandler());
            end                          
        end

        % Menu selected function: SkeletonMenu
        function SkeletonMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL18_SkeletonsFunctionApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: ReconstructionMenu_2
        function ReconstructionMenu_2Selected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL19_GrayReconstructionApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: ImageformationMenu
        function ImageformationMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL15_ImageFormationPSFApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: WienerfilterMenu
        function WienerfilterMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL15_WienerFilteringApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: SharpeninginFourierMenu
        function SharpeninginFourierMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL10_SharpeningLinearQuadraticApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: IncreaseordecreasebrightnessMenu
        function IncreaseordecreasebrightnessMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_IncreaseDecreaseBrightnessApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: NotchfilterMenu
        function NotchfilterMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL16_NotchFilterApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: IdealfilterMenu
        function IdealfilterMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL9_IdealFilterApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: BlurEdgedetectionMenu
        function BlurEdgedetectionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL8_Blur_EdgeDetectortApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: ReinterpolationMenu
        function ReinterpolationMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL13_DownsamplingUpsamplingApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: CMYKprintpatternsMenu
        function CMYKprintpatternsMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL5_CMYKEncodingPatternsApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: FoveatransformMenu
        function FoveatransformMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_FoveaTransformApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: DWavesMenu
        function DWavesMenuSelected(app, event)
            CeuL7_2DWaveApp(app);
        end

        % Callback function
        function WarpingMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL14_WarpingApp(app,app.getFigureHandler());
            end                                      
        end

        % Menu selected function: WarpingMenu
        function WarpingMenuSelected2(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL14_WarpingApp(app,app.getFigureHandler());
            end                                                  
        end

        % Menu selected function: DilationErosionOpeningClosingMenu
        function DilationErosionOpeningClosingMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL19_DilationErisionOpeningClosingApp(app,app.getFigureHandler());
            end                                                              
        end

        % Menu selected function: ColormedianfilterMenu
        function ColormedianfilterMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL17_MedianFilterApp(app,app.getFigureHandler());
            end                                                                          
        end

        % Menu selected function: ColorcorrectionMenu
        function ColorcorrectionMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL6_MatrixCorrectionApp(app,app.getFigureHandler());
            end                                                                                      
        end

        % Menu selected function: FunctionperchannelMenu
        function FunctionperchannelMenuSelected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL4_Separate_function_chanelApp(app,app.getFigureHandler());
            end
        end

        % Menu selected function: 
        % DilationErosionOpeningClosingMenu_2
        function DilationErosionOpeningClosingMenu_2Selected(app, event)
            if length(findobj('Type', 'figure'))>0
                CeuL18_Binary_images_operations(app,app.getFigureHandler());
            end
        end

        % Menu selected function: OpticalIllusionsMenu
        function OpticalIllusionsMenuSelected(app, event)
            CeuL5_OpticalIllusionsApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadimageMenu = uimenu(app.FileMenu);
            app.LoadimageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadimageMenuSelected, true);
            app.LoadimageMenu.Text = 'Load image';
            app.UpdateimagelistMenu = uimenu(app.FileMenu);
            app.UpdateimagelistMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateimagelistMenuSelected, true);
            app.UpdateimagelistMenu.Text = 'Update image list';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.LecturesMenu = uimenu(app.UIFigure);
            app.LecturesMenu.Text = 'Lectures';
            app.Lecture02DigitalimagesMenu = uimenu(app.LecturesMenu);
            app.Lecture02DigitalimagesMenu.Text = 'Lecture 02. Digital images';
            app.SeparateRGBchannelsMenu = uimenu(app.Lecture02DigitalimagesMenu);
            app.SeparateRGBchannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @SeparateRGBchannelsMenuSelected, true);
            app.SeparateRGBchannelsMenu.Text = 'Separate RGB channels';
            app.MergeRGBchannelsMenu = uimenu(app.Lecture02DigitalimagesMenu);
            app.MergeRGBchannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeRGBchannelsMenuSelected, true);
            app.MergeRGBchannelsMenu.Text = 'Merge RGB channels';
            app.ShowcolormapMenu = uimenu(app.Lecture02DigitalimagesMenu);
            app.ShowcolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowcolormapMenuSelected, true);
            app.ShowcolormapMenu.Text = 'Show colormap';
            app.InspectpixelvaluesMenu = uimenu(app.Lecture02DigitalimagesMenu);
            app.InspectpixelvaluesMenu.MenuSelectedFcn = createCallbackFcn(app, @InspectpixelvaluesMenuSelected, true);
            app.InspectpixelvaluesMenu.Text = 'Inspect pixel values';
            app.Lecture03ImagehistogramsMenu = uimenu(app.LecturesMenu);
            app.Lecture03ImagehistogramsMenu.Text = 'Lecture 03. Image histograms';
            app.ComputeintensityMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.ComputeintensityMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputeintensityMenuSelected, true);
            app.ComputeintensityMenu.Text = 'Compute intensity';
            app.HistogramMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.PDFandCDFMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.PDFandCDFMenu.MenuSelectedFcn = createCallbackFcn(app, @PDFandCDFMenuSelected, true);
            app.PDFandCDFMenu.Text = 'PDF and CDF';
            app.ShowintensitylayersMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.ShowintensitylayersMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowintensitylayersMenuSelected, true);
            app.ShowintensitylayersMenu.Text = 'Show intensity layers';
            app.PDFselectorMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.PDFselectorMenu.MenuSelectedFcn = createCallbackFcn(app, @PDFselectorMenuSelected, true);
            app.PDFselectorMenu.Text = 'PDF selector';
            app.QuantizationMenu = uimenu(app.Lecture03ImagehistogramsMenu);
            app.QuantizationMenu.MenuSelectedFcn = createCallbackFcn(app, @QuantizationMenuSelected, true);
            app.QuantizationMenu.Text = 'Quantization';
            app.Lecture04PointprocessMenu = uimenu(app.LecturesMenu);
            app.Lecture04PointprocessMenu.Text = 'Lecture 04. Point process';
            app.ImagesubtractionMenu = uimenu(app.Lecture04PointprocessMenu);
            app.ImagesubtractionMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagesubtractionMenuSelected, true);
            app.ImagesubtractionMenu.Text = 'Image subtraction';
            app.IncreaseordecreasecontrastMenu = uimenu(app.Lecture04PointprocessMenu);
            app.IncreaseordecreasecontrastMenu.MenuSelectedFcn = createCallbackFcn(app, @IncreaseordecreasecontrastMenuSelected, true);
            app.IncreaseordecreasecontrastMenu.Text = 'Increase or decrease contrast';
            app.IncreaseordecreasebrightnessMenu = uimenu(app.Lecture04PointprocessMenu);
            app.IncreaseordecreasebrightnessMenu.MenuSelectedFcn = createCallbackFcn(app, @IncreaseordecreasebrightnessMenuSelected, true);
            app.IncreaseordecreasebrightnessMenu.Text = 'Increase or decrease brightness';
            app.IncreaseordecreasegammaMenu = uimenu(app.Lecture04PointprocessMenu);
            app.IncreaseordecreasegammaMenu.MenuSelectedFcn = createCallbackFcn(app, @IncreaseordecreasegammaMenuSelected, true);
            app.IncreaseordecreasegammaMenu.Text = 'Increase or decrease gamma';
            app.AnalyticalfunctionMenu = uimenu(app.Lecture04PointprocessMenu);
            app.AnalyticalfunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalyticalfunctionMenuSelected, true);
            app.AnalyticalfunctionMenu.Text = 'Analytical function';
            app.FunctionperchannelMenu = uimenu(app.Lecture04PointprocessMenu);
            app.FunctionperchannelMenu.MenuSelectedFcn = createCallbackFcn(app, @FunctionperchannelMenuSelected, true);
            app.FunctionperchannelMenu.Text = 'Function per channel';
            app.HistogramequalizationMenu = uimenu(app.Lecture04PointprocessMenu);
            app.HistogramequalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramequalizationMenuSelected, true);
            app.HistogramequalizationMenu.Text = 'Histogram equalization';
            app.HistogrammatchingMenu = uimenu(app.Lecture04PointprocessMenu);
            app.HistogrammatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogrammatchingMenuSelected, true);
            app.HistogrammatchingMenu.Text = 'Histogram matching';
            app.RGBdisplaypatternMenu = uimenu(app.Lecture04PointprocessMenu);
            app.RGBdisplaypatternMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBdisplaypatternMenuSelected, true);
            app.RGBdisplaypatternMenu.Text = 'RGB display pattern';
            app.Lecture05ColorperceptionMenu = uimenu(app.LecturesMenu);
            app.Lecture05ColorperceptionMenu.Text = 'Lecture 05. Color perception';
            app.IncreaseDecreasesaturationMenu = uimenu(app.Lecture05ColorperceptionMenu);
            app.IncreaseDecreasesaturationMenu.MenuSelectedFcn = createCallbackFcn(app, @IncreaseDecreasesaturationMenuSelected, true);
            app.IncreaseDecreasesaturationMenu.Text = 'Increase/Decrease saturation';
            app.PixelizationbychannelMenu = uimenu(app.Lecture05ColorperceptionMenu);
            app.PixelizationbychannelMenu.MenuSelectedFcn = createCallbackFcn(app, @PixelizationbychannelMenuSelected, true);
            app.PixelizationbychannelMenu.Text = 'Pixelization by channel';
            app.CMYKprintpatternsMenu = uimenu(app.Lecture05ColorperceptionMenu);
            app.CMYKprintpatternsMenu.MenuSelectedFcn = createCallbackFcn(app, @CMYKprintpatternsMenuSelected, true);
            app.CMYKprintpatternsMenu.Text = 'CMYK print patterns';
            app.FoveatransformMenu = uimenu(app.Lecture05ColorperceptionMenu);
            app.FoveatransformMenu.MenuSelectedFcn = createCallbackFcn(app, @FoveatransformMenuSelected, true);
            app.FoveatransformMenu.Text = 'Fovea transform';
            app.OpticalIllusionsMenu = uimenu(app.Lecture05ColorperceptionMenu);
            app.OpticalIllusionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OpticalIllusionsMenuSelected, true);
            app.OpticalIllusionsMenu.Text = 'Optical Illusions';
            app.Lecture06ColorcorrectionMenu = uimenu(app.LecturesMenu);
            app.Lecture06ColorcorrectionMenu.Text = 'Lecture 06. Color correction';
            app.ColorcubeexplorerMenu = uimenu(app.Lecture06ColorcorrectionMenu);
            app.ColorcubeexplorerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorcubeexplorerMenuSelected, true);
            app.ColorcubeexplorerMenu.Text = 'Color cube explorer';
            app.HSVConversionMenu = uimenu(app.Lecture06ColorcorrectionMenu);
            app.HSVConversionMenu.MenuSelectedFcn = createCallbackFcn(app, @HSVConversionMenuSelected, true);
            app.HSVConversionMenu.Text = 'HSV Conversion';
            app.ColorcorrectionMenu = uimenu(app.Lecture06ColorcorrectionMenu);
            app.ColorcorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorcorrectionMenuSelected, true);
            app.ColorcorrectionMenu.Text = 'Color correction';
            app.Lecture07FouriertransformMenu = uimenu(app.LecturesMenu);
            app.Lecture07FouriertransformMenu.Text = 'Lecture 07. Fourier transform';
            app.DWavesMenu = uimenu(app.Lecture07FouriertransformMenu);
            app.DWavesMenu.MenuSelectedFcn = createCallbackFcn(app, @DWavesMenuSelected, true);
            app.DWavesMenu.Text = '2D Waves';
            app.PlotFTMenu = uimenu(app.Lecture07FouriertransformMenu);
            app.PlotFTMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotFTMenuSelected, true);
            app.PlotFTMenu.Text = 'Plot FT';
            app.PlotFTandnoiseMenu = uimenu(app.Lecture07FouriertransformMenu);
            app.PlotFTandnoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotFTandnoiseMenuSelected, true);
            app.PlotFTandnoiseMenu.Text = 'Plot FT and noise';
            app.Lecture08ConvolutionMenu = uimenu(app.LecturesMenu);
            app.Lecture08ConvolutionMenu.Text = 'Lecture 08. Convolution';
            app.SpatialconvolutiondefinitionMenu = uimenu(app.Lecture08ConvolutionMenu);
            app.SpatialconvolutiondefinitionMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialconvolutiondefinitionMenuSelected, true);
            app.SpatialconvolutiondefinitionMenu.Text = 'Spatial convolution definition';
            app.SpatialconvolutionMenu = uimenu(app.Lecture08ConvolutionMenu);
            app.SpatialconvolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialconvolutionMenuSelected, true);
            app.SpatialconvolutionMenu.Text = 'Spatial convolution';
            app.EdgedetectionMenu = uimenu(app.Lecture08ConvolutionMenu);
            app.EdgedetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgedetectionMenuSelected, true);
            app.EdgedetectionMenu.Text = 'Edge detection';
            app.BlurEdgedetectionMenu = uimenu(app.Lecture08ConvolutionMenu);
            app.BlurEdgedetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @BlurEdgedetectionMenuSelected, true);
            app.BlurEdgedetectionMenu.Text = 'Blur/Edge detection';
            app.Lecture09FrequencyfiltersMenu = uimenu(app.LecturesMenu);
            app.Lecture09FrequencyfiltersMenu.Text = 'Lecture 09. Frequency filters';
            app.IdealfilterMenu = uimenu(app.Lecture09FrequencyfiltersMenu);
            app.IdealfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @IdealfilterMenuSelected, true);
            app.IdealfilterMenu.Text = 'Ideal filter';
            app.GaussianfilterMenu = uimenu(app.Lecture09FrequencyfiltersMenu);
            app.GaussianfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianfilterMenuSelected, true);
            app.GaussianfilterMenu.Text = 'Gaussian filter';
            app.SharpeningbyconvolutionMenu = uimenu(app.Lecture09FrequencyfiltersMenu);
            app.SharpeningbyconvolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @SharpeningbyconvolutionMenuSelected, true);
            app.SharpeningbyconvolutionMenu.Text = 'Sharpening by convolution';
            app.Lecture10SharpeningMenu = uimenu(app.LecturesMenu);
            app.Lecture10SharpeningMenu.Text = 'Lecture 10. Sharpening';
            app.DirectionalderivativeMenu = uimenu(app.Lecture10SharpeningMenu);
            app.DirectionalderivativeMenu.MenuSelectedFcn = createCallbackFcn(app, @DirectionalderivativeMenuSelected, true);
            app.DirectionalderivativeMenu.Text = 'Directional derivative';
            app.SharpeningandnoiseMenu = uimenu(app.Lecture10SharpeningMenu);
            app.SharpeningandnoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @SharpeningandnoiseMenuSelected, true);
            app.SharpeningandnoiseMenu.Text = 'Sharpening and noise';
            app.SharpeninginFourierMenu = uimenu(app.Lecture10SharpeningMenu);
            app.SharpeninginFourierMenu.MenuSelectedFcn = createCallbackFcn(app, @SharpeninginFourierMenuSelected, true);
            app.SharpeninginFourierMenu.Text = 'Sharpening in Fourier';
            app.Lecture11PixelationandquantizationMenu = uimenu(app.LecturesMenu);
            app.Lecture11PixelationandquantizationMenu.Text = 'Lecture 11. Pixelation and quantization';
            app.PixelationMenu = uimenu(app.Lecture11PixelationandquantizationMenu);
            app.PixelationMenu.MenuSelectedFcn = createCallbackFcn(app, @PixelationMenuSelected, true);
            app.PixelationMenu.Text = 'Pixelation';
            app.SteganographyMenu = uimenu(app.Lecture11PixelationandquantizationMenu);
            app.SteganographyMenu.MenuSelectedFcn = createCallbackFcn(app, @SteganographyMenuSelected, true);
            app.SteganographyMenu.Text = 'Steganography';
            app.DitheringMenu = uimenu(app.Lecture11PixelationandquantizationMenu);
            app.DitheringMenu.MenuSelectedFcn = createCallbackFcn(app, @DitheringMenuSelected, true);
            app.DitheringMenu.Text = 'Dithering';
            app.Lecture12SamplingandaliasingMenu = uimenu(app.LecturesMenu);
            app.Lecture12SamplingandaliasingMenu.Text = 'Lecture 12. Sampling and aliasing';
            app.DownsamplingMenu = uimenu(app.Lecture12SamplingandaliasingMenu);
            app.DownsamplingMenu.MenuSelectedFcn = createCallbackFcn(app, @DownsamplingMenuSelected, true);
            app.DownsamplingMenu.Text = 'Downsampling';
            app.Lecture13ImageresizingMenu = uimenu(app.LecturesMenu);
            app.Lecture13ImageresizingMenu.Text = 'Lecture 13. Image resizing';
            app.ReinterpolationMenu = uimenu(app.Lecture13ImageresizingMenu);
            app.ReinterpolationMenu.MenuSelectedFcn = createCallbackFcn(app, @ReinterpolationMenuSelected, true);
            app.ReinterpolationMenu.Text = 'Reinterpolation';
            app.Lecture14WarpingandrotationMenu = uimenu(app.LecturesMenu);
            app.Lecture14WarpingandrotationMenu.Text = 'Lecture 14. Warping and rotation';
            app.AffinetransformMenu = uimenu(app.Lecture14WarpingandrotationMenu);
            app.AffinetransformMenu.MenuSelectedFcn = createCallbackFcn(app, @AffinetransformMenuSelected, true);
            app.AffinetransformMenu.Text = 'Affine transform';
            app.FisheyetransformMenu = uimenu(app.Lecture14WarpingandrotationMenu);
            app.FisheyetransformMenu.MenuSelectedFcn = createCallbackFcn(app, @FisheyetransformMenuSelected, true);
            app.FisheyetransformMenu.Text = 'Fish eye transform';
            app.WarpingMenu = uimenu(app.Lecture14WarpingandrotationMenu);
            app.WarpingMenu.MenuSelectedFcn = createCallbackFcn(app, @WarpingMenuSelected2, true);
            app.WarpingMenu.Text = 'Warping';
            app.Lecture15UncorrelatednoiseMenu = uimenu(app.LecturesMenu);
            app.Lecture15UncorrelatednoiseMenu.Text = 'Lecture 15. Uncorrelated noise';
            app.NoiseexplorerMenu = uimenu(app.Lecture15UncorrelatednoiseMenu);
            app.NoiseexplorerMenu.MenuSelectedFcn = createCallbackFcn(app, @NoiseexplorerMenuSelected, true);
            app.NoiseexplorerMenu.Text = 'Noise explorer';
            app.PlotFTandnoiseMenu_2 = uimenu(app.Lecture15UncorrelatednoiseMenu);
            app.PlotFTandnoiseMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PlotFTandnoiseMenuSelected, true);
            app.PlotFTandnoiseMenu_2.Text = 'Plot FT and noise';
            app.ImageformationMenu = uimenu(app.Lecture15UncorrelatednoiseMenu);
            app.ImageformationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageformationMenuSelected, true);
            app.ImageformationMenu.Text = 'Image formation';
            app.WienerfilterMenu = uimenu(app.Lecture15UncorrelatednoiseMenu);
            app.WienerfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @WienerfilterMenuSelected, true);
            app.WienerfilterMenu.Text = 'Wiener filter';
            app.Lecture16CorrelatednoiseMenu = uimenu(app.LecturesMenu);
            app.Lecture16CorrelatednoiseMenu.Text = 'Lecture 16. Correlated noise';
            app.NotchfilterMenu = uimenu(app.Lecture16CorrelatednoiseMenu);
            app.NotchfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @NotchfilterMenuSelected, true);
            app.NotchfilterMenu.Text = 'Notch filter';
            app.Lecture17MedianfiltersMenu = uimenu(app.LecturesMenu);
            app.Lecture17MedianfiltersMenu.Text = 'Lecture 17. Median filters';
            app.MedianfilterMenu = uimenu(app.Lecture17MedianfiltersMenu);
            app.MedianfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @MedianfilterMenuSelected, true);
            app.MedianfilterMenu.Text = 'Median filter';
            app.ColormedianfilterMenu = uimenu(app.Lecture17MedianfiltersMenu);
            app.ColormedianfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormedianfilterMenuSelected, true);
            app.ColormedianfilterMenu.Text = 'Color median filter';
            app.Lecture18BinarymorphologyMenu = uimenu(app.LecturesMenu);
            app.Lecture18BinarymorphologyMenu.Text = 'Lecture 18. Binary morphology';
            app.DilationErosionOpeningClosingMenu_2 = uimenu(app.Lecture18BinarymorphologyMenu);
            app.DilationErosionOpeningClosingMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DilationErosionOpeningClosingMenu_2Selected, true);
            app.DilationErosionOpeningClosingMenu_2.Text = 'Dilation/Erosion/Opening/Closing';
            app.ReconstructionMenu = uimenu(app.Lecture18BinarymorphologyMenu);
            app.ReconstructionMenu.MenuSelectedFcn = createCallbackFcn(app, @ReconstructionMenuSelected, true);
            app.ReconstructionMenu.Text = 'Reconstruction';
            app.SkeletonMenu = uimenu(app.Lecture18BinarymorphologyMenu);
            app.SkeletonMenu.MenuSelectedFcn = createCallbackFcn(app, @SkeletonMenuSelected, true);
            app.SkeletonMenu.Text = 'Skeleton';
            app.Lecture19GraymorphologyMenu = uimenu(app.LecturesMenu);
            app.Lecture19GraymorphologyMenu.Text = 'Lecture 19. Gray morphology';
            app.DilationErosionOpeningClosingMenu = uimenu(app.Lecture19GraymorphologyMenu);
            app.DilationErosionOpeningClosingMenu.MenuSelectedFcn = createCallbackFcn(app, @DilationErosionOpeningClosingMenuSelected, true);
            app.DilationErosionOpeningClosingMenu.Text = 'Dilation/Erosion/Opening/Closing';
            app.TopHatandBotHatMenu = uimenu(app.Lecture19GraymorphologyMenu);
            app.TopHatandBotHatMenu.MenuSelectedFcn = createCallbackFcn(app, @TopHatandBotHatMenuSelected, true);
            app.TopHatandBotHatMenu.Text = 'TopHat and BotHat';
            app.ReconstructionMenu_2 = uimenu(app.Lecture19GraymorphologyMenu);
            app.ReconstructionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ReconstructionMenu_2Selected, true);
            app.ReconstructionMenu_2.Text = 'Reconstruction';
            app.Lecture20JpegcompressionMenu = uimenu(app.LecturesMenu);
            app.Lecture20JpegcompressionMenu.Text = 'Lecture 20. Jpeg compression';
            app.QualitycomparisonMenu = uimenu(app.Lecture20JpegcompressionMenu);
            app.QualitycomparisonMenu.MenuSelectedFcn = createCallbackFcn(app, @QualitycomparisonMenuSelected, true);
            app.QualitycomparisonMenu.Text = 'Quality comparison';
            app.JpegcompressionMenu = uimenu(app.Lecture20JpegcompressionMenu);
            app.JpegcompressionMenu.MenuSelectedFcn = createCallbackFcn(app, @JpegcompressionMenuSelected, true);
            app.JpegcompressionMenu.Text = 'Jpeg compression';
            app.SelectimageDropDownLabel = uilabel(app.UIFigure);
            app.SelectimageDropDownLabel.Text = 'Select image';
            app.SelectimageDropDown = uidropdown(app.UIFigure);
            app.SelectimageDropDown.Items = {};
            app.SelectimageDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuImgProcApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end