classdef CeuL10_SharpeningAndNoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SharpeningandnoiseLabel      matlab.ui.control.Label
        ChooseactionButtonGroup      matlab.ui.container.ButtonGroup
        AddnoiseButton               matlab.ui.control.RadioButton
        SharpenButton                matlab.ui.control.RadioButton
        SharpenandnoiseButton        matlab.ui.control.RadioButton
        ExecuteButton                matlab.ui.control.Button
        ImageLabel                   matlab.ui.control.Label
        TypeofnoiseButtonGroup       matlab.ui.container.ButtonGroup
        GaussianButton               matlab.ui.control.RadioButton
        SaltandpepperButton          matlab.ui.control.RadioButton
        SpeckleButton                matlab.ui.control.RadioButton
        SharpenamountSliderLabel     matlab.ui.control.Label
        SharpenamountSlider          matlab.ui.control.Slider
        NoisySharpenedimageLabel     matlab.ui.control.Label
        GaussianvarianceSliderLabel  matlab.ui.control.Label
        GaussianvarianceSlider       matlab.ui.control.Slider
        UIAxesChanged                matlab.ui.control.UIAxes
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.NoisySharpenedimageLabel.Text='Original image:';
            I = getimage(app.h);
            image(app.UIAxesChanged,I);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            amount = app.SharpenamountSlider.Value;
            var_gauss = app.GaussianvarianceSlider.Value;
            
            if app.AddnoiseButton.Value
                if app.GaussianButton.Value
                    J = imnoise(I,'gaussian',0,var_gauss);
                    app.NoisySharpenedimageLabel.Text='Gaussian noise:';
                    image(app.UIAxesChanged,J);
                elseif app.SaltandpepperButton.Value
                    app.NoisySharpenedimageLabel.Text='Salt & pepper noise:';
                    J = imnoise(I,'salt & pepper');
                    image(app.UIAxesChanged,J);
                elseif app.SpeckleButton.Value
                    app.NoisySharpenedimageLabel.Text='Speckel noise:';
                    J = imnoise(I,'speckle');
                    image(app.UIAxesChanged,J);
                end
                
            elseif app.SharpenButton.Value
                app.NoisySharpenedimageLabel.Text='Sharpened image:';
                J = imsharpen(I,'Amount',amount);
                image(app.UIAxesChanged,J);
                
            elseif app.SharpenandnoiseButton.Value
                
                if app.GaussianButton.Value
                    app.NoisySharpenedimageLabel.Text='Gaussian noise and sharpen:';
                    J = imsharpen(imnoise(I,'gaussian'),'Amount',amount);
                    image(app.UIAxesChanged,J);
                    
                elseif app.SaltandpepperButton.Value
                    app.NoisySharpenedimageLabel.Text='S&P noisy and sharpen:';
                    J = imsharpen(imnoise(I,'salt & pepper'),'Amount',amount);
                    image(app.UIAxesChanged,J);
                    
                elseif app.SpeckleButton.Value
                    app.NoisySharpenedimageLabel.Text='Speckle noisy and sharpen:';
                    J = imsharpen(imnoise(I,'speckle'),'Amount',amount);
                    image(app.UIAxesChanged,J);
                end 
            end
            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SharpeningandnoiseLabel = uilabel(app.UIFigure);
            app.SharpeningandnoiseLabel.Text = 'Sharpening and noise';
            app.ChooseactionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseactionButtonGroup.Title = 'Choose action';
            app.AddnoiseButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.AddnoiseButton.Text = 'Add noise';
            app.AddnoiseButton.Value = true;
            app.SharpenButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.SharpenButton.Text = 'Sharpen';
            app.SharpenandnoiseButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.SharpenandnoiseButton.Text = 'Sharpen and noise';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.TypeofnoiseButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeofnoiseButtonGroup.Title = 'Type of noise';
            app.GaussianButton = uiradiobutton(app.TypeofnoiseButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.GaussianButton.Value = true;
            app.SaltandpepperButton = uiradiobutton(app.TypeofnoiseButtonGroup);
            app.SaltandpepperButton.Text = 'Salt and pepper';
            app.SpeckleButton = uiradiobutton(app.TypeofnoiseButtonGroup);
            app.SpeckleButton.Text = 'Speckle';
            app.SharpenamountSliderLabel = uilabel(app.UIFigure);
            app.SharpenamountSliderLabel.Text = 'Sharpen amount';
            app.SharpenamountSlider = uislider(app.UIFigure);
            app.SharpenamountSlider.Limits = [0 30];
            app.SharpenamountSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.NoisySharpenedimageLabel = uilabel(app.UIFigure);
            app.NoisySharpenedimageLabel.Text = 'Noisy/Sharpened image:';
            app.GaussianvarianceSliderLabel = uilabel(app.UIFigure);
            app.GaussianvarianceSliderLabel.Text = 'Gaussian variance';
            app.GaussianvarianceSlider = uislider(app.UIFigure);
            app.GaussianvarianceSlider.Limits = [0 0.05];
            app.UIAxesChanged = uiaxes(app.UIFigure);
            app.UIAxesChanged.XTick = [];
            app.UIAxesChanged.XTickLabel = '';
            app.UIAxesChanged.YTick = [];
            app.UIAxesChanged.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL10_SharpeningAndNoiseApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end