classdef CeuL11_DitheringApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DitheringLabel                  matlab.ui.control.Label
        ImageNameLabel                  matlab.ui.control.Label
        OriginalImage                   matlab.ui.control.Image
        OriginalLabel                   matlab.ui.control.Label
        NumberofQuantizedColorsSpinner  matlab.ui.control.Spinner
        AcceptButton                    matlab.ui.control.Button
        NumberOfQuantizedColorsLabel    matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I         % Image
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        function createFigures(app)
            numberColors = app.NumberofQuantizedColorsSpinner.Value;
            
            I = getimage(app.h);
            
            [image_noDither,map] = rgb2ind(I,numberColors,'nodither');
            figure('Name','No Dithering'), imshow(image_noDither,map);
            
            [image_dither,map] = rgb2ind(I,numberColors,'dither');
            figure('Name','Dithering'), imshow(image_dither,map);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            
            
            app.parentApp = parentApp;
            app.h = h;
            app.ImageNameLabel.Text=strcat("Image: ",h.Name);
            
            I = getimage(app.h);
            
            app.OriginalLabel.Text=("Original Image");
            
            app.OriginalImage.ImageSource = I;


        end

        % Button pushed function: AcceptButton
        function Accept(app, event)
            app.createFigures();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DitheringLabel = uilabel(app.UIFigure);
            app.DitheringLabel.Text = 'Dithering';
            app.ImageNameLabel = uilabel(app.UIFigure);
            app.ImageNameLabel.Text = '';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = '';
            app.NumberofQuantizedColorsSpinner = uispinner(app.UIFigure);
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @Accept, true);
            app.AcceptButton.Text = 'Accept';
            app.NumberOfQuantizedColorsLabel = uilabel(app.UIFigure);
            app.NumberOfQuantizedColorsLabel.Text = 'Number Of Quantized Colors';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL11_DitheringApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end