classdef CeuL11_SteganographyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SteganographycomputationLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ChangeButton                   matlab.ui.control.StateButton
        ExtractButton                  matlab.ui.control.StateButton
        UIAxes4                        matlab.ui.control.UIAxes
        Label_2                        matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        ThisisyouractualmessageimageclickonitifyouwanttochangeitLabel_2  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        PleaseclickexecutetorunthesteganographycomputationLabel  matlab.ui.control.Label
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
    end

    % Author: Ines de los Mozos    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            axis(app.UIAxes2,'off');
            axis(app.UIAxes4,'off');
            axis(app.UIAxes,'off');
            axis(app.UIAxes4,'off');
        end

        % Callback function
        function ExecuteButtonPushed(app, event)

            
        end

        % Callback function
        function IntroducethenameofyourmessageimageasjpgEditFieldValueChanged(app, event)
            value = app.IntroducethenameofyourmessageimageasjpgEditField.Value;
            
        end

        % Value changed function: ChangeButton
        function ChangeButtonValueChanged(app, event)
            value = app.ChangeButton.Value;
            [file,path] = uigetfile('*.*','Select an Image');
            if ~isequal(file,0)
               fn=fullfile(path,file);
               [I,map] = imread(fn);
               figure('Name',file);
               imshow(I,map);
               app.Image2.ImageSource=fn;
               app.updateImageList();
            end
            
        end

        % Value changed function: ExtractButton
        function ExtractButtonValueChanged(app, event)
            value = app.ExtractButton.Value;
            stegoImage=imread('stegoImage.png');
            imbed=round(app.Slider.Value);
            %input('Please input the number of bits use to compute the encryptation:');
            Extracted = uint8(bitand(255, bitshift(stegoImage, 8-imbed)));
           imshow(Extracted,'Parent',app.UIAxes3);
           
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
%             
             I = getimage(app.h);
             I= imresize(I, [256, 256]);
             windowName = app.h.Name;
            imshow(I,'Parent',app.UIAxes2);
            m=app.Image2.ImageSource;
            message=imread(m);
            message=imresize(message, [256, 256]);
            imshow(message,'Parent',app.UIAxes4);
            %display on screen the two images 
            figure(1), imshow(I); title('Cover Image'); 
            figure(2), imshow(message);title('Message Image');
           if size(I)== size(message)
               %change to double to work with addition below 
                cover=double(I); 
                message=double(message); 
                %imbed = no. of bits of message image to embed in cover image 
                imbed=round(app.Slider.Value);
                %input('Specify number of bits to replace (between 1 and 7): '); 
                if imbed >=8 || imbed <=0
                    disp('Error: The number of bits  must be between 1 and 7!');
                else
                %shift the message image over (8-imbed) bits to right 
                %messageshift=bitshift(message,-(8-imbed)); 
                %show the message image with only embed bits on screen 
                %must shift from LSBs to MSBs
                messageshift=uint8(bitshift(message,-(8-imbed)));
                showmess=uint8(messageshift); 
                showmess=bitshift(showmess,8-imbed); 
                figure(3),imshow(showmess);title('4 Bit Image to Hide'); 
                %now zero out imbed bits in cover image 
                coverzero = cover; 
                for i=1:imbed 
                    coverzero=bitset(coverzero,i,0); 
                end 
                cove=uint8(coverzero);
                %now add message image and cover image 
                stego = imadd(cove,messageshift); 
                figure(4),imshow(stego);title('Stego image'); 
                %save files if need to
                %4 bit file that was embedded = same as file extracted 
                imwrite(stego,'StegoImage.png');
                 %figure('Name','Original Image ');
                 %imshow(cover);
                 %figure('Name','StegoImage ');
                 imshow(stego,'Parent',app.UIAxes);
                 %TODO cambiar ejes
                 app.parentApp.updateImageList();  
                end
                 
           else
                disp( 'Error: Images must have the same dimensions!')
                
           end   


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SteganographycomputationLabel = uilabel(app.UIFigure);
            app.SteganographycomputationLabel.Text = 'Steganography computation ';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'StegoImage')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'OriginalImage')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ChangeButton = uibutton(app.UIFigure, 'state');
            app.ChangeButton.ValueChangedFcn = createCallbackFcn(app, @ChangeButtonValueChanged, true);
            app.ChangeButton.Text = 'Change';
            app.ExtractButton = uibutton(app.UIFigure, 'state');
            app.ExtractButton.ValueChangedFcn = createCallbackFcn(app, @ExtractButtonValueChanged, true);
            app.ExtractButton.Text = 'Extract';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Message')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Please to extract the hidden message in your Stego Image click on the extract button:';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Extracted message ')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.ThisisyouractualmessageimageclickonitifyouwanttochangeitLabel_2 = uilabel(app.UIFigure);
            app.ThisisyouractualmessageimageclickonitifyouwanttochangeitLabel_2.Text = 'This is your actual message image, please click on "change" if you want to change it :';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'fluorescenceCells.jpg';
            app.PleaseclickexecutetorunthesteganographycomputationLabel = uilabel(app.UIFigure);
            app.PleaseclickexecutetorunthesteganographycomputationLabel.Text = 'Please,move the slider to the number of bits you want to hide(Remember it must be a number between 1 and 7):';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 7];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 1 2 3 4 5 6 7];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL11_SteganographyApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end