classdef CeuL12_DownsampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DownsampleImagewandwoantialiasingfilterLabel  matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        PleaseselectthefactorbywhichyouwanttodownsampletheimageLabel  matlab.ui.control.Label
        Minimumfactorvalue1SpinnerLabel  matlab.ui.control.Label
        Minimumfactorvalue1Spinner      matlab.ui.control.Spinner
        LastlyselectthestandarddeviationoftheGaussianfilterLabel  matlab.ui.control.Label
        AsmallvalueforsigmaisrecommendedLabel  matlab.ui.control.Label
        Minimumsigmavalue1SpinnerLabel  matlab.ui.control.Label
        Minimumsigmavalue1Spinner       matlab.ui.control.Spinner
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
       % value
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            %app.NofbitsEditField.Value = value;
            
               
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
           
            
            I = getimage(app.h); 
            
            windowName = app.h.Name; 
            
            R=size(I,1);
            C=size(I,2);
            factor= app.Minimumfactorvalue1Spinner.Value;
            [rows, columns, numberOfColorChannels] = size(I);

            if numberOfColorChannels == 1
                 down1 = I(1:factor:R,1:factor:C, :);

           
            % FILTER: GAUSSIAN FILTER
           
                 sigma= [app.Minimumsigmavalue1Spinner.Value, app.Minimumsigmavalue1Spinner.Value];
                 B = imgaussfilt(I,sigma); 
                 down2 = B(1:factor:R,1:factor:C, :);
            
            
            %USANDO FUNCION IMRESIZE
            
                 factor2= 1/factor;
                 down3 = imresize(I,factor2,'box', 'Antialiasing', true);


            else
            RC = I(:,:,1);
            GC = I(:,:,2);
            BC = I(:,:,3);


            J_1 = RC(1:factor:R,1:factor:C, :);
            J_2 = GC(1:factor:R,1:factor:C, :);
            J_3 = BC(1:factor:R,1:factor:C, :);
            
            down1=zeros([size(J_1,1) size(J_1,2) 3]);
            down1(:,:,1) = J_1;
            down1(:,:,2) = J_2;
            down1(:,:,3) = J_3;
            
            
            % FILTER: FT X GAUSSIAN FILTER
            sigma= [app.Minimumsigmavalue1Spinner.Value, app.Minimumsigmavalue1Spinner.Value];
            
            b1 = imgaussfilt(RC,sigma); 
            b2 = imgaussfilt(GC,sigma); 
            b3 = imgaussfilt(BC,sigma); 
            
            J1 = b1(1:factor:R,1:factor:C, :);
            J2 = b2(1:factor:R,1:factor:C, :);
            J3 = b3(1:factor:R,1:factor:C, :);
            
            down2=zeros([size(J1,1) size(J1,2) 3]);
            down2(:,:,1) = J1;
            down2(:,:,2) = J2;
            down2(:,:,3) = J3;
            
            
            %USANDO FUNCION IMRESIZE
            
            factor2= 1/factor;
            R_1 = imresize(RC,factor2,'box', 'Antialiasing', true);
            R_2 = imresize(GC,factor2,'box', 'Antialiasing', true);
            R_3 = imresize(BC,factor2,'box', 'Antialiasing', true);
            
            down3=zeros([size(R_1,1) size(R_1,2) 3]);
            down3(:,:,1) = R_1;
            down3(:,:,2) = R_2;
            down3(:,:,3) = R_3;
            
            end 
            if true
                
             figure;
             imshow(uint8(down1));
             title('Downsampled image w/o filter', 'FontSize', 14)
             axis off;
             pause(0.01)
             
             figure;
             imshow(uint8(down2));
             title('Downsampled image w designed filter', 'FontSize', 14)
             axis off;
             pause(0.01)
             
             figure;
             imshow(uint8(down3));
             title('Downsampled image w designed filter (imresize)', 'FontSize', 14)
             axis off;
             pause(0.01)
            end


            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DownsampleImagewandwoantialiasingfilterLabel = uilabel(app.UIFigure);
            app.DownsampleImagewandwoantialiasingfilterLabel.Text = 'Downsample Image w and w/o anti-aliasing filter ';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.PleaseselectthefactorbywhichyouwanttodownsampletheimageLabel = uilabel(app.UIFigure);
            app.PleaseselectthefactorbywhichyouwanttodownsampletheimageLabel.Text = 'Please, select the factor by which you want to downsample the image:';
            app.Minimumfactorvalue1SpinnerLabel = uilabel(app.UIFigure);
            app.Minimumfactorvalue1SpinnerLabel.Text = 'Minimum factor value: 1';
            app.Minimumfactorvalue1Spinner = uispinner(app.UIFigure);
            app.Minimumfactorvalue1Spinner.Limits = [1 Inf];
            app.Minimumfactorvalue1Spinner.Value = 1;
            app.LastlyselectthestandarddeviationoftheGaussianfilterLabel = uilabel(app.UIFigure);
            app.LastlyselectthestandarddeviationoftheGaussianfilterLabel.Text = 'Lastly, select the standard deviation of the Gaussian filter:';
            app.AsmallvalueforsigmaisrecommendedLabel = uilabel(app.UIFigure);
            app.AsmallvalueforsigmaisrecommendedLabel.Text = 'A small value for sigma is recommended.';
            app.Minimumsigmavalue1SpinnerLabel = uilabel(app.UIFigure);
            app.Minimumsigmavalue1SpinnerLabel.Text = 'Minimum sigma value: 1';
            app.Minimumsigmavalue1Spinner = uispinner(app.UIFigure);
            app.Minimumsigmavalue1Spinner.Limits = [1 Inf];
            app.Minimumsigmavalue1Spinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL12_DownsampleApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end