classdef CeuL13_DownsamplingUpsamplingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DownsamplingUpsamplingbyarationalfactorLabel  matlab.ui.control.Label
        InterpolationButtonGroup  matlab.ui.container.ButtonGroup
        NearestneighbourButton    matlab.ui.control.RadioButton
        BilinearButton            matlab.ui.control.RadioButton
        BicubicButton             matlab.ui.control.RadioButton
        ExecuteButton             matlab.ui.control.Button
        ImageLabel                matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        RatioSpinnerLabel         matlab.ui.control.Label
        RatioSpinner              matlab.ui.control.Spinner
    end

    % Author: Carlos Oscar Sorzano    
    % Author: Héctor Yebes del Pino 
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.Image.ImageSource = app.h.Name;
            %app.I = getimage(app.h);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            
            I = getimage(app.h);
            ratioValue = app.RatioSpinner.Value;

            
            if app.NearestneighbourButton.Value
                P = imresize(I,ratioValue,'nearest');
                %figure;
                figure('Name','NEAREST NEIGHBOUR','NumberTitle','off');
                subplot(1,2,1);
                imshow(I);
                title('Original image');
                subplot(1,2,2);
                imshow(P);
                title(['Resized by x ',num2str(ratioValue)]);
                %image(app.Image,P);

                
            end
     
            if app.BilinearButton.Value
                P = imresize(I,ratioValue,'bilinear');
                %P = imresize(I,'scale',ratioValue,'Method','bilinear');
                %figure;
                figure('Name','BILINEAR','NumberTitle','off');
                subplot(1,2,1);
                imshow(I);
                title('Original image');
                subplot(1,2,2);
                imshow(P);
                title(['Resized by x ',num2str(ratioValue)]);
                %image(app.Image,P);

            end
           
            
            if app.BicubicButton.Value
                P = imresize(I,ratioValue,'bicubic');
                figure('Name','BICUBIC','NumberTitle','off');
                subplot(1,2,1);
                imshow(I);
                title('Original image');
                subplot(1,2,2);
                imshow(P);     
                title(['Resized by x ',num2str(ratioValue)]);
                %image(app.Image,P);
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DownsamplingUpsamplingbyarationalfactorLabel = uilabel(app.UIFigure);
            app.DownsamplingUpsamplingbyarationalfactorLabel.Text = 'Downsampling Upsampling by a rational factor';
            app.InterpolationButtonGroup = uibuttongroup(app.UIFigure);
            app.InterpolationButtonGroup.Title = 'Interpolation';
            app.NearestneighbourButton = uiradiobutton(app.InterpolationButtonGroup);
            app.NearestneighbourButton.Text = 'Nearest neighbour';
            app.NearestneighbourButton.Value = true;
            app.BilinearButton = uiradiobutton(app.InterpolationButtonGroup);
            app.BilinearButton.Text = 'Bilinear';
            app.BicubicButton = uiradiobutton(app.InterpolationButtonGroup);
            app.BicubicButton.Text = 'Bicubic';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.Image = uiimage(app.UIFigure);
            app.RatioSpinnerLabel = uilabel(app.UIFigure);
            app.RatioSpinnerLabel.Text = 'Ratio';
            app.RatioSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL13_DownsamplingUpsamplingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end