classdef CeuL14_ImageFishEyeRotationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadImageMenu                  matlab.ui.container.Menu
        UpdateImageListMenu            matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        TransformButton                matlab.ui.control.Button
        SelectedImageLabel             matlab.ui.control.Label
        SelectimageDropDown            matlab.ui.control.DropDown
        PictureImage                   matlab.ui.control.Image
        SaveTransformationAsEditFieldLabel  matlab.ui.control.Label
        SaveTransformationAsEditField  matlab.ui.control.EditField
        TotransformanotherimagepleaseloadsaidimageLabel  matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = public)
        function updateImageList(app)
            figHandles = findobj('Type', 'figure');
            if length(figHandles)>0
                app.SelectimageDropDown.Items = {};
                for i=1:length(figHandles)
                    app.SelectimageDropDown.Items{i} = figHandles(i).Name;
                end
            end
        end
    end
    
    methods (Access = public)
        function h2 = getFigureHandler(app)
            h2= app.getFigureHandlerByName(app.SelectimageDropDown.Value);
        end
    end
    
    methods (Access = public)
        
        function h2 = getFigureHandlerByName(app, selectedName)
            figHandles = findobj('Type', 'figure');
            for i=1:length(figHandles)
                if strcmp(figHandles(i).Name,selectedName)
                    h2=figHandles(i);
                    return
                end
            end
            h2 = -1;
            return            
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;            
            app.updateImageList();
            im = getimage(app.h); 
            app.PictureImage.ImageSource = im;
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            
            app.TransformButton.Enable = 'off';
            app.SaveTransformationAsEditField.Enable = 'off';
            h2 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
            image = getimage(h2);
            [r0 c0 d0] = size(image);
            maximum = max(r0,c0);
            imo = imresize(image,[maximum maximum]);
            m = floor(maximum/2);
             
            image00 = imo(1:m,1:m,:);
            image01 = imo(1:m,m+1:end,:);
            image11 = imo(m+1:end,m+1:end,:);
            image10 = imo(m+1:end,1:m,:);
             
            for i = 1:m
              u = image00(m:-1:i,i,:);
              v = image00(i,i+1:m,:);
              s = [];
              s(:,:,1) = [u(:,:,1)' v(:,:,1)];
              s(:,:,2) = [u(:,:,2)' v(:,:,2)];
              s(:,:,3) = [u(:,:,3)' v(:,:,3)];
              cd = size(s,2);
              dss(i,i:i+cd-1,:) = s;
              R = m;
              for j = 1:0.2:cd
                a = 90*j/cd;
                q = m+1-floor((m-i)*cosd(a));
                p = m+1-floor((m-i)*sind(a));
                ds(p,q,:) = s(1,floor(j),:);
              end
            end
             
            for i = 1:m
              u = image01(i,1:m+1-i,:);
              v = image01(i:m,m+1-i,:);
              s = [];
              s(:,:,1) = [u(:,:,1) v(:,:,1)'];
              s(:,:,2) = [u(:,:,2) v(:,:,2)'];
              s(:,:,3) = [u(:,:,3) v(:,:,3)'];
              cd = size(s,2);
              dss(i,i:i+cd-1,:) = s;
              R = m;
              for j = 1:0.2:cd
                a = 90*j/cd;
                q = m+1+floor((m-i)*cosd(90-a));
                p = m+1-floor((m-i)*sind(90-a));
                ds(p,q,:) = s(1,floor(j),:);
              end
            end
             
            for i = 1:m
              u = image11(1:m+1-i,m+1-i,:);
              v = image11(m+1-i,m+1-i:-1:1,:);
              s = [];
              s(:,:,1) = [u(:,:,1)' v(:,:,1)];
              s(:,:,2) = [u(:,:,2)' v(:,:,2)];
              s(:,:,3) = [u(:,:,3)' v(:,:,3)];
              cd = size(s,2);
              dss(i,i:i+cd-1,:) = s;
              R = m;
              for j = 1:0.2:cd
                a = 90*j/cd;
                q = m+1+floor((m-i)*cosd(a));
                p = m+1+floor((m-i)*sind(a));
                ds(p,q,:) = s(1,floor(j),:);
              end
            end
             
            for i = 1:m
              u = image10(m+1-i,m:-1:i,:);
              v = image10(m+1-i:-1:1,i,:);
              s = [];
              s(:,:,1) = [u(:,:,1) v(:,:,1)'];
              s(:,:,2) = [u(:,:,2) v(:,:,2)'];
              s(:,:,3) = [u(:,:,3) v(:,:,3)'];
              cd = size(s,2);
              dss(i,i:i+cd-1,:) = s;
              R = m;
              for j = 1:0.2:cd
                a = 90*j/cd;
                q = m+1-floor((m-i)*cosd(90-a));
                p = m+1+floor((m-i)*sind(90-a));
                ds(p,q,:) = s(1,floor(j),:);
              end
            end
             
            imout = cast(ds,'uint8');
            app.PictureImage.ImageSource = imout;
            name = strcat(app.SaveTransformationAsEditField.Value,'.jpg');
            imwrite(imout, name, "jpg");
            app.updateImageList();
        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
               [file,path] = uigetfile('*.*','Select an Image');
               if ~isequal(file,0)
               fn=fullfile(path,file);
               [I,map] = imread(fn);
               figure('Name',file);
               imshow(I,map);
               app.PictureImage.ImageSource = I;
               app.updateImageList();
               app.SaveTransformationAsEditField.Enable = 'on';
            end 
        end

        % Callback function
        function SaveButtonPushed(app, event)
            name = app.SaveTransformationAsEditField.Value;
            imgsave = app.PictureImage;
            imwrite(imgsave, name, "jpg");
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            close all
            closereq
        end

        % Menu selected function: UpdateImageListMenu
        function UpdateImageListMenuSelected(app, event)
            h2 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
            im = getimage(h2);
            app.PictureImage.ImageSource = im;
        end

        % Callback function
        function UpdateImageButtonPushed(app, event)
            h2 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
            im = getimage(h2);
            app.PictureImage.ImageSource = im;
            app.SaveTransformationAsEditField.Enable = 'on';
        end

        % Value changed function: SaveTransformationAsEditField
        function SaveTransformationAsEditFieldValueChanged(app, event)
            value = app.SaveTransformationAsEditField.Value;
            app.TransformButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.UpdateImageListMenu = uimenu(app.FileMenu);
            app.UpdateImageListMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateImageListMenuSelected, true);
            app.UpdateImageListMenu.Text = 'Update Image List';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.TransformButton = uibutton(app.UIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Enable = 'off';
            app.TransformButton.Text = {'Transform'; ''};
            app.SelectedImageLabel = uilabel(app.UIFigure);
            app.SelectedImageLabel.Text = 'Selected Image:';
            app.SelectimageDropDown = uidropdown(app.UIFigure);
            app.SelectimageDropDown.Items = {'', ''};
            app.SelectimageDropDown.Enable = 'off';
            app.SelectimageDropDown.Value = '';
            app.PictureImage = uiimage(app.UIFigure);
            app.SaveTransformationAsEditFieldLabel = uilabel(app.UIFigure);
            app.SaveTransformationAsEditFieldLabel.Text = {'Save Transformation As:'; ''};
            app.SaveTransformationAsEditField = uieditfield(app.UIFigure, 'text');
            app.SaveTransformationAsEditField.ValueChangedFcn = createCallbackFcn(app, @SaveTransformationAsEditFieldValueChanged, true);
            app.TotransformanotherimagepleaseloadsaidimageLabel = uilabel(app.UIFigure);
            app.TotransformanotherimagepleaseloadsaidimageLabel.Text = {'To transform another image,'; 'please load said image.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL14_ImageFishEyeRotationApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end