classdef CeuL14_PowerSpectraApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PowerSpectraLabel             matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        ImageLabel                    matlab.ui.control.Label
        NoisetypeDropDownLabel        matlab.ui.control.Label
        NoisetypeDropDown             matlab.ui.control.DropDown
        GaussvarianceSliderLabel      matlab.ui.control.Label
        GaussvarianceSlider           matlab.ui.control.Slider
        SaltPepperdensitySliderLabel  matlab.ui.control.Label
        SaltPepperdensitySlider       matlab.ui.control.Slider
        SpecklevarianceSliderLabel    matlab.ui.control.Label
        SpecklevarianceSlider         matlab.ui.control.Slider
    end

    % Author: Pablo Carnero Olavarría    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function showSliders(app)
            
            if (strcmp(app.NoisetypeDropDown.Value, "Gaussian")==1)
                app.GaussvarianceSlider.Visible=true;
                app.GaussvarianceSliderLabel.Visible=true;
                app.SaltPepperdensitySlider.Visible=false;
                app.SaltPepperdensitySliderLabel.Visible=false;
                app.SpecklevarianceSlider.Visible=false;
                app.SpecklevarianceSliderLabel.Visible=false;
            end
            if (strcmp(app.NoisetypeDropDown.Value, "Salt & Pepper")==1)
                app.SaltPepperdensitySlider.Visible=true;
                app.SaltPepperdensitySliderLabel.Visible=true; 
                app.GaussvarianceSlider.Visible=false;
                app.GaussvarianceSliderLabel.Visible=false;  
                app.SpecklevarianceSlider.Visible=false;
                app.SpecklevarianceSliderLabel.Visible=false;
            end
            if (strcmp(app.NoisetypeDropDown.Value, "Speckle")==1)
                app.SpecklevarianceSlider.Visible=true;
                app.SpecklevarianceSliderLabel.Visible=true; 
                app.GaussvarianceSlider.Visible=false;
                app.GaussvarianceSliderLabel.Visible=false;
                app.SaltPepperdensitySlider.Visible=false;
                app.SaltPepperdensitySliderLabel.Visible=false;           
            end
            if (strcmp(app.NoisetypeDropDown.Value, "Poisson")==1)
                app.SpecklevarianceSlider.Visible=false;
                app.SpecklevarianceSliderLabel.Visible=false; 
                app.GaussvarianceSlider.Visible=false;
                app.GaussvarianceSliderLabel.Visible=false;
                app.SaltPepperdensitySlider.Visible=false;
                app.SaltPepperdensitySliderLabel.Visible=false;           
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.showSliders();
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;     
            
            idx = find(strcmp(app.NoisetypeDropDown.Items, ...
                                  app.NoisetypeDropDown.Value));
                
            var_gauss = app.GaussvarianceSlider.Value;
            d = app.SaltPepperdensitySlider.Value;
            var_speckle = app.SpecklevarianceSlider.Value;
            
                if idx==1
                    J = imnoise(I,'gaussian', 0, var_gauss);
                elseif idx==2
                    J = imnoise(I,'poisson');
                elseif idx==3
                    J = imnoise(I,'salt & pepper', d);
                elseif idx==4
                    J = imnoise(I,'speckle',var_speckle);
                end
            
            figure('Name', "Image with noise");
            imshow(J)
            
            I2 = rgb2gray(I);
            figure('Name', "Power spectrum of original image");
            imagesc( log10(abs(fftshift(fft2(I2))).^2 ));
            
            J2 = rgb2gray(J);
            figure('Name', "Power spectrum of image with noise");
            imagesc( log10(abs(fftshift(fft2(J2))).^2 ));
            
            %periodogram(double(J));
            
        end

        % Value changed function: NoisetypeDropDown
        function NoisetypeDropDownValueChanged(app, event)
            app.showSliders();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PowerSpectraLabel = uilabel(app.UIFigure);
            app.PowerSpectraLabel.Text = 'Power Spectra';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.NoisetypeDropDownLabel = uilabel(app.UIFigure);
            app.NoisetypeDropDownLabel.Text = 'Noise type';
            app.NoisetypeDropDown = uidropdown(app.UIFigure);
            app.NoisetypeDropDown.Items = {'Gaussian', 'Poisson', 'Salt & Pepper', 'Speckle'};
            app.NoisetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoisetypeDropDownValueChanged, true);
            app.NoisetypeDropDown.Value = 'Gaussian';
            app.GaussvarianceSliderLabel = uilabel(app.UIFigure);
            app.GaussvarianceSliderLabel.Text = 'Gauss variance';
            app.GaussvarianceSlider = uislider(app.UIFigure);
            app.GaussvarianceSlider.Limits = [0 1];
            app.SaltPepperdensitySliderLabel = uilabel(app.UIFigure);
            app.SaltPepperdensitySliderLabel.Text = {'Salt & Pepper'; 'density'};
            app.SaltPepperdensitySlider = uislider(app.UIFigure);
            app.SaltPepperdensitySlider.Limits = [0 1];
            app.SpecklevarianceSliderLabel = uilabel(app.UIFigure);
            app.SpecklevarianceSliderLabel.Text = 'Speckle variance';
            app.SpecklevarianceSlider = uislider(app.UIFigure);
            app.SpecklevarianceSlider.Limits = [0 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL14_PowerSpectraApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end