classdef CeuL14_WarpingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        WarpingLabel   matlab.ui.control.Label
        ExecuteButton  matlab.ui.control.Button
        ImageLabel     matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            %I = imread('/Users/gustavo/Downloads/r1Xt6.png');
imshow(I);
[x,y] = ginput(4);
[u,v] = ginput(4);

a1=x(1)*u(1);
a2=y(1)*u(1);
a3=x(1)*v(1);
a4=y(1)*v(1);

b1=x(2)*u(2);
b2=y(2)*u(2);
b3=x(2)*v(2);
b4=y(2)*v(2);

c1=x(3)*u(3);
c2=y(3)*u(3);
c3=x(3)*v(3);
c4=y(3)*v(3);

d1=x(4)*u(4);
d2=y(4)*u(4);
d3=x(4)*v(4);
d4=y(4)*v(4);


matrizA =[0 0 0 -x(1) -y(1) -1 a3 a4 v(1);
          x(1) y(1) 1 0 0 0 -a1 -a2 -u(1);
          0 0 0 -x(2) -y(2) -1 b3 b4 v(2);
          x(2) y(2) 1 0 0 0 -b1 -b2 -u(2);
          0 0 0 -x(3) -y(3) -1 c3 c4 v(3);
          x(3) y(3) 1 0 0 0 -c1 -c2 -u(3);
          0 0 0 -x(4) -y(4) -1 d3 d4 v(4);
          x(4) y(4) 1 0 0 0 -d1 -d2 -u(4);];
[U,S,V] = svd(matrizA); 


vsubk = transpose(V(:,9));
matrizH = [vsubk(1) vsubk(2) vsubk(3);
           vsubk(4) vsubk(5) vsubk(6);
           vsubk(7) vsubk(8) vsubk(9)];
invH = inv(matrizH); 
invH=invH/invH(3,3);
[R,C,B] = size(I);
J = zeros(R,C,B);

for rj = 1 : R
    for cj = 1 : C
        Ri = invH*[cj;rj;1];
        J(rj,cj,1) = interp2(I(:,:,1),Ri(1),Ri(2));
        J(rj,cj,2) = interp2(I(:,:,2),Ri(1),Ri(2));
        J(rj,cj,3) = interp2(I(:,:,3),Ri(1),Ri(2));
    end     
end 
imshow(uint8(J))
           
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WarpingLabel = uilabel(app.UIFigure);
            app.WarpingLabel.Text = 'Warping';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL14_WarpingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end