classdef CeuL14_affine2DtransformationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Affine2DtransformationLabel  matlab.ui.control.Label
        ExecuteButton                matlab.ui.control.Button
        ImageLabel                   matlab.ui.control.Label
        a11EditFieldLabel            matlab.ui.control.Label
        a11EditField                 matlab.ui.control.NumericEditField
        a12EditFieldLabel            matlab.ui.control.Label
        a12EditField                 matlab.ui.control.NumericEditField
        a21EditFieldLabel            matlab.ui.control.Label
        a21EditField                 matlab.ui.control.NumericEditField
        a22EditFieldLabel            matlab.ui.control.Label
        a22EditField                 matlab.ui.control.NumericEditField
        Image                        matlab.ui.control.Image
        txEditFieldLabel             matlab.ui.control.Label
        txEditField                  matlab.ui.control.NumericEditField
        tyEditFieldLabel             matlab.ui.control.Label
        tyEditField                  matlab.ui.control.NumericEditField
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            
            a11 = app.a11EditField.Value;
            a12 = app.a12EditField.Value;
            a21 = app.a21EditField.Value;
            a22 = app.a22EditField.Value;
            tx = app.txEditField.Value;
            ty = app.tyEditField.Value;
            
            tform = affine2d([a11 a12 0; a21 a22 0; tx ty 1]);
            I_transformation = imwarp(I,tform);
            
            name1 = sprintf(" original");
            name2 = sprintf(" with a 2D affine transformation");
            windowName_1 = strcat(windowName,name1);
            windowName_2 = strcat(windowName,name2);
            
            %app.h.Name = windowName_1;
%             image(I);
%             figure('Name',windowName_2);
%             image(I_transformation);
%             
            figure('Name',windowName);
            subplot(1,2,1);
            imshow(I);
            title(windowName_1);
            subplot(1,2,2);
            imshow(I_transformation);
            title(windowName_2);
            
            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Affine2DtransformationLabel = uilabel(app.UIFigure);
            app.Affine2DtransformationLabel.Text = 'Affine 2D transformation';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.a11EditFieldLabel = uilabel(app.UIFigure);
            app.a11EditFieldLabel.Text = 'a11';
            app.a11EditField = uieditfield(app.UIFigure, 'numeric');
            app.a11EditField.Value = 1;
            app.a12EditFieldLabel = uilabel(app.UIFigure);
            app.a12EditFieldLabel.Text = 'a12';
            app.a12EditField = uieditfield(app.UIFigure, 'numeric');
            app.a21EditFieldLabel = uilabel(app.UIFigure);
            app.a21EditFieldLabel.Text = 'a21';
            app.a21EditField = uieditfield(app.UIFigure, 'numeric');
            app.a21EditField.Value = 0.5;
            app.a22EditFieldLabel = uilabel(app.UIFigure);
            app.a22EditFieldLabel.Text = 'a22';
            app.a22EditField = uieditfield(app.UIFigure, 'numeric');
            app.a22EditField.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'affine_transformation.png';
            app.txEditFieldLabel = uilabel(app.UIFigure);
            app.txEditFieldLabel.Text = 'tx';
            app.txEditField = uieditfield(app.UIFigure, 'numeric');
            app.tyEditFieldLabel = uilabel(app.UIFigure);
            app.tyEditFieldLabel.Text = 'ty';
            app.tyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL14_affine2DtransformationApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end