classdef CeuL15_ImageFormationPSFApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChangePSFtypeDropDown       matlab.ui.control.DropDown
        ChangePSFtypeDropDownLabel  matlab.ui.control.Label
        ImagewithanPSFtypeplusNoiseLabel  matlab.ui.control.Label
        ImagewithspecifiedMotionPSFLabel  matlab.ui.control.Label
        BlurAngleSlider             matlab.ui.control.Slider
        BlurAngleSliderLabel        matlab.ui.control.Label
        NoiseLevelSlider            matlab.ui.control.Slider
        NoiseLevelSliderLabel       matlab.ui.control.Label
        BlurLengthSlider            matlab.ui.control.Slider
        BlurLengthSliderLabel       matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        ImageLabel                  matlab.ui.control.Label
        ImageFormationwithPSFNoiseLabel  matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I % Image without noise
        H % Image convolved with PSF
        J % Image to which noise is added 
    
        % J = I(orignal)* H(point spread function) + N (added noise)
        
    end
    
    properties (Access = public)
        PSF
        noise_var
        
     end 
    
    methods (Access = public)
 
    end
    methods (Access = private)
        
        function applyPSFmotion(app)
            
            app.PSF = fspecial('motion',app.BlurLengthSlider.Value,app.BlurAngleSlider.Value); 
           
            app.H = zeros(size(app.I));
            
            
            %PSF convolved with the original clear image
            app.H(:,:,1) = imfilter(app.I(:,:,1),app.PSF,'circular','conv');
            app.H(:,:,2) = imfilter(app.I(:,:,2),app.PSF,'circular','conv');
            app.H(:,:,3) = imfilter(app.I(:,:,3),app.PSF,'circular','conv');
            app.Image.ImageSource = uint8(app.H);
            app.Image2.ImageSource = uint8(app.H);

            
        end
        
        function applyPSFdisk(app)
            
            app.PSF = fspecial('disk',10); 
           
            app.H = zeros(size(app.I));
            
            
            app.H(:,:,1) = imfilter(app.I(:,:,1),app.PSF,'circular','conv');
            app.H(:,:,2) = imfilter(app.I(:,:,2),app.PSF,'circular','conv');
            app.H(:,:,3) = imfilter(app.I(:,:,3),app.PSF,'circular','conv');
            app.Image2.ImageSource = uint8(app.H);

            
        end
        
       function applyPSFgauss(app)
            
            app.PSF = fspecial('gaussian',8,15); 
           
            app.H = zeros(size(app.I));
            
            
            %PSF convolved with the original clear image
            app.H(:,:,1) = imfilter(app.I(:,:,1),app.PSF,'circular','conv');
            app.H(:,:,2) = imfilter(app.I(:,:,2),app.PSF,'circular','conv');
            app.H(:,:,3) = imfilter(app.I(:,:,3),app.PSF,'circular','conv');
            app.Image2.ImageSource = uint8(app.H);

            
       end
       
       function applyPSFsob(app)
            
            app.PSF = fspecial('sobel'); 
           
            app.H = zeros(size(app.I));
            
            
            %PSF convolved with the original clear image
            app.H(:,:,1) = imfilter(app.I(:,:,1),app.PSF,'circular','conv');
            app.H(:,:,2) = imfilter(app.I(:,:,2),app.PSF,'circular','conv');
            app.H(:,:,3) = imfilter(app.I(:,:,3),app.PSF,'circular','conv');
            app.Image2.ImageSource = uint8(app.H);

            
        end
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.Image.ImageSource=app.h.Name;
            app.I = getimage(app.h);     
            
        end

        % Value changed function: BlurLengthSlider
        function BlurLengthSliderValueChanged(app, event)

            app.applyPSFmotion();
            
        end

        % Value changed function: NoiseLevelSlider
        function NoiseLevelSliderValueChanged(app, event)

           %Noise is added to the now degraded image
            value = app.NoiseLevelSlider.Value;
            app.J=zeros(size(app.H));
            Hmax=double(max(app.H(:)));
            varianza = (Hmax * value/100)^2;
  
            app.J(:,:,1) = app.H(:,:,1)+sqrt(varianza)*randn(size(app.H(:,:,1))); 
            app.J(:,:,2) = app.H(:,:,2)+sqrt(varianza)*randn(size(app.H(:,:,2)));
            app.J(:,:,3) = app.H(:,:,3)+sqrt(varianza)*randn(size(app.H(:,:,3)));
           
            app.Image2.ImageSource = uint8(app.J);
        end

        % Value changed function: BlurAngleSlider
        function BlurAngleSliderValueChanged(app, event)

            app.applyPSFmotion();
            
        end

        % Value changed function: ChangePSFtypeDropDown
        function ChangePSFtypeDropDownValueChanged(app, event)
         %   value = app.ChangePSFtypeDropDown.Value;
            
         option = find(strcmp(app.ChangePSFtypeDropDown.Items,app.ChangePSFtypeDropDown.Value));
            
             if option==1
                    app.applyPSFmotion();

                   % windowName = strcat(windowName," (Motion)");
             elseif option==2
                    app.applyPSFdisk();
                    
             elseif option==3
                    app.applyPSFgauss();
            
             elseif option==4
                    app.applyPSFsob();
                 
             end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageFormationwithPSFNoiseLabel = uilabel(app.UIFigure);
            app.ImageFormationwithPSFNoiseLabel.Text = 'Image Formation with PSF + Noise';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.BlurLengthSliderLabel = uilabel(app.UIFigure);
            app.BlurLengthSliderLabel.Text = 'Blur Length';
            app.BlurLengthSlider = uislider(app.UIFigure);
            app.BlurLengthSlider.ValueChangedFcn = createCallbackFcn(app, @BlurLengthSliderValueChanged, true);
            app.NoiseLevelSliderLabel = uilabel(app.UIFigure);
            app.NoiseLevelSliderLabel.Text = 'Noise Level';
            app.NoiseLevelSlider = uislider(app.UIFigure);
            app.NoiseLevelSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.NoiseLevelSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseLevelSliderValueChanged, true);
            app.BlurAngleSliderLabel = uilabel(app.UIFigure);
            app.BlurAngleSliderLabel.Text = 'Blur Angle';
            app.BlurAngleSlider = uislider(app.UIFigure);
            app.BlurAngleSlider.MajorTickLabels = {'0', '15', '45', '75', '90', '135', '180'};
            app.BlurAngleSlider.ValueChangedFcn = createCallbackFcn(app, @BlurAngleSliderValueChanged, true);
            app.ImagewithspecifiedMotionPSFLabel = uilabel(app.UIFigure);
            app.ImagewithspecifiedMotionPSFLabel.Text = 'Image with specified ''Motion'' PSF';
            app.ImagewithanPSFtypeplusNoiseLabel = uilabel(app.UIFigure);
            app.ImagewithanPSFtypeplusNoiseLabel.Text = 'Image with an PSF type plus Noise';
            app.ChangePSFtypeDropDownLabel = uilabel(app.UIFigure);
            app.ChangePSFtypeDropDownLabel.Text = 'Change PSF type: ';
            app.ChangePSFtypeDropDown = uidropdown(app.UIFigure);
            app.ChangePSFtypeDropDown.Items = {'Motion Blur', 'Disk Blur', 'Gaussian', 'Smoothen (edges)'};
            app.ChangePSFtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePSFtypeDropDownValueChanged, true);
            app.ChangePSFtypeDropDown.Value = 'Motion Blur';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL15_ImageFormationPSFApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end