classdef CeuL15_MultipleNoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ComputemultiplenoisesLabel  matlab.ui.control.Label
        ExecuteButton               matlab.ui.control.Button
        ImageLabel                  matlab.ui.control.Label
        TypeofnoiseButtonGroup      matlab.ui.container.ButtonGroup
        gaussianButton              matlab.ui.control.RadioButton
        saltpepperButton            matlab.ui.control.RadioButton
        speckleButton               matlab.ui.control.RadioButton
        NumberofrealizationsSliderLabel  matlab.ui.control.Label
        NumberofrealizationsSlider  matlab.ui.control.Slider
        NoiseintensitySliderLabel   matlab.ui.control.Label
        NoiseintensitySlider        matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            R = I(:,:,1);
            G = I(:,:,2);
            B = I(:,:,3);
            I_noiseR=uint8(zeros(size(R)));
            I_noiseG=uint8(zeros(size(G)));
            I_noiseB=uint8(zeros(size(B)));
            value = round(app.NumberofrealizationsSlider.Value);
            windowName = app.h.Name;
            J1=I;
            I_noise=uint8(I);
            Z=uint8(zeros(size(R)));
            c1 = zeros(size(I(:,:,1))*2-1);
            pxx1 = abs(fft2(fftshift(J1(:,:,1)))).^2;
            K= (app.NoiseintensitySlider.Value);
            maxRGB=double(max(I(:)));
            if app.gaussianButton.Value
                user_noise = "gaussian";
                for i=1:1:value
                   I_noiseR=I_noiseR+uint8(K*maxRGB*randn(size(Z)));
                   I_noiseG=I_noiseG+uint8(K*maxRGB*randn(size(Z)));
                   I_noiseB=I_noiseB+uint8(K*maxRGB*randn(size(Z)));
%                     I_noiseR = imnoise(I_noiseR, user_noise, 0, K);
%                     I_noiseG = imnoise(I_noiseG, user_noise, 0, K);
%                     I_noiseB = imnoise(I_noiseB, user_noise, 0, K);
                    c1 = c1 + xcorr2(I_noiseR);%for finding the autocorrelation
                    pxx1 = pxx1 + abs(fftshift(fft2(I_noiseR))).^2;%for the pds
                end
            end
            if app.saltpepperButton.Value
                user_noise = "salt & pepper";
                for i=1:1:value
                    I_noiseR = imnoise(I_noiseR, user_noise, K);
                    I_noiseG = imnoise(I_noiseG, user_noise, K);
                    I_noiseB = imnoise(I_noiseB, user_noise, K);
                    c1 = c1 + xcorr2(I_noiseR);%for finding the autocorrelation
                    pxx1 = pxx1 + abs(fftshift(fft2(I_noiseR))).^2;%for the pds
                end
            end
            if app.speckleButton.Value
                user_noise = "speckle";
                for i=1:1:value
                    I_noiseR = imnoise(R, user_noise, K);
                    I_noiseG = imnoise(G, user_noise, K);
                    I_noiseB = imnoise(B, user_noise, K);
                    c1 = c1 + xcorr2(I_noiseR);%for finding the autocorrelation
                    pxx1 = pxx1 + abs(fftshift(fft2(I_noiseR))).^2;%for the pds
                end
            end
                figure('Name',strcat(windowName, user_noise));
                subplot(1,2,1)
                J=uint8(zeros(size(I)));
                J(:,:,1)=R+I_noiseR;
                J(:,:,2)=G+I_noiseG;                                
                J(:,:,3)=B+I_noiseB;
                imshow(uint8(J));
                title("Image with " + user_noise);
                subplot(1,2,2)
                I_noise(:,:,1)=I_noiseR;
                I_noise(:,:,2)=I_noiseG;
                I_noise(:,:,3)=I_noiseB;
                imhist(I_noise);
                title("Noise Histogram");
                hf = figure('Name',strcat(windowName, "Noise Autocorrelation" ));
                surf(log10(c1));
                figure('Name',strcat(windowName, "Noise Autocorrelation 2D" ));
                imagesc(log10(c1));
                colormap(hf,'hot');
                title("Image Autocorrelation");
                hd = figure('Name',strcat(windowName, "PSD"));
                wx=2*pi*(-size(pxx1,2)/2+[0:size(pxx1,2)-1])/size(pxx1,2);
                wy=2*pi*(-size(pxx1,1)/2+[0:size(pxx1,1)-1])/size(pxx1,1);
                surf(wy,wx,log10(pxx1));
                colormap(hd,'autumn');
                title("Image PSD");
                figure('Name',strcat(windowName, "PSD in 2D"));
                imagesc(log10(pxx1));
                title("Image PSD");
                app.parentApp.updateImageList();
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.ComputemultiplenoisesLabel = uilabel(app.UIFigure);
            app.ComputemultiplenoisesLabel.Text = 'Compute multiple noises';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.TypeofnoiseButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeofnoiseButtonGroup.Title = 'Type of noise';
            app.gaussianButton = uiradiobutton(app.TypeofnoiseButtonGroup);
            app.gaussianButton.Text = 'gaussian';
            app.gaussianButton.Value = true;
            app.saltpepperButton = uiradiobutton(app.TypeofnoiseButtonGroup);
            app.saltpepperButton.Text = 'salt & pepper';
            app.speckleButton = uiradiobutton(app.TypeofnoiseButtonGroup);
            app.speckleButton.Text = 'speckle';
            app.NumberofrealizationsSliderLabel = uilabel(app.UIFigure);
            app.NumberofrealizationsSliderLabel.Text = 'Number of realizations';
            app.NumberofrealizationsSlider = uislider(app.UIFigure);
            app.NumberofrealizationsSlider.Limits = [0 10];
            app.NoiseintensitySliderLabel = uilabel(app.UIFigure);
            app.NoiseintensitySliderLabel.Text = 'Noise intensity';
            app.NoiseintensitySlider = uislider(app.UIFigure);
            app.NoiseintensitySlider.Limits = [0 0.05];
        end
    end

    methods (Access = public)

        % Construct app
        function app = CeuL15_MultipleNoiseApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end