classdef CeuL15_WienerFilteringApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        KernelSizeSlider       matlab.ui.control.Slider
        KernelSizeSliderLabel  matlab.ui.control.Label
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        NoiseLevelSlider       matlab.ui.control.Slider
        NoiseLevelSliderLabel  matlab.ui.control.Label
        ImageLabel             matlab.ui.control.Label
        WienerFilteringLabel   matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I % Image without noise
        J % Image to which noise is added 
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function filter(app)
            val_size = round(app.KernelSizeSlider.Value);            
            K = zeros(size(app.J));
            K(:,:,1)= wiener2(app.J(:,:,1),[val_size val_size]);
            K(:,:,2)= wiener2(app.J(:,:,2),[val_size val_size]);
            K(:,:,3)= wiener2(app.J(:,:,3),[val_size val_size]);
         
            app.Image2.ImageSource = uint8(K);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.Image.ImageSource=app.h.Name;
            app.I = getimage(app.h);
            
        end

        % Value changed function: NoiseLevelSlider
        function NoiseLevelSliderValueChanged(app, event)
            value = app.NoiseLevelSlider.Value;            
            app.J=zeros(size(app.I));
            Imax=double(max(app.I(:)));
            varianza = (Imax/400 * value/100)^2;
            app.J(:,:,1) = imnoise(app.I(:,:,1),'gaussian',0,varianza);
            app.J(:,:,2) = imnoise(app.I(:,:,2),'gaussian',0,varianza);
            app.J(:,:,3) = imnoise(app.I(:,:,3),'gaussian',0,varianza);
            app.Image.ImageSource = uint8(app.J);
            
            app.filter();
        end

        % Value changed function: KernelSizeSlider
        function KernelSizeSliderValueChanged(app, event)
            app.filter();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WienerFilteringLabel = uilabel(app.UIFigure);
            app.WienerFilteringLabel.Text = 'Wiener Filtering';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.NoiseLevelSliderLabel = uilabel(app.UIFigure);
            app.NoiseLevelSliderLabel.Text = 'Noise Level';
            app.NoiseLevelSlider = uislider(app.UIFigure);
            app.NoiseLevelSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseLevelSliderValueChanged, true);
            app.NoiseLevelSlider.Value = 50;
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.KernelSizeSliderLabel = uilabel(app.UIFigure);
            app.KernelSizeSliderLabel.Text = 'Kernel Size';
            app.KernelSizeSlider = uislider(app.UIFigure);
            app.KernelSizeSlider.Limits = [3 11];
            app.KernelSizeSlider.ValueChangedFcn = createCallbackFcn(app, @KernelSizeSliderValueChanged, true);
            app.KernelSizeSlider.MinorTicks = [];
            app.KernelSizeSlider.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL15_WienerFilteringApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end