classdef CeuL16_NotchFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FilteredimageLabel   matlab.ui.control.Label
        FT2Label             matlab.ui.control.Label
        ImagewithnoiseLabel  matlab.ui.control.Label
        EditArea             matlab.ui.control.NumericEditField
        AreaLabel            matlab.ui.control.Label
        ImageLabel           matlab.ui.control.Label
        ExecuteButton        matlab.ui.control.Button
        NotchFilterLabel     matlab.ui.control.Label
        FilteredImage        matlab.ui.control.UIAxes
        FilterMask           matlab.ui.control.UIAxes
        OriginalImage        matlab.ui.control.UIAxes
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            I = getimage(app.h);
            imshow(uint8(I),'Parent',app.OriginalImage);
            axis(app.OriginalImage,'off');
            axis(app.FilterMask,'off');
            axis(app.FilteredImage,'off');
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            [m n numberOfColorBands] = size(I);
            if numberOfColorBands > 1
            	grayImage = rgb2gray(I);
                I = grayImage;
            end
            value = app.EditArea.Value;
            figure;
            FT2=fftshift(fft2(I));
            im = log10(abs(FT2));
            imagesc(im);           
            [x,y] = ginput; 
            x2 = round(x);
            y2 = round(y);

            [R,C]=size(I);
            for n=1:length(x2)
                xn=x2(n);
                yn=y2(n);
                for i=-value:value
                    y=yn+i;
                    if y>0 && y<R
                        for j = -value:value
                            x=xn+j;
                            if x>0 && x<C
                                FT2(y,x)=0;
                                y3=R-y;
                                x3=C-x;
                                FT2(y3,x3)=0;
                            end
                        end
                    end
                end
            end
      
            imshow(log10(abs(FT2)), 'Parent',app.FilterMask);
            J=real(ifft2(ifftshift(FT2)));
            imshow(int8(J),'Parent',app.FilteredImage); 
            % colormap gray does not work in the image that I want

              app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiaxes(app.UIFigure);
            app.FilterMask = uiaxes(app.UIFigure);
            app.FilteredImage = uiaxes(app.UIFigure);
            app.NotchFilterLabel = uilabel(app.UIFigure);
            app.NotchFilterLabel.Text = 'Notch Filter';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.AreaLabel = uilabel(app.UIFigure);
            app.AreaLabel.Text = 'Area';
            app.EditArea = uieditfield(app.UIFigure, 'numeric');
            app.ImagewithnoiseLabel = uilabel(app.UIFigure);
            app.ImagewithnoiseLabel.Text = 'Image with noise';
            app.FT2Label = uilabel(app.UIFigure);
            app.FT2Label.Text = 'FT2';
            app.FilteredimageLabel = uilabel(app.UIFigure);
            app.FilteredimageLabel.Text = 'Filtered image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL16_NotchFilterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end