classdef CeuL17_ColorMedianFilterFunctionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ColorMedianFilterLabel  matlab.ui.control.Label
        ExecuteButton           matlab.ui.control.Button
        ShowImagei              matlab.ui.control.UIAxes
        Slider_2Label           matlab.ui.control.Label
        Slider_2                matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            
            I = getimage(app.h);
            image(app.ShowImagei,I);
            axis(app.ShowImagei,'off');
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            
            [R,C,B]=size(I);
            delta=round(app.Slider_2.Value);
            
            Ifilt = zeros(size(I));
            for r=(1+delta):(R-delta)
                for c=(1+delta):(C-delta)
                    NN=I(((r-delta):(r+delta)),((c-delta):(c+delta)),:);
                    bestDist=1;
                    bestVk=[0,0,0];
                    X=double(reshape(NN,[((2*delta+1)*(2*delta+1)),3]));
                    [bestDist, bestk]=min(sum(squareform(pdist(X))));
                    bestVk=X(bestk,:);
                Ifilt(r,c,:)=bestVk;
                end
            end
            
             
             figure;
             imshow(uint8(Ifilt));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorMedianFilterLabel = uilabel(app.UIFigure);
            app.ColorMedianFilterLabel.Text = 'Color Median Filter';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ShowImagei = uiaxes(app.UIFigure);
            title(app.ShowImagei, 'Input image')
            xlabel(app.ShowImagei, 'X')
            ylabel(app.ShowImagei, 'Y')
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 10];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL17_ColorMedianFilterFunctionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end