classdef CeuL18_BinaryReconstructionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BinaryReconstructionProcessLabel  matlab.ui.control.Label
        ImageLabel                    matlab.ui.control.Label
        imageAxis                     matlab.ui.control.UIAxes
        imageAxisReconstructed        matlab.ui.control.UIAxes
        StructuralElementSliderLabel  matlab.ui.control.Label
        StructuralElementSlider       matlab.ui.control.Slider
        NoiseSliderLabel              matlab.ui.control.Label
        NoiseSlider                   matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I         % image used 
        Inoisy    % image with noise 
        Ibin      % Binary image 
     
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function  J=binReconFunction(app, valueSE, Inoisy)
            %The structuring element is the same for every step
            SE = strel ('disk', valueSE, valueSE);
            
            %First step: image opening 
            J = imopen(Inoisy, SE);

            %Second step
            T = J; 
             
            proceed=1;
            while proceed         
                 %Third step: image dilation
                 J = imdilate(J, SE);
                 
                 %Fourth step: we do the AND of Inoisy and J 
                 J = and(Inoisy, J);
                 
                 %Fifth step: check if J is not the same as T, is not go to step
                 %2, if they're the same is the reconstructed image you need to
                 %show in the image axis 
                 proceed = sum(J(:) ~= T(:)) > 0;

                 T = J;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
          
           %First, transform the image into a binary image
           app.I = getimage(h);
           app.Ibin = (uint8 (imbinarize(app.I)))*255; %we're casting a logical (binary 0 and 1s) image
           
        end

        % Value changed function: StructuralElementSlider
        function StructuralElementSliderValueChanged(app, event)

            valueSE = round(app.StructuralElementSlider.Value);
            refValues = [0 4 6 8]; %we add this code line to take only this four values for the reconstruction
            [m, idx] = min(abs(refValues-valueSE));
            
            %Im going to apply this for each channel
            J=zeros(size(app.Inoisy));
            J(:,:,1)=binReconFunction(app, refValues(idx), app.Inoisy(:,:,1));
            J(:,:,2)=binReconFunction (app, refValues(idx), app.Inoisy(:,:,2));
            J(:,:,3)=binReconFunction (app, refValues(idx), app.Inoisy(:,:,3));
            
            image(app.imageAxisReconstructed, uint8(J*255));
            axis(app.imageAxisReconstructed, 'off');
            
            %binReconFunction(app, refValues(idx));
            
            
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            value = app.NoiseSlider.Value;
            
            probability= value/100;
           
            app.Inoisy = imnoise(app.Ibin,'salt & pepper', probability);
            
            %show the salt & pepper noisy image in the first axis
            colormap(app.imageAxis, gray);
            image(app.imageAxis, app.Inoisy);
            axis(app.imageAxis, 'off');
           
            %now we do the BINARY RECONSTRUCTION--> function
            %binReconFunction and the sliders callback 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BinaryReconstructionProcessLabel = uilabel(app.UIFigure);
            app.BinaryReconstructionProcessLabel.Text = 'Binary Reconstruction Process';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.imageAxis = uiaxes(app.UIFigure);
            title(app.imageAxis, 'Salt & Pepper Image')
            xlabel(app.imageAxis, '')
            ylabel(app.imageAxis, '')
            app.imageAxis.XTick = [];
            app.imageAxis.YTick = [];
            app.imageAxisReconstructed = uiaxes(app.UIFigure);
            title(app.imageAxisReconstructed, 'Reconstructed Image')
            xlabel(app.imageAxisReconstructed, '')
            ylabel(app.imageAxisReconstructed, '')
            app.imageAxisReconstructed.XTick = [];
            app.imageAxisReconstructed.YTick = [];
            app.StructuralElementSliderLabel = uilabel(app.UIFigure);
            app.StructuralElementSliderLabel.Text = {'Structural Element '; ''};
            app.StructuralElementSlider = uislider(app.UIFigure);
            app.StructuralElementSlider.Limits = [0 8];
            app.StructuralElementSlider.MajorTicks = [0 4 6 8];
            app.StructuralElementSlider.MajorTickLabels = {'0', '4', '6', '8'};
            app.StructuralElementSlider.ValueChangedFcn = createCallbackFcn(app, @StructuralElementSliderValueChanged, true);
            app.StructuralElementSlider.MinorTicks = [];
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL18_BinaryReconstructionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end