classdef CeuL18_Binary_images_operations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DilatationerosionopeningandclosingofBinaryImagesLabel  matlab.ui.control.Label
        ImageLabel                    matlab.ui.control.Label
        BinaryoperationDropDownLabel  matlab.ui.control.Label
        BinaryoperationDropDown       matlab.ui.control.DropDown
        Image                         matlab.ui.control.Image
        DimenssionsSliderLabel        matlab.ui.control.Label
        DimenssionsSlider             matlab.ui.control.Slider
        Slider                        matlab.ui.control.Slider
        TypeofstructureDropDownLabel  matlab.ui.control.Label
        TypeofstructureDropDown       matlab.ui.control.DropDown
        ExecuteButton                 matlab.ui.control.Button
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function apply(app)
            I = getimage(app.h);
            windowName = app.h.Name;
           
            newImage = zeros(size(I), "uint8");
            
            option = app.BinaryoperationDropDown.Value;
            structure = app.TypeofstructureDropDown.Value;
            x = app.DimenssionsSlider.Value;
            y = app.Slider.Value;
            
            if strcmp(structure,'Line')
                se = strel('line',x,y);
            elseif strcmp(structure,'Cube')
                    se = strel('cube', 6);
                elseif strcmp(structure,'Square')
                        se = strel('square',7);
                    elseif strcmp(structure,'Disk')
                            se = strel('disk', 8)
            end
            
            if strcmp(option,'Dilatation')
                newImage = imdilate(I,se);
            elseif strcmp(option,'Erosion')
                    newImage = imerode(I,se);
                elseif strcmp(option,'Closing')
                        newImage = imclose(I,se);
                    elseif strcmp(option,'Opening')
                            newImage = imopen(I,se);
            end
            
%             figure();
%             imshow(newImage)
            app.Image.ImageSource = newImage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            apply(app);
        end

        % Value changed function: BinaryoperationDropDown
        function BinaryoperationDropDownValueChanged(app, event)
            %apply(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DilatationerosionopeningandclosingofBinaryImagesLabel = uilabel(app.UIFigure);
            app.DilatationerosionopeningandclosingofBinaryImagesLabel.Text = 'Dilatation, erosion, opening and closing of Binary Images';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.BinaryoperationDropDownLabel = uilabel(app.UIFigure);
            app.BinaryoperationDropDownLabel.Text = 'Binary operation';
            app.BinaryoperationDropDown = uidropdown(app.UIFigure);
            app.BinaryoperationDropDown.Items = {'Dilatation', 'Erosion', 'Opening', 'Closing'};
            app.BinaryoperationDropDown.ValueChangedFcn = createCallbackFcn(app, @BinaryoperationDropDownValueChanged, true);
            app.BinaryoperationDropDown.Value = 'Dilatation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '200px-Example_binary.png';
            app.DimenssionsSliderLabel = uilabel(app.UIFigure);
            app.DimenssionsSliderLabel.Text = 'Dimenssions';
            app.DimenssionsSlider = uislider(app.UIFigure);
            app.Slider = uislider(app.UIFigure);
            app.TypeofstructureDropDownLabel = uilabel(app.UIFigure);
            app.TypeofstructureDropDownLabel.Text = 'Type of structure';
            app.TypeofstructureDropDown = uidropdown(app.UIFigure);
            app.TypeofstructureDropDown.Items = {'Line', 'Cube', 'Square', 'Disk'};
            app.TypeofstructureDropDown.Value = 'Line';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL18_Binary_images_operations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end