classdef CeuL18_SkeletonsFunctionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SkeletonComputationLabel  matlab.ui.control.Label
        ExecuteButton             matlab.ui.control.Button
        ShowImage                 matlab.ui.control.UIAxes
        ShowImageOutput           matlab.ui.control.UIAxes
        ExportButton              matlab.ui.control.Button
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
           app.parentApp = parentApp;
            app.h = h;
           
            I = getimage(app.h);
            windowName = app.h.Name;
             image(app.ShowImage,I);
            axis(app.ShowImage,'off');
     
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
           I = getimage(app.h);
           
           J = bwskel(imbinarize(I));
            image(app.ShowImageOutput,J);
            axis(app.ShowImageOutput,'off');
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
           I = getimage(app.h);
            
              J = bwskel(imbinarize(I));
              figure;
              image(J);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SkeletonComputationLabel = uilabel(app.UIFigure);
            app.SkeletonComputationLabel.Text = 'Skeleton Computation';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ShowImage = uiaxes(app.UIFigure);
            title(app.ShowImage, 'Input Image')
            xlabel(app.ShowImage, 'X')
            ylabel(app.ShowImage, 'Y')
            app.ShowImageOutput = uiaxes(app.UIFigure);
            title(app.ShowImageOutput, 'Output Image')
            xlabel(app.ShowImageOutput, 'X')
            ylabel(app.ShowImageOutput, 'Y')
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = {'Export'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL18_SkeletonsFunctionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end