classdef CeuL19_DilationErisionOpeningClosingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputImage                     matlab.ui.control.UIAxes
        InputImage                      matlab.ui.control.UIAxes
        ExecuteButton                   matlab.ui.control.Button
        GrayscaleMorphologyButtonGroup  matlab.ui.container.ButtonGroup
        DilationButton                  matlab.ui.control.RadioButton
        ErosionButton                   matlab.ui.control.RadioButton
        OpeningButton                   matlab.ui.control.RadioButton
        ClosingButton                   matlab.ui.control.RadioButton
        StructuringElementSizeSpinnerLabel  matlab.ui.control.Label
        StructuringElementSizeSpinner   matlab.ui.control.Spinner
        IntroduceanaturalnumberLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        StructuringElementshapeDropDownLabel  matlab.ui.control.Label
        StructuringElementshapeDropDown  matlab.ui.control.DropDown
        DiskshapedStructuringElementPanel  matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        ItrepresentstheradiusofthediskshapedLabel  matlab.ui.control.Label
        structuringelementusedforGrayscaleLabel  matlab.ui.control.Label
        morphologyoperationsLabel       matlab.ui.control.Label
        Exampleforaradius4thematrixthatLabel  matlab.ui.control.Label
        representstheStructuringElementwillbeLabel  matlab.ui.control.Label
        Label_71                        matlab.ui.control.Label
        DiamondshapedStructuringElementPanel  matlab.ui.container.Panel
        Label_69                        matlab.ui.control.Label
        ItrepresentsthedistancefromtheoriginofLabel  matlab.ui.control.Label
        thestructuringelementuptothepointsoftheLabel  matlab.ui.control.Label
        diamondLabel                    matlab.ui.control.Label
        Exampleforadistance4theLabel    matlab.ui.control.Label
        matrixthatrepresentstheStructuringElementLabel  matlab.ui.control.Label
        Label_70                        matlab.ui.control.Label
        OctagonshapedStructuringElementPanel  matlab.ui.container.Panel
        Label_72                        matlab.ui.control.Label
        ItrepresentsthedistancefromtheoriginofLabel_2  matlab.ui.control.Label
        thestructuringelementuptothepointsoftheLabel_2  matlab.ui.control.Label
        sidesoftheoctagonmeasuredalongtheLabel  matlab.ui.control.Label
        horizontalandverticalaxesItmustbeanonLabel  matlab.ui.control.Label
        negativemultipleof3ExampleforaLabel  matlab.ui.control.Label
        distance3thematrixwillbeLabel   matlab.ui.control.Label
        Label_73                        matlab.ui.control.Label
        SquareshapedStructuringElementPanel  matlab.ui.container.Panel
        Label_74                        matlab.ui.control.Label
        ItrepresentsthewidthofthesquareLabel  matlab.ui.control.Label
        Label_75                        matlab.ui.control.Label
        StructuringElementExampleforawidth4Label  matlab.ui.control.Label
        mustbeanonnegativenumberLabel   matlab.ui.control.Label
        multipleof3Label                matlab.ui.control.Label
        PleaseusealowervalueLabel       matlab.ui.control.Label
        UpdateImageButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        parentApp;
        h;
        original_image;
        input_imageGray;
        input_image;
        se;
        outputI;
        isgray;

        
    end
    
    methods (Access = private)
        
        function dilatedI = dilation(app, I)
            image_copy=I;
            if app.isgray
                I_g=I;
                t=size(I_g);
                r=t(1);
                c=t(2);
                nh=app.se.Neighborhood;
                tam=size(nh);
                r_k=tam(1);
                c_k=tam(2);
                result=ones(r,c);
                cpad=floor(c_k/2);
                rpad=floor(r_k/2);
                hcorig=cpad+1;
                hrorig=rpad+1;
                aux=zeros(r+2*rpad,c+2*cpad);
                aux(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=I_g;           
                for i=hrorig:hrorig+r-1
                    for j=hcorig:hcorig+c-1
                        aux2=aux(i-rpad:i+rpad,j-cpad:j+rpad);
                        suma=0;          
                        for w=1:r_k
                            for y=1:c_k
                                suma=max(suma,(aux2(w,y)*nh(w,y)));            
                            end
                        end
                        result(i-rpad,j-cpad)=suma;           
                    end
                end
            image_copy=mat2gray(result);    
            else
                m_R=I(:,:,1);
                m_G=I(:,:,2);
                m_B=I(:,:,3);
                I_g=I;
                t=size(I_g);
                r=t(1);
                c=t(2);
                nh=app.se.Neighborhood;
                tam=size(nh);
                r_k=tam(1);
                c_k=tam(2);
                %result=ones(r,c);
                cpad=floor(c_k/2);
                rpad=floor(r_k/2);
                hcorig=cpad+1;
                hrorig=rpad+1;
                aux_r=zeros(r+2*rpad,c+2*cpad);
                aux_r(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_R;
                aux_g=zeros(r+2*rpad,c+2*cpad);
                aux_g(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_G;
                aux_b=zeros(r+2*rpad,c+2*cpad);
                aux_b(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_B;
                result_r=zeros(r,c);
                result_g=zeros(r,c);
                result_b=zeros(r,c);         
                for i=hrorig:hrorig+r-1
                    for j=hcorig:hcorig+c-1
                        aux2_r=aux_r(i-rpad:i+rpad,j-cpad:j+rpad);
                        aux2_g=aux_g(i-rpad:i+rpad,j-cpad:j+rpad);
                        aux2_b=aux_b(i-rpad:i+rpad,j-cpad:j+rpad); 
                        %suma=0; 
                        suma_r=0;
                        suma_g=0;
                        suma_b=0;
                        for w=1:r_k
                            for y=1:c_k
                                suma_r=max(suma_r,(aux2_r(w,y)*nh(w,y)));
                                suma_g=max(suma_g,(aux2_g(w,y)*nh(w,y)));
                                suma_b=max(suma_b,(aux2_b(w,y)*nh(w,y)));
                            end
                        end
                        %result(i-rpad,j-cpad)=suma;
                        result_r(i-rpad,j-cpad)=suma_r;
                        result_g(i-rpad,j-cpad)=suma_g;
                        result_b(i-rpad,j-cpad)=suma_b;
                    end
                end
            %dilatedI=mat2gray(result); 
            image_copy(:,:,1)=result_r;
            image_copy(:,:,2)=result_g;
            image_copy(:,:,3)=result_b; 
            end 
            dilatedI=image_copy;
        end
        
        function erodedI = erosion(app, I)
            image_copy=I;
            if app.isgray
                I_g=I;
                t=size(I_g);
                r=t(1);
                c=t(2);
                nh=app.se.Neighborhood;
                tam=size(nh);
                r_k=tam(1);
                c_k=tam(2);
                result=ones(r,c);
                cpad=floor(c_k/2);
                rpad=floor(r_k/2);
                hcorig=cpad+1;
                hrorig=rpad+1;
                aux=zeros(r+2*rpad,c+2*cpad);
                aux(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=I_g;           
                for i=hrorig:hrorig+r-1
                    for j=hcorig:hcorig+c-1
                        aux2=aux(i-rpad:i+rpad,j-cpad:j+rpad);
                        suma1=inf;          
                        for w=1:r_k
                            for y=1:c_k
                                val=(aux2(w,y)*nh(w,y));
                                if val~= 0
                                    suma1=min(val,suma1);
                                end
                
                            end
                        end
                        if suma1==inf
                            suma1=0;
                        end
                        result(i-rpad,j-cpad)=suma1;           
                    end
                end          
                image_copy=mat2gray(result);
            else
                m_R=I(:,:,1);
                m_G=I(:,:,2);
                m_B=I(:,:,3);
                I_g=I;
                t=size(I_g);
                r=t(1);
                c=t(2);
                nh=app.se.Neighborhood;
                tam=size(nh);
                r_k=tam(1);
                c_k=tam(2);
                %result=ones(r,c);
                cpad=floor(c_k/2);
                rpad=floor(r_k/2);
                hcorig=cpad+1;
                hrorig=rpad+1;
                aux_r=zeros(r+2*rpad,c+2*cpad);
                aux_r(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_R;
                aux_g=zeros(r+2*rpad,c+2*cpad);
                aux_g(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_G;
                aux_b=zeros(r+2*rpad,c+2*cpad);
                aux_b(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_B;
                result_r=zeros(r,c);
                result_g=zeros(r,c);
                result_b=zeros(r,c);         
                for i=hrorig:hrorig+r-1
                    for j=hcorig:hcorig+c-1
                        aux2_r=aux_r(i-rpad:i+rpad,j-cpad:j+rpad);
                        aux2_g=aux_g(i-rpad:i+rpad,j-cpad:j+rpad);
                        aux2_b=aux_b(i-rpad:i+rpad,j-cpad:j+rpad); 
                        suma_r=inf;
                        suma_g=inf;
                        suma_b=inf;
                        for w=1:r_k
                            for y=1:c_k
                                val_r=(aux2_r(w,y)*nh(w,y));
                                val_g=(aux2_g(w,y)*nh(w,y));
                                val_b=(aux2_b(w,y)*nh(w,y));
                                if val_r~= 0
                                    suma_r=min(val_r,suma_r);
                                end
                                if val_g~= 0
                                    suma_g=min(val_g,suma_g);
                                end
                                if val_b~= 0
                                    suma_b=min(val_b,suma_b);
                                end
                            end
                        end                       
                        if suma_r==inf
                            suma_r=0;
                        end
                        
                        if suma_g==inf
                            suma_g=0;
                        end
                        
                        if suma_b==inf
                            suma_b=0;
                        end
                        %result(i-rpad,j-cpad)=suma;
                        result_r(i-rpad,j-cpad)=suma_r;
                        result_g(i-rpad,j-cpad)=suma_g;
                        result_b(i-rpad,j-cpad)=suma_b;
                    end
                end
            %dilatedI=mat2gray(result); 
            image_copy(:,:,1)=result_r;
            image_copy(:,:,2)=result_g;
            image_copy(:,:,3)=result_b;     
            end
            erodedI=image_copy;
        end
        
        function openingI = opening(app, I)
            erodedI = erosion(app,I);
            dilatedI = dilation(app, erodedI);
            openingI = dilatedI;
            
        end
        
        function closingI = closing(app, I)
            dilatedI = dilation(app,I);
            erodedI = erosion(app, dilatedI);
            closingI = erodedI;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.original_image = getimage(app.h);
            if length(size(app.original_image)) == 3
                app.input_image = app.original_image;
                app.isgray=false;
            else
                app.input_image = app.original_image;
                app.isgray=true;
            end
            imshow(app.input_image,[],'Parent',app.InputImage);
            app.IntroduceanaturalnumberLabel.Visible="off";
            app.OutputImage.Visible = 'Off';
            app.DiamondshapedStructuringElementPanel.Visible="off";
            app.DiskshapedStructuringElementPanel.Visible="on";
            app.OctagonshapedStructuringElementPanel.Visible="off";
            app.SquareshapedStructuringElementPanel.Visible="off";
            app.mustbeanonnegativenumberLabel.Visible="off";
            app.multipleof3Label.Visible="off"; 
            app.PleaseusealowervalueLabel.Visible="off";
            app.UpdateImageButton.Visible="off";

        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)

            value=app.StructuringElementSizeSpinner.Value;
            val=app.StructuringElementshapeDropDown.Value;
            if strcmp(val,'Octagon')==1 && (mod(value,1)~=0 || value<1 || mod(value,3)~=0)
                app.mustbeanonnegativenumberLabel.Visible="on";
                app.multipleof3Label.Visible="on";
                app.IntroduceanaturalnumberLabel.Visible="off";
                app.PleaseusealowervalueLabel.Visible="off";
            elseif value>50
                app.mustbeanonnegativenumberLabel.Visible="off";
                app.multipleof3Label.Visible="off";
                app.IntroduceanaturalnumberLabel.Visible="off";
                app.PleaseusealowervalueLabel.Visible="on";
                
            elseif mod(value,1)~=0 || value<1
                app.IntroduceanaturalnumberLabel.Visible="on";
                app.mustbeanonnegativenumberLabel.Visible="off";
                app.multipleof3Label.Visible="off";
                app.PleaseusealowervalueLabel.Visible="off";
            else 
            app.se = strel(val,app.StructuringElementSizeSpinner.Value);
            
            if app.DilationButton.Value
                app.outputI = dilation(app, app.input_image);
            end
                
            if app.ErosionButton.Value
                app.outputI = erosion(app, app.input_image);
            end
                    
            if app.OpeningButton.Value
               app.outputI = opening(app,app.input_image);
            end
                    
            if app.ClosingButton.Value
               app.outputI = closing(app,app.input_image);
            end

            imshow(app.outputI,[],'Parent',app.OutputImage)
            app.IntroduceanaturalnumberLabel.Visible="off";
            app.mustbeanonnegativenumberLabel.Visible="off";
            app.multipleof3Label.Visible="off";
            app.PleaseusealowervalueLabel.Visible="off";
            app.UpdateImageButton.Visible="on";
            end
        end

        % Value changed function: StructuringElementshapeDropDown
        function StructuringElementshapeDropDownValueChanged(app, event)
            value = app.StructuringElementshapeDropDown.Value;
            if strcmp(value,'Disk')==1
                app.DiamondshapedStructuringElementPanel.Visible="off";
                app.DiskshapedStructuringElementPanel.Visible="on";  
                app.OctagonshapedStructuringElementPanel.Visible="off";
                app.SquareshapedStructuringElementPanel.Visible="off";
            end
            
            if strcmp(value,'Diamond')==1
                app.DiamondshapedStructuringElementPanel.Visible="on";
                app.DiskshapedStructuringElementPanel.Visible="off"; 
                app.OctagonshapedStructuringElementPanel.Visible="off";
                app.SquareshapedStructuringElementPanel.Visible="off";
            end
            if strcmp(value,'Octagon')==1
                app.DiamondshapedStructuringElementPanel.Visible="off";
                app.DiskshapedStructuringElementPanel.Visible="off"; 
                app.OctagonshapedStructuringElementPanel.Visible="on";
                app.SquareshapedStructuringElementPanel.Visible="off";
            end
            if strcmp(value,'Square')==1
                app.DiamondshapedStructuringElementPanel.Visible="off";
                app.DiskshapedStructuringElementPanel.Visible="off"; 
                app.OctagonshapedStructuringElementPanel.Visible="off";
                app.SquareshapedStructuringElementPanel.Visible="on";
            end

        end

        % Button pushed function: UpdateImageButton
        function UpdateImageButtonPushed(app, event)
            windowName = app.h.Name;
            if app.DilationButton.Value
            figure("Name",strcat(windowName,"_Dilated"));
            elseif app.ErosionButton.Value
            figure("Name",strcat(windowName,"_Eroded"));
            elseif app.OpeningButton.Value
            figure("Name",strcat(windowName,"_Opened"));
            else
            figure("Name",strcat(windowName,"_Closed"));
            end
            imshow(app.outputI,[]);
            app.parentApp.updateImageList();
            app.UpdateImageButton.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OutputImage = uiaxes(app.UIFigure);
            title(app.OutputImage, 'Output Image')
            xlabel(app.OutputImage, '')
            ylabel(app.OutputImage, '')
            app.OutputImage.XTick = [];
            app.OutputImage.YTick = [];
            app.OutputImage.ZTick = [];
            app.InputImage = uiaxes(app.UIFigure);
            title(app.InputImage, 'Input Image')
            xlabel(app.InputImage, '')
            ylabel(app.InputImage, '')
            app.InputImage.XTick = [];
            app.InputImage.YTick = [];
            app.InputImage.ZTick = [];
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.GrayscaleMorphologyButtonGroup = uibuttongroup(app.UIFigure);
            app.GrayscaleMorphologyButtonGroup.Title = 'Grayscale Morphology';
            app.DilationButton = uiradiobutton(app.GrayscaleMorphologyButtonGroup);
            app.DilationButton.Text = 'Dilation';
            app.DilationButton.Value = true;
            app.ErosionButton = uiradiobutton(app.GrayscaleMorphologyButtonGroup);
            app.ErosionButton.Text = 'Erosion';
            app.OpeningButton = uiradiobutton(app.GrayscaleMorphologyButtonGroup);
            app.OpeningButton.Text = 'Opening';
            app.ClosingButton = uiradiobutton(app.GrayscaleMorphologyButtonGroup);
            app.ClosingButton.Text = 'Closing';
            app.StructuringElementSizeSpinnerLabel = uilabel(app.UIFigure);
            app.StructuringElementSizeSpinnerLabel.Text = {'Structuring Element Size '; ''};
            app.StructuringElementSizeSpinner = uispinner(app.UIFigure);
            app.StructuringElementSizeSpinner.Value = 5;
            app.IntroduceanaturalnumberLabel = uilabel(app.UIFigure);
            app.IntroduceanaturalnumberLabel.Text = 'Introduce a natural number';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*';
            app.StructuringElementshapeDropDownLabel = uilabel(app.UIFigure);
            app.StructuringElementshapeDropDownLabel.Text = 'Structuring Element shape';
            app.StructuringElementshapeDropDown = uidropdown(app.UIFigure);
            app.StructuringElementshapeDropDown.Items = {'Disk', 'Diamond', 'Octagon', 'Square'};
            app.StructuringElementshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @StructuringElementshapeDropDownValueChanged, true);
            app.StructuringElementshapeDropDown.Value = 'Disk';
            app.DiskshapedStructuringElementPanel = uipanel(app.UIFigure);
            app.DiskshapedStructuringElementPanel.Title = 'Disk-shaped Structuring Element';
            app.Label_2 = uilabel(app.DiskshapedStructuringElementPanel);
            app.Label_2.Text = '*';
            app.ItrepresentstheradiusofthediskshapedLabel = uilabel(app.DiskshapedStructuringElementPanel);
            app.ItrepresentstheradiusofthediskshapedLabel.Text = 'It represents the radius of the disk-shaped';
            app.structuringelementusedforGrayscaleLabel = uilabel(app.DiskshapedStructuringElementPanel);
            app.structuringelementusedforGrayscaleLabel.Text = 'structuring element used for Grayscale';
            app.morphologyoperationsLabel = uilabel(app.DiskshapedStructuringElementPanel);
            app.morphologyoperationsLabel.Text = 'morphology operations.';
            app.Exampleforaradius4thematrixthatLabel = uilabel(app.DiskshapedStructuringElementPanel);
            app.Exampleforaradius4thematrixthatLabel.Text = 'Example: for a radius=4, the matrix that';
            app.representstheStructuringElementwillbeLabel = uilabel(app.DiskshapedStructuringElementPanel);
            app.representstheStructuringElementwillbeLabel.Text = 'represents the Structuring Element will be:';
            app.Label_71 = uilabel(app.DiskshapedStructuringElementPanel);
            app.Label_71.Text = {''; '   0   0   1   1   1   0   0'; '   0   1   1   1   1   1   0'; '   1   1   1   1   1   1   1'; '   1   1   1   1   1   1   1'; '   1   1   1   1   1   1   1'; '   0   1   1   1   1   1   0'; '   0   0   1   1   1   0   0'; ''};
            app.DiamondshapedStructuringElementPanel = uipanel(app.UIFigure);
            app.DiamondshapedStructuringElementPanel.Title = 'Diamond-shaped Structuring Element';
            app.Label_69 = uilabel(app.DiamondshapedStructuringElementPanel);
            app.Label_69.Text = '*';
            app.ItrepresentsthedistancefromtheoriginofLabel = uilabel(app.DiamondshapedStructuringElementPanel);
            app.ItrepresentsthedistancefromtheoriginofLabel.Text = 'It represents the distance from the origin of';
            app.thestructuringelementuptothepointsoftheLabel = uilabel(app.DiamondshapedStructuringElementPanel);
            app.thestructuringelementuptothepointsoftheLabel.Text = 'the structuring element up to the points of the';
            app.diamondLabel = uilabel(app.DiamondshapedStructuringElementPanel);
            app.diamondLabel.Text = 'diamond.';
            app.Exampleforadistance4theLabel = uilabel(app.DiamondshapedStructuringElementPanel);
            app.Exampleforadistance4theLabel.Text = 'Example: for a distance=4, the  ';
            app.matrixthatrepresentstheStructuringElementLabel = uilabel(app.DiamondshapedStructuringElementPanel);
            app.matrixthatrepresentstheStructuringElementLabel.Text = 'matrix that represents the Structuring Element:';
            app.Label_70 = uilabel(app.DiamondshapedStructuringElementPanel);
            app.Label_70.Text = {'   0   0   0   0   1   0   0   0   0'; '   0   0   0   1   1   1   0   0   0'; '   0   0   1   1   1   1   1   0   0'; '   0   1   1   1   1   1   1   1   0'; '   1   1   1   1   1   1   1   1   1'; '   0   1   1   1   1   1   1   1   0'; '   0   0   1   1   1   1   1   0   0'; '   0   0   0   1   1   1   0   0   0'; '   0   0   0   0   1   0   0   0   0'};
            app.OctagonshapedStructuringElementPanel = uipanel(app.UIFigure);
            app.OctagonshapedStructuringElementPanel.Title = 'Octagon-shaped Structuring Element';
            app.Label_72 = uilabel(app.OctagonshapedStructuringElementPanel);
            app.Label_72.Text = '*';
            app.ItrepresentsthedistancefromtheoriginofLabel_2 = uilabel(app.OctagonshapedStructuringElementPanel);
            app.ItrepresentsthedistancefromtheoriginofLabel_2.Text = 'It represents the distance from the origin of';
            app.thestructuringelementuptothepointsoftheLabel_2 = uilabel(app.OctagonshapedStructuringElementPanel);
            app.thestructuringelementuptothepointsoftheLabel_2.Text = 'the structuring element up to the points of the';
            app.sidesoftheoctagonmeasuredalongtheLabel = uilabel(app.OctagonshapedStructuringElementPanel);
            app.sidesoftheoctagonmeasuredalongtheLabel.Text = 'sides of the octagon,measured along the';
            app.horizontalandverticalaxesItmustbeanonLabel = uilabel(app.OctagonshapedStructuringElementPanel);
            app.horizontalandverticalaxesItmustbeanonLabel.Text = 'horizontal and vertical axes. It must be a non ';
            app.negativemultipleof3ExampleforaLabel = uilabel(app.OctagonshapedStructuringElementPanel);
            app.negativemultipleof3ExampleforaLabel.Text = 'negative multiple of 3. Example: for a  ';
            app.distance3thematrixwillbeLabel = uilabel(app.OctagonshapedStructuringElementPanel);
            app.distance3thematrixwillbeLabel.Text = 'distance=3 the matrix will be: ';
            app.Label_73 = uilabel(app.OctagonshapedStructuringElementPanel);
            app.Label_73.Text = {''; '   0   0   1   1   1   0   0'; '   0   1   1   1   1   1   0'; '   1   1   1   1   1   1   1'; '   1   1   1   1   1   1   1'; '   1   1   1   1   1   1   1'; '   0   1   1   1   1   1   0'; '   0   0   1   1   1   0   0'};
            app.SquareshapedStructuringElementPanel = uipanel(app.UIFigure);
            app.SquareshapedStructuringElementPanel.Title = 'Square-shaped Structuring Element';
            app.Label_74 = uilabel(app.SquareshapedStructuringElementPanel);
            app.Label_74.Text = '*';
            app.ItrepresentsthewidthofthesquareLabel = uilabel(app.SquareshapedStructuringElementPanel);
            app.ItrepresentsthewidthofthesquareLabel.Text = 'It represents the width of the square ';
            app.Label_75 = uilabel(app.SquareshapedStructuringElementPanel);
            app.Label_75.Text = {'   1   1   1   1'; '   1   1   1   1'; '   1   1   1   1'; '   1   1   1   1'};
            app.StructuringElementExampleforawidth4Label = uilabel(app.SquareshapedStructuringElementPanel);
            app.StructuringElementExampleforawidth4Label.Text = 'Structuring Element . Example: for a width=4:';
            app.mustbeanonnegativenumberLabel = uilabel(app.UIFigure);
            app.mustbeanonnegativenumberLabel.Text = 'must be a non negative number';
            app.multipleof3Label = uilabel(app.UIFigure);
            app.multipleof3Label.Text = 'multiple of 3.';
            app.PleaseusealowervalueLabel = uilabel(app.UIFigure);
            app.PleaseusealowervalueLabel.Text = 'Please use a lower value';
            app.UpdateImageButton = uibutton(app.UIFigure, 'push');
            app.UpdateImageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateImageButtonPushed, true);
            app.UpdateImageButton.Text = 'Update Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL19_DilationErisionOpeningClosingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end