classdef CeuL19_GrayReconstructionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GrayReconstructionLabel         matlab.ui.control.Label
        ImageLabel                      matlab.ui.control.Label
        StructuringElementLabel         matlab.ui.control.Label
        StructuringElementSlider        matlab.ui.control.Slider
        NoiseSliderLabel                matlab.ui.control.Label
        NoiseSlider                     matlab.ui.control.Slider
        IndependentFigureButton         matlab.ui.control.Button
        ThealgorithmcantakeawhileLabel  matlab.ui.control.Label
        SaltandPepperLabel              matlab.ui.control.Label
        ReconstructedImage              matlab.ui.control.UIAxes
        NoisyImage                      matlab.ui.control.UIAxes
        OriginalImage                   matlab.ui.control.UIAxes
    end

    % Author: María Montes    
    
    properties (Access = private)
       parentApp % parent app
        h         % figure handler
        I         % image used 
        Inoisy      % noisy image 
    end
    
    methods (Access = public)
        
    end

    methods (Access = private)
        function J=grayReconstruction (app, valueH, Inoisy)
            
            %returns an image
            SE = strel ('disk', valueH, valueH);
            
            %1 open the image
            J=imopen (Inoisy, SE);
            
            %2 equalize
            T=J;
            
            %loop
            proceed=1;
            while proceed         
                 % 3 image dilation
                 J = imdilate(J, SE);
                 
                 %4 find the minimum 
                 J = min(Inoisy, J);

                 %5 check if they're the same = reconstructed image
                 %if not go back to step 2
                 proceed = sum(J(:) ~= T(:)) > 0;
                 T = J;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.I = getimage(h);
                        
            %show the original image to let us compare
            image(app.OriginalImage, app.I);
            axis(app.OriginalImage, 'off');
            
        end

        % Value changed function: StructuringElementSlider
        function StructuringElementSliderValueChanged(app, event)
            %slider used for the SE
            val = app.StructuringElementSlider.Value;
            refValues = [0 4 6 8]; %needed because the program told me
            [m, idx] = min(abs(refValues-val));
            
            %reconstruct the image for each channel
            J=zeros(size(app.Inoisy));
            J(:,:,1)=grayReconstruction (app, refValues(idx), app.Inoisy(:,:,1));
            J(:,:,2)=grayReconstruction (app, refValues(idx), app.Inoisy(:,:,2));
            J(:,:,3)=grayReconstruction (app, refValues(idx), app.Inoisy(:,:,3));
            
            image(app.ReconstructedImage, uint8(J));
            axis(app.ReconstructedImage, 'off');

        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            value = app.NoiseSlider.Value;   
            %slider made for the user to determine the noise of the
            %original image
            
            prob= value/100;
            app.Inoisy = imnoise(app.I,'salt & pepper', prob);

            %as we are giving the variance it needs to be gaussian
            %app.Inoisy = imnoise(app.I,'gaussian', variance);
            
            %show the gaussian noisy image in the noisy Image spot
            image(app.NoisyImage, app.Inoisy);
            axis(app.NoisyImage, 'off');

            
        end

        % Button pushed function: IndependentFigureButton
        function IndependentFigureButtonPushed(app, event)
            %Button to let the user download the reconstructed image 
            JF = getimage(app.ReconstructedImage);
            figure('Name','Grayscale Reconstructed Image');
            imshow (JF);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GrayReconstructionLabel = uilabel(app.UIFigure);
            app.GrayReconstructionLabel.Text = {'Gray Reconstruction'; ''};
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.StructuringElementLabel = uilabel(app.UIFigure);
            app.StructuringElementLabel.Text = {'Structuring'; 'Element'};
            app.StructuringElementSlider = uislider(app.UIFigure);
            app.StructuringElementSlider.Limits = [0 8.1];
            app.StructuringElementSlider.MajorTicks = [0 4 6 8];
            app.StructuringElementSlider.ValueChangedFcn = createCallbackFcn(app, @StructuringElementSliderValueChanged, true);
            app.StructuringElementSlider.MinorTicks = [];
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.Limits = [0 20];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.IndependentFigureButton = uibutton(app.UIFigure, 'push');
            app.IndependentFigureButton.ButtonPushedFcn = createCallbackFcn(app, @IndependentFigureButtonPushed, true);
            app.IndependentFigureButton.Text = 'Independent Figure';
            app.ThealgorithmcantakeawhileLabel = uilabel(app.UIFigure);
            app.ThealgorithmcantakeawhileLabel.Text = {'The algorithm'; 'can take a while'; ''};
            app.SaltandPepperLabel = uilabel(app.UIFigure);
            app.SaltandPepperLabel.Text = {'Salt and Pepper'; ''};
            app.ReconstructedImage = uiaxes(app.UIFigure);
            title(app.ReconstructedImage, 'Grayscale Reconstructed Image')
            app.ReconstructedImage.XTick = [];
            app.ReconstructedImage.YTick = [];
            app.NoisyImage = uiaxes(app.UIFigure);
            title(app.NoisyImage, 'Noisy Image')
            app.NoisyImage.XTick = [];
            app.NoisyImage.YTick = [];
            app.OriginalImage = uiaxes(app.UIFigure);
            title(app.OriginalImage, 'Original Image')
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL19_GrayReconstructionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end