classdef CeuL20_JPEGatDifferentQualitiesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        JPEGatdifferentqualitiesLabel  matlab.ui.control.Label
        ImageLabel                     matlab.ui.control.Label
        QualitySliderLabel             matlab.ui.control.Label
        QualitySlider                  matlab.ui.control.Slider
        ImageModificado                matlab.ui.control.Image
        ImageOriginal                  matlab.ui.control.Image
        LabelOriginal                  matlab.ui.control.Label
        LabelModificado                matlab.ui.control.Label
    end

    % Author: Susana del Riego 
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.ImageOriginal.ImageSource=app.h.Name;
            app.ImageModificado.ImageSource=app.h.Name;
            app.LabelOriginal.Text='Original Image';
            app.LabelModificado.Text='Quality of JPEG 75% ';

        end

        % Value changed function: QualitySlider
        function SliderValueModified(app, event)
            value = app.QualitySlider.Value;
            I = getimage(app.h);
            windowName = app.h.Name;
            
            quality1 = round(app.QualitySlider.Value);
            imwrite(I,'jpegL20.jpeg','jpeg','Quality',quality1);
            Ijpeg=imread('jpegL20.jpeg');
            app.LabelModificado.Text=['Quality of JPEG ' num2str(quality1) '%'];
            app.ImageModificado.ImageSource=Ijpeg;
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JPEGatdifferentqualitiesLabel = uilabel(app.UIFigure);
            app.JPEGatdifferentqualitiesLabel.Text = 'JPEG at different qualities';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.QualitySliderLabel = uilabel(app.UIFigure);
            app.QualitySliderLabel.Text = 'Quality ';
            app.QualitySlider = uislider(app.UIFigure);
            app.QualitySlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueModified, true);
            app.ImageModificado = uiimage(app.UIFigure);
            app.ImageOriginal = uiimage(app.UIFigure);
            app.LabelOriginal = uilabel(app.UIFigure);
            app.LabelOriginal.Text = '';
            app.LabelModificado = uilabel(app.UIFigure);
            app.LabelModificado.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL20_JPEGatDifferentQualitiesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end