classdef CeuL2_EdgesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EDGESOFANIMAGELabel      matlab.ui.control.Label
        ImageLabel               matlab.ui.control.Label
        VieworiginalimageButton  matlab.ui.control.Button
        ViewsobelgradientButton  matlab.ui.control.Button
        ViewedgedetectedButton   matlab.ui.control.Button
    end

    % Author: Alejandra Aceves Arranz   
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: VieworiginalimageButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            
           
            figure('Name',windowName);
            imshow(I);
               
            app.parentApp.updateImageList();
            
        end

        % Button pushed function: ViewsobelgradientButton
        function ViewsobelgradientButtonPushed(app, event)
            
              I = getimage(app.h);
             
              
              %convert to grayscale
              B=rgb2gray(I);

              C=double(B);


              % compute the mask and the gradient for each point of the
              % input image 
              
    for i=1:size(C,1)-2
        for j=1:size(C,2)-2
            
        %Sobel mask for x-direction:
        Gx=((2*C(i+2,j+1)+C(i+2,j)+C(i+2,j+2))-(2*C(i,j+1)+C(i,j)+C(i,j+2)));
        
        %Sobel mask for y-direction:
        Gy=((2*C(i+1,j+2)+C(i,j+2)+C(i+2,j+2))-(2*C(i+1,j)+C(i,j)+C(i+2,j)));
      
        %The gradient of the image
        %B(i,j)=abs(Gx)+abs(Gy);
        B(i,j)=sqrt(Gx.^2+Gy.^2);


        end
    end
  figure,imshow(B); title('Sobel gradient');
  app.parentApp.updateImageList();
  
        end

        % Button pushed function: ViewedgedetectedButton
        function ViewedgedetectedButtonPushed(app, event)
            
             %edge detected image can be obtained from the sobel gradient b
             I = getimage(app.h);
             B=rgb2gray(I);
             C=double(B);


            % for every point of thhe inut image
    for i=1:size(C,1)-2
        for j=1:size(C,2)-2
        %Sobel mask for x-direction:
        Gx=((2*C(i+2,j+1)+C(i+2,j)+C(i+2,j+2))-(2*C(i,j+1)+C(i,j)+C(i,j+2)));
        
        %Sobel mask for y-direction:
        Gy=((2*C(i+1,j+2)+C(i,j+2)+C(i+2,j+2))-(2*C(i+1,j)+C(i,j)+C(i+2,j)));
      
        %The gradient of the image
        %B(i,j)=abs(Gx)+abs(Gy);
        B(i,j)=sqrt(Gx.^2+Gy.^2);


        end
    end
            %Define a threshold value;
            % if f < threshold value then f = threshold value.
            Thresh=100;
            B=max(B,Thresh);
            B(B==round(Thresh))=0;

            B=uint8(B);
            figure,imshow(~B);
            title('Edge detected Image');
            app.parentApp.updateImageList();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EDGESOFANIMAGELabel = uilabel(app.UIFigure);
            app.EDGESOFANIMAGELabel.Text = 'EDGES OF AN IMAGE';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.VieworiginalimageButton = uibutton(app.UIFigure, 'push');
            app.VieworiginalimageButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.VieworiginalimageButton.Text = 'View original image';
            app.ViewsobelgradientButton = uibutton(app.UIFigure, 'push');
            app.ViewsobelgradientButton.ButtonPushedFcn = createCallbackFcn(app, @ViewsobelgradientButtonPushed, true);
            app.ViewsobelgradientButton.Text = 'View sobel gradient';
            app.ViewedgedetectedButton = uibutton(app.UIFigure, 'push');
            app.ViewedgedetectedButton.ButtonPushedFcn = createCallbackFcn(app, @ViewedgedetectedButtonPushed, true);
            app.ViewedgedetectedButton.Text = 'View edge detected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL2_EdgesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end