classdef CeuL2_InspectPixelValuesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InspectPixelValuesLabel  matlab.ui.control.Label
        ImageLabel               matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        SliderColumns            matlab.ui.control.Slider
        Slider_2Label            matlab.ui.control.Label
        SliderRows               matlab.ui.control.Slider
        LabelRowSelected         matlab.ui.control.Label
        LabelColumnSelected      matlab.ui.control.Label
        LabelPixelValue          matlab.ui.control.Label
        LabelGrayValue           matlab.ui.control.Label
        ImagePixel               matlab.ui.control.Image
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I         % Image
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function updatePixelValue(app)
            r = round(app.SliderRows.Value);
            c = round(app.SliderColumns.Value);
            
            
            image = imread(app.ImageLabel.Text);
            app.LabelRowSelected.Text="Row: "+string(r)
            app.LabelColumnSelected.Text="Column: "+string(c)
            RGB = impixel(image,c,r);
            
            grayLevel = image(r,c);
            app.LabelPixelValue.Text=string("R: " +RGB(1)+ "; G: " +RGB(2)+ "; B: " +RGB(3));
            app.LabelGrayValue.Text=string("Gray Value: " +grayLevel);
            
            red=[RGB(1)];
            green=[RGB(2)];
            blue=[RGB(3)];
            pixel = cat(3,red,green,blue);
            pixel;
            app.ImagePixel.ImageSource=pixel;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;            
            app.ImageLabel.Text=strcat(h.Name);
            I = getimage(app.h);
            app.Image.ImageSource=h.Name;
            [r,c] = size(I);
            app.SliderColumns.Limits = [1,c];
            app.SliderRows.Limits = [1,r];
        end

        % Value changed function: SliderRows
        function SliderRowsValueChanged(app, event)
            app.updatePixelValue();
            
        end

        % Value changed function: SliderColumns
        function SliderColumnsValueChanged(app, event)
            app.updatePixelValue();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InspectPixelValuesLabel = uilabel(app.UIFigure);
            app.InspectPixelValuesLabel.Text = 'Inspect Pixel Values';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.Image = uiimage(app.UIFigure);
            app.SliderColumns = uislider(app.UIFigure);
            app.SliderColumns.ValueChangedFcn = createCallbackFcn(app, @SliderColumnsValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '';
            app.SliderRows = uislider(app.UIFigure);
            app.SliderRows.ValueChangedFcn = createCallbackFcn(app, @SliderRowsValueChanged, true);
            app.LabelRowSelected = uilabel(app.UIFigure);
            app.LabelRowSelected.Text = '';
            app.LabelColumnSelected = uilabel(app.UIFigure);
            app.LabelColumnSelected.Text = '';
            app.LabelPixelValue = uilabel(app.UIFigure);
            app.LabelPixelValue.Text = '';
            app.LabelGrayValue = uilabel(app.UIFigure);
            app.LabelGrayValue.Text = '';
            app.ImagePixel = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL2_InspectPixelValuesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end