classdef CeuL2_JPEG100vs0qualityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        JPEG100vs0qualityLabel  matlab.ui.control.Label
        ExecuteButton           matlab.ui.control.Button
        ImageLabel              matlab.ui.control.Label
        q1SliderLabel           matlab.ui.control.Label
        q1Slider                matlab.ui.control.Slider
        QualitiesDropDownLabel  matlab.ui.control.Label
        QualitiesDropDown       matlab.ui.control.DropDown
        q2SliderLabel           matlab.ui.control.Label
        q2Slider                matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            
            app.QualitiesDropDownValueChanged(0);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            
            idx = find(strcmp(app.QualitiesDropDown.Items, ...
                                  app.QualitiesDropDown.Value));
                              
            if idx==1
                    imwrite(I,'Image100quality.jpg','jpg','quality',100);
                    imwrite(I,'Image0quality.jpg','jpg','quality',0);
                    
                    I_100quality = imread("Image100quality.jpg");
                    I_0quality = imread("Image0quality.jpg");
                    
                    comparison = abs(I_100quality - I_0quality);
                    comparison = double(comparison);
                    I_comparison = comparison; %log(comparison+1);
                    I_comparison = uint8(255*I_comparison/max(I_comparison(:)));
                    
                    windowName_1 = "Comparison between 0 and 100";
                    
                    figure('Name',windowName_1);
                    subplot(1,3,1);
                    imshow(I_100quality);
                    title('Image with quality = 100');
                    subplot(1,3,2);
                    imshow(I_0quality);
                    title('Image with quality = 0');
                    subplot(1,3,3);
                    imshow(I_comparison);
                    title('Comparison');
                    
                elseif idx==2
                    q1 = app.q1Slider.Value;
                    q2 = app.q2Slider.Value;
                    
                    imwrite(I,'Imageq1quality.jpg','jpg','quality',q1);
                    imwrite(I,'Imageq2quality.jpg','jpg','quality',q2);
                    
                    I_q1quality = imread("Imageq1quality.jpg");
                    I_q2quality = imread("Imageq2quality.jpg");
                    
                    comparison2 = abs(I_q1quality - I_q2quality);
                    comparison2 = double(comparison2);
                    I_comparison2 = comparison2;
                    I_comparison2 = uint8(255*I_comparison2/max(I_comparison2(:)));
                    
                    windowName_2 = sprintf("Comparison between %d and %d", q1,q2);
                    q1_show = sprintf("Image with quality = %.2f", q1);
                    q2_show = sprintf("Image with quality = %.2f", q2);
                    
                    figure('Name',windowName_2);
                    subplot(1,3,1);
                    imshow(I_q1quality);
                    title(q1_show);
                    subplot(1,3,2);
                    imshow(I_q2quality);
                    title(q2_show);
                    subplot(1,3,3);
                    imshow(I_comparison2);
                    title('Comparison');
            end
            
            app.parentApp.updateImageList();
        end

        % Value changed function: QualitiesDropDown
        function QualitiesDropDownValueChanged(app, event)
            %value = app.QualitiesDropDown.Value;
            
            idx = find(strcmp(app.QualitiesDropDown.Items, ...
                                  app.QualitiesDropDown.Value));
            if idx==1
                app.q1Slider.Visible=false;
                app.q2Slider.Visible=false;
                
                app.q1SliderLabel.Visible = false;
                app.q2SliderLabel.Visible = false;
            else
                app.q1Slider.Visible=true;
                app.q2Slider.Visible=true;
                
                app.q1SliderLabel.Visible = true;
                app.q2SliderLabel.Visible = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JPEG100vs0qualityLabel = uilabel(app.UIFigure);
            app.JPEG100vs0qualityLabel.Text = 'JPEG 100 vs 0 quality';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.QualitiesDropDownLabel = uilabel(app.UIFigure);
            app.QualitiesDropDownLabel.Text = 'Qualities';
            app.QualitiesDropDown = uidropdown(app.UIFigure);
            app.QualitiesDropDown.Items = {'0 vs 100', 'Custom(q1 vs q2)'};
            app.QualitiesDropDown.ValueChangedFcn = createCallbackFcn(app, @QualitiesDropDownValueChanged, true);
            app.QualitiesDropDown.Value = '0 vs 100';
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL2_JPEG100vs0qualityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end