classdef CeuL3_HistogramComputationforGrayandColorimagesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HitogramComputationLabel  matlab.ui.control.Label
        ChooseactionButtonGroup   matlab.ui.container.ButtonGroup
        ColorimagesButton         matlab.ui.control.RadioButton
        GrayimagesButton          matlab.ui.control.RadioButton
        ExecuteButton             matlab.ui.control.Button
        ImageLabel                matlab.ui.control.Label
    end

    % Author: Ines de los Mozos    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                if app.ColorimagesButton.Value
                    figure('Name',windowName);
                    histogram(R,'BinMethod','integers','FaceColor','r','EdgeAlpha',0,'FaceAlpha',1)
                    hold on
                    histogram(G,'BinMethod','integers','FaceColor','g','EdgeAlpha',0,'FaceAlpha',0.7)
                    histogram(B,'BinMethod','integers','FaceColor','b','EdgeAlpha',0,'FaceAlpha',0.7)
                    xlabel('RGB value')
                    ylabel('Frequency')
                    title('Color histogram in RGB color space')
                    xlim([0 257])
                    
                elseif app.GrayimagesButton.Value
                   figure('Name',windowName);
                   imhist(I); 
                   title('Gray Value histogram')
                 
                
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HitogramComputationLabel = uilabel(app.UIFigure);
            app.HitogramComputationLabel.Text = 'Hitogram Computation';
            app.ChooseactionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseactionButtonGroup.Title = 'Choose action';
            app.ColorimagesButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.ColorimagesButton.Text = 'Color images';
            app.ColorimagesButton.Value = true;
            app.GrayimagesButton = uiradiobutton(app.ChooseactionButtonGroup);
            app.GrayimagesButton.Text = 'Gray  images';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL3_HistogramComputationforGrayandColorimagesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end