classdef CeuL3_ShowIntensityLayersApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ShowstackoflayerswithggLabel  matlab.ui.control.Label
        ImageLabel                    matlab.ui.control.Label
        ChannelsDropDownLabel         matlab.ui.control.Label
        ChannelsDropDown              matlab.ui.control.DropDown
        GraylevelSliderLabel          matlab.ui.control.Label
        GraylevelSlider               matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I         % Input image
        %outputHandler % Description
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function  showGray(app,C,grayLevel)
            grayLevel = round(grayLevel);
            J = 255*(C == grayLevel);
            %if app.outputHandler==-1               
            %   app.outputHandler=figure;
            %else
            %    figure(app.outputHandler);
            %end
           %imagesc(J);
           
            image(app.UIAxes,J);
            colormap('gray');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            
            app.I = getimage(app.h);
            image(app.UIAxes,app.I);
            if(length(size(app.I))==3)
                app.ChannelsDropDown.Items = {'Red','Green','Blue'};
            elseif (length(size(app.I))==2)
                app.ChannelsDropDown.Items = {'Monochrome'};
            end
            app.parentApp.updateImageList();
            
            %app.outputHandler=-1;
        end

        % Value changed function: GraylevelSlider
        function GraylevelSliderValueChanged(app, event)
            value = app.GraylevelSlider.Value;
            if length(size(app.I))==3
                idx = find(strcmp(app.ChannelsDropDown.Items, ...
                                      app.ChannelsDropDown.Value));
                app.showGray(app.I(:,:,idx),value);    
            else
                app.showGray(app.I,value);    
            end
            app.parentApp.updateImageList();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShowstackoflayerswithggLabel = uilabel(app.UIFigure);
            app.ShowstackoflayerswithggLabel.Text = 'Show stack of layers with g=g';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.ChannelsDropDownLabel = uilabel(app.UIFigure);
            app.ChannelsDropDownLabel.Text = 'Channels';
            app.ChannelsDropDown = uidropdown(app.UIFigure);
            app.ChannelsDropDown.Items = {'Monochrome', 'Red', 'Green', 'Blue'};
            app.ChannelsDropDown.Value = 'Monochrome';
            app.GraylevelSliderLabel = uilabel(app.UIFigure);
            app.GraylevelSliderLabel.Text = 'Gray level';
            app.GraylevelSlider = uislider(app.UIFigure);
            app.GraylevelSlider.Limits = [0 255];
            app.GraylevelSlider.ValueChangedFcn = createCallbackFcn(app, @GraylevelSliderValueChanged, true);
            app.GraylevelSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL3_ShowIntensityLayersApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end