classdef CeuL4_AnalyticalFunctionLUTApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AnalyticalFunctionLUTLabel  matlab.ui.control.Label
        ExecuteButton               matlab.ui.control.Button
        yLabel                      matlab.ui.control.Label
        yEditField                  matlab.ui.control.EditField
        ShowImage                   matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            %app.ImageLabel.Text=strcat("Image: ",h.Name);
            I = getimage(app.h);
             windowName = app.h.Name;
             image(app.ShowImage,I);
             axis(app.ShowImage,'off');
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
             I = getimage(app.h);
             windowName = app.h.Name;
             
             
             x=[0:255];
             LUT = zeros(256,1);
             for x=0:255
                 LUT(x+1)=eval(app.yEditField.Value);
             end
%              LUT = eval(app.yEditField.Value);
             LUT(LUT>255)=255;
             LUT(LUT<0)=0;
             
         
             plot(app.UIAxes,x,LUT);
         
             J = uint8(LUT(I+1));
             
             
             figure;
             imshow(J)
          
        end

        % Callback function
        function IntensityDropDownValueChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalyticalFunctionLUTLabel = uilabel(app.UIFigure);
            app.AnalyticalFunctionLUTLabel.Text = 'Analytical Function LUT';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y=';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.ShowImage = uiaxes(app.UIFigure);
            title(app.ShowImage, 'Input Image')
            xlabel(app.ShowImage, 'X')
            ylabel(app.ShowImage, 'Y')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_AnalyticalFunctionLUTApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end