classdef CeuL4_ComputeDifferencesBetweenImgsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DirectButton                  matlab.ui.control.Button
        AbsoluteButton                matlab.ui.control.Button
        LogarithmicButton             matlab.ui.control.Button
        CheckBox                      matlab.ui.control.CheckBox
        QuadraticButton               matlab.ui.control.Button
        SelectimageDropDown           matlab.ui.control.DropDown
        SelectimageDropDownLabel      matlab.ui.control.Label
        IJLabel                       matlab.ui.control.Label
        DirectmMmLabel                matlab.ui.control.Label
        logDirect1maxlogDirect1Label  matlab.ui.control.Label
        Direct2Label                  matlab.ui.control.Label
        PictureImage                  matlab.ui.control.Image
        PictureLabel                  matlab.ui.control.Label
        SelectdifferentimagestocomputedifferenceLabel  matlab.ui.control.Label
        ChecktomaxdynamicrangeLabel   matlab.ui.control.Label
        SelectimageDropDown_2         matlab.ui.control.DropDown
        SelectimageDropDownLabel_2    matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        ComputeadifferentiationfirstLabel  matlab.ui.control.Label
        ExportlatestimageshownLabel   matlab.ui.control.Label
    end

     % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        hshown % image shown in app
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = public)
        function updateImageList(app)
            figHandles = findobj('Type', 'figure');
            if length(figHandles)>0
                app.SelectimageDropDown.Items = {};
                for i=1:length(figHandles)
                    app.SelectimageDropDown.Items{i} = figHandles(i).Name;
                    app.SelectimageDropDown_2.Items{i} = figHandles(i).Name;
                end
            end
        end
    end
    
    methods (Access = public)
        function h2 = getFigureHandler(app)
            h2= app.getFigureHandlerByName(app.SelectimageDropDown.Value);
        end
    end
    
    methods (Access = public)
        
        function h2 = getFigureHandlerByName(app, selectedName)
            figHandles = findobj('Type', 'figure');
            for i=1:length(figHandles)
                if strcmp(figHandles(i).Name,selectedName)
                    h2=figHandles(i);
                    return
                end
            end
            h2 = -1;
            return            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.updateImageList();
            im = getimage(app.h); 
            app.PictureImage.ImageSource = im;
        end

        % Button pushed function: DirectButton
        function DirectButtonPushed(app, event)

             h1 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown_2.Value);
             I = getimage(h1);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = (I-J);
             if app.CheckBox.Value == 1
                 D = D*255;
             end
             app.PictureImage.ImageSource = D;
             app.PictureLabel.Text='DIRECT DIFFERENCE';
             app.hshown = D;
             app.ExportButton.Enable = 'on';
             app.ComputeadifferentiationfirstLabel.Visible = 'off';
             app.ExportlatestimageshownLabel.Visible = 'on';
        end

        % Callback function: AbsoluteButton, SelectimageDropDown
        function AbsoluteButtonPushed(app, event)
             h1 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown_2.Value);
             I = getimage(h1);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = (I-J); 
             m= min(I(:));
             M = max(I(:));
             Ab=uint8(255*(D-m)/(M-m));
             Ab2 = imabsdiff(I,J) %las operaciones de arriba muestran la imagen en negro...
             if app.CheckBox.Value == 1
                 Ab2 = Ab2*255;
             end
             app.PictureImage.ImageSource = Ab2;
             app.PictureLabel.Text='ABSOLUTE DIFFERENCE';
             app.hshown = Ab2;
        end

        % Button pushed function: LogarithmicButton
        function LogarithmicButtonPushed(app, event)
             h1 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown_2.Value);
             I = getimage(h1);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = double(abs(I-J));
             L=log(D+1);
             Lg = uint8(255*L/max(L(:)));
             app.PictureImage.ImageSource = Lg;
             app.PictureLabel.Text='LOGARITHMIC DIFFERENCE';
             app.hshown = Lg;
        end

        % Button pushed function: QuadraticButton
        function QuadraticButtonPushed(app, event)
             h1 = app.getFigureHandlerByName(app.SelectimageDropDown.Value);
             h2 = app.getFigureHandlerByName(app.SelectimageDropDown_2.Value);
             I = getimage(h1);
             J = getimage(h2);
             [r1, c1, d1]=size(I);
             [r2, c2, d2]=size(J);
             minrow=min([r1,r2]);
             mincol=min([c1,c2]);
             I=imcrop(I,[0 0 mincol minrow]);
             J=imcrop(J,[0 0 mincol minrow]);
             D = abs(I-J);
             Cr = uint8(D.^2);
             if app.CheckBox.Value == 1
                 Cr = Cr*255;
             end
             app.PictureImage.ImageSource = Cr;
             app.PictureLabel.Text='QUADRATIC DIFFERENCE';
             app.hshown = Cr;
        end

        % Callback function
        function LoadimageMenuSelected(app, event)
               [file,path] = uigetfile('*.*','Select an Image');
               if ~isequal(file,0)
               fn=fullfile(path,file);
               [I,map] = imread(fn);
               figure('Name',file);
               imshow(I,map);
               app.SelectimageDropDown.Enable = 'on';
               app.DirectButton.Enable = 'on';
               app.AbsoluteButton.Enable = 'on';
               app.QuadraticButton.Enable = 'on';
               app.LogarithmicButton.Enable = 'on';
               app.SelectdifferentimagestocomputedifferenceLabel.Visible = 'off';
               app.updateImageList();
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
              
               figure('Name','Differentiation Image');
               imshow(app.hshown);
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DirectButton = uibutton(app.UIFigure, 'push');
            app.DirectButton.ButtonPushedFcn = createCallbackFcn(app, @DirectButtonPushed, true);
            app.DirectButton.Text = 'Direct';
            app.AbsoluteButton = uibutton(app.UIFigure, 'push');
            app.AbsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteButtonPushed, true);
            app.AbsoluteButton.Text = 'Absolute';
            app.LogarithmicButton = uibutton(app.UIFigure, 'push');
            app.LogarithmicButton.ButtonPushedFcn = createCallbackFcn(app, @LogarithmicButtonPushed, true);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = {'0-255'; ''};
            app.QuadraticButton = uibutton(app.UIFigure, 'push');
            app.QuadraticButton.ButtonPushedFcn = createCallbackFcn(app, @QuadraticButtonPushed, true);
            app.QuadraticButton.Text = 'Quadratic';
            app.SelectimageDropDown = uidropdown(app.UIFigure);
            app.SelectimageDropDown.Items = {};
            app.SelectimageDropDown.ValueChangedFcn = createCallbackFcn(app, @AbsoluteButtonPushed, true);
            app.SelectimageDropDown.Value = {};
            app.SelectimageDropDownLabel = uilabel(app.UIFigure);
            app.SelectimageDropDownLabel.Text = 'Select parent image (I)';
            app.IJLabel = uilabel(app.UIFigure);
            app.IJLabel.Text = '(I-J)';
            app.DirectmMmLabel = uilabel(app.UIFigure);
            app.DirectmMmLabel.Text = '(Direct-m)/(M-m)';
            app.logDirect1maxlogDirect1Label = uilabel(app.UIFigure);
            app.logDirect1maxlogDirect1Label.Text = '(log(Direct+1))/max(log(Direct+1)(:))';
            app.Direct2Label = uilabel(app.UIFigure);
            app.Direct2Label.Text = '(Direct.^2)';
            app.PictureImage = uiimage(app.UIFigure);
            app.PictureLabel = uilabel(app.UIFigure);
            app.PictureLabel.Text = 'PARENT IMAGE';
            app.SelectdifferentimagestocomputedifferenceLabel = uilabel(app.UIFigure);
            app.SelectdifferentimagestocomputedifferenceLabel.Text = 'Select different images to compute difference!';
            app.ChecktomaxdynamicrangeLabel = uilabel(app.UIFigure);
            app.ChecktomaxdynamicrangeLabel.Text = {'Check to max'; 'dynamic range'};
            app.SelectimageDropDown_2 = uidropdown(app.UIFigure);
            app.SelectimageDropDown_2.Items = {};
            app.SelectimageDropDown_2.Value = {};
            app.SelectimageDropDownLabel_2 = uilabel(app.UIFigure);
            app.SelectimageDropDownLabel_2.Text = {'Select image to '; 'compute difference with (J)'};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ComputeadifferentiationfirstLabel = uilabel(app.UIFigure);
            app.ComputeadifferentiationfirstLabel.Text = 'Compute a differentiation first!';
            app.ExportlatestimageshownLabel = uilabel(app.UIFigure);
            app.ExportlatestimageshownLabel.Visible = 'off';
            app.ExportlatestimageshownLabel.Text = 'Export latest image shown';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_ComputeDifferencesBetweenImgsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end