classdef CeuL4_FoveaTransformApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FoveaTransformLabel             matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        ImageLabel                      matlab.ui.control.Label
        FoveaPositionColumnSliderLabel  matlab.ui.control.Label
        FoveaPositionColumnSlider       matlab.ui.control.Slider
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        ORIGINALIMAGELabel              matlab.ui.control.Label
        TRANSFORMEDIMAGELabel           matlab.ui.control.Label
        FoveaPositionRowSliderLabel     matlab.ui.control.Label
        FoveaPositionRowSlider          matlab.ui.control.Slider
        RatiooftheclearpartSliderLabel  matlab.ui.control.Label
        RatiooftheclearpartSlider       matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            matlab.ui.control.Slider
            I=getimage(app.h);
            app.Image.ImageSource=I;
            [rows,columns,chan]=size(I);
            disp(rows)
            disp(columns)
            disp(chan)
            app.FoveaPositionRowSlider.Limits = [0, rows];
            app.FoveaPositionColumnSlider.Limits = [0, columns];
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            
            M=1;
            beta=0;
            
            x0=app.FoveaPositionColumnSlider.Value;
            y0inv=app.FoveaPositionRowSlider.Value;
            
            alphaInv=app.RatiooftheclearpartSlider.Value;
            alpha=100-alphaInv;
            
            [rows,columns,chan]=size(I);
            
            y0=rows-y0inv;
            
            [Ir,Ig,Ib] = imsplit(I);
            
            rmax=sqrt((rows/2)^2+(columns/2)^2);
            
            rhomax=M*log(rmax+alpha)-beta;
            rhoStep=rhomax/100;
            
            thetamax=2*pi;
            thetaStep=thetamax/100;
            
            IlogPolar=zeros(101,101);
            
            IlogPolarR=zeros(101,101);
            IlogPolarG=zeros(101,101);
            IlogPolarB=zeros(101,101);
            
            irho=1;
            for rho=0:rhoStep:rhomax
                r=exp((rho+beta)/M)-alpha;

                itheta=1;
                for theta=0:thetaStep:thetamax
                    x=r*cos(theta)+x0;
                    y=r*sin(theta)+y0;
                    
                    IlogPolarR(irho,itheta)=interp2(Ir,x,y,'linear',0);
                    IlogPolarG(irho,itheta)=interp2(Ig,x,y,'linear',0);
                    IlogPolarB(irho,itheta)=interp2(Ib,x,y,'linear',0);
                    
                    itheta=itheta+1;
                end
                irho=irho+1;
            end
            IlogPolar(:,:,1)=IlogPolarR;
            IlogPolar(:,:,2)=IlogPolarG;
            IlogPolar(:,:,3)=IlogPolarB;
            
            
            IfoveaTrans=zeros(rows,columns,3);
            
            IfoveaTransR=zeros(rows,columns);
            IfoveaTransG=zeros(rows,columns);
            IfoveaTransB=zeros(rows,columns);

            for x=1:1:columns
                for y=1:1:rows
                    r=sqrt((x-x0)^2+(y-y0)^2);
                    
                    theta=atan2((y-y0),(x-x0));
                    if theta<0
                        theta=theta+2*pi;
                    end
                    rho=beta+M*log(r+alpha);

                    irho=(rho/rhoStep)+1;
                    itheta=(theta/thetaStep)+1;
                    
                    IfoveaTransR(y,x)=interp2(IlogPolarR,itheta,irho,'linear',0);
                    IfoveaTransG(y,x)=interp2(IlogPolarG,itheta,irho,'linear',0);
                    IfoveaTransB(y,x)=interp2(IlogPolarB,itheta,irho,'linear',0);
                           
                end
            end
           
            IfoveaTrans(:,:,1)=IfoveaTransR;
            IfoveaTrans(:,:,2)=IfoveaTransG;
            IfoveaTrans(:,:,3)=IfoveaTransB;
            
            app.Image_2.ImageSource=uint8(IfoveaTrans);
            disp("He mostrado ya la imagen");
        end

        % Value changed function: FoveaPositionColumnSlider
        function FoveaPositionColumnSliderValueChanged(app, event)
            %value = app.FoveaPositionColumnSlider.Value; 
        end

        % Value changed function: FoveaPositionRowSlider
        function FoveaPositionRowSliderValueChanged(app, event)
            %value = app.FoveaPositionRowSlider.Value;            s
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FoveaTransformLabel = uilabel(app.UIFigure);
            app.FoveaTransformLabel.Text = 'Fovea Transform';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.FoveaPositionColumnSliderLabel = uilabel(app.UIFigure);
            app.FoveaPositionColumnSliderLabel.Text = {'Fovea Position'; '(Column)'};
            app.FoveaPositionColumnSlider = uislider(app.UIFigure);
            app.FoveaPositionColumnSlider.Limits = [-6 6];
            app.FoveaPositionColumnSlider.ValueChangedFcn = createCallbackFcn(app, @FoveaPositionColumnSliderValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.ORIGINALIMAGELabel = uilabel(app.UIFigure);
            app.ORIGINALIMAGELabel.Text = 'ORIGINAL IMAGE';
            app.TRANSFORMEDIMAGELabel = uilabel(app.UIFigure);
            app.TRANSFORMEDIMAGELabel.Text = 'TRANSFORMED IMAGE';
            app.FoveaPositionRowSliderLabel = uilabel(app.UIFigure);
            app.FoveaPositionRowSliderLabel.Text = {'Fovea Position'; '(Row)'};
            app.FoveaPositionRowSlider = uislider(app.UIFigure);
            app.FoveaPositionRowSlider.Limits = [-6 6];
            app.FoveaPositionRowSlider.ValueChangedFcn = createCallbackFcn(app, @FoveaPositionRowSliderValueChanged, true);
            app.RatiooftheclearpartSliderLabel = uilabel(app.UIFigure);
            app.RatiooftheclearpartSliderLabel.Text = 'Ratio of the clear part';
            app.RatiooftheclearpartSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_FoveaTransformApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end