classdef CeuL4_IncreaseDecreaseBrightnessApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BluechannelSlider        matlab.ui.control.Slider
        BluechannelSliderLabel   matlab.ui.control.Label
        GreenchannelSlider       matlab.ui.control.Slider
        GreenchannelSliderLabel  matlab.ui.control.Label
        RedchannelSlider         matlab.ui.control.Slider
        RedchannelSliderLabel    matlab.ui.control.Label
        ImageLabel               matlab.ui.control.Label
        IncreaseDecreaseBrightnessLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.UIAxes
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            I = getimage(app.h);
            image(app.Image,I);
            axis(app.Image,'off');
        end

        % Callback function
        function ExecuteButtonPushed(app, event)
  
                app.parentApp.updateImageList();
           
        end

        % Value changed function: RedchannelSlider
        function RedchannelSliderValueChanged(app, event)
             I = getimage(app.h);
              windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
            BrightSliderValueRed = app.RedchannelSlider.Value;            
            BrightSliderValueGreen = app.GreenchannelSlider.Value;
            BrightSliderValueBlue = app.BluechannelSlider.Value;
             
            R = R + BrightSliderValueRed ; % to be changed
            G = G + BrightSliderValueGreen ;
            B = B + BrightSliderValueBlue ;
            
             I2 = zeros(size(I));
                 I2(:,:,1)= R;
                 I2(:,:,2)= G;
                 I2(:,:,3)= B;              
                 %imshow(uint8(I2));
                 image(app.Image,uint8(I2));
            end
        end

        % Value changed function: GreenchannelSlider
        function GreenchannelSliderValueChanged(app, event)
             I = getimage(app.h);
              windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
            BrightSliderValueRed = app.RedchannelSlider.Value;            
            BrightSliderValueGreen = app.GreenchannelSlider.Value;
            BrightSliderValueBlue = app.BluechannelSlider.Value;
             
            R = R + BrightSliderValueRed ; % to be changed
            G = G + BrightSliderValueGreen ;
            B = B + BrightSliderValueBlue ;
            
             I2 = zeros(size(I));
                 I2(:,:,1)= R;
                 I2(:,:,2)= G;
                 I2(:,:,3)= B;
                 %imshow(uint8(I2));
                 image(app.Image,uint8(I2));
            end
        end

        % Value changed function: BluechannelSlider
        function BluechannelSliderValueChanged(app, event)
             I = getimage(app.h);
              windowName = app.h.Name;
            if length(size(I))==3
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
            BrightSliderValueRed = app.RedchannelSlider.Value;            
            BrightSliderValueGreen = app.GreenchannelSlider.Value;
            BrightSliderValueBlue = app.BluechannelSlider.Value;
             
            R = R + BrightSliderValueRed ; % to be changed
            G = G + BrightSliderValueGreen ;
            B = B + BrightSliderValueBlue ;
            
              I2 = zeros(size(I));
                 I2(:,:,1)= R;
                 I2(:,:,2)= G;
                 I2(:,:,3)= B;
                 %imshow(uint8(I2));
                 image(app.Image,uint8(I2));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiaxes(app.UIFigure);
            app.IncreaseDecreaseBrightnessLabel = uilabel(app.UIFigure);
            app.IncreaseDecreaseBrightnessLabel.Text = 'Increase/Decrease Brightness';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.RedchannelSliderLabel = uilabel(app.UIFigure);
            app.RedchannelSliderLabel.Text = 'Red channel';
            app.RedchannelSlider = uislider(app.UIFigure);
            app.RedchannelSlider.Limits = [-100 100];
            app.RedchannelSlider.ValueChangedFcn = createCallbackFcn(app, @RedchannelSliderValueChanged, true);
            app.GreenchannelSliderLabel = uilabel(app.UIFigure);
            app.GreenchannelSliderLabel.Text = 'Green channel';
            app.GreenchannelSlider = uislider(app.UIFigure);
            app.GreenchannelSlider.Limits = [-100 100];
            app.GreenchannelSlider.ValueChangedFcn = createCallbackFcn(app, @GreenchannelSliderValueChanged, true);
            app.BluechannelSliderLabel = uilabel(app.UIFigure);
            app.BluechannelSliderLabel.Text = 'Blue channel';
            app.BluechannelSlider = uislider(app.UIFigure);
            app.BluechannelSlider.Limits = [-100 100];
            app.BluechannelSlider.ValueChangedFcn = createCallbackFcn(app, @BluechannelSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_IncreaseDecreaseBrightnessApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end