classdef CeuL4_IncreaseDecreaseGamma < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        IncreaseDecreaseGammaLabel  matlab.ui.control.Label
        ExporttootherwindowButton   matlab.ui.control.Button
        ImageLabel                  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        SliderLabel                 matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
          app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.Image.ImageSource=app.h.Name;
        end

        % Button pushed function: ExporttootherwindowButton
        function ExporttootherwindowButtonPushed(app, event)
            value = app.Slider.Value;
            I = getimage(app.h);
            windowName = app.h.Name;
            J = uint8(255 * (double(I)/255).^(1/value) );
            header = figure('Name',strcat(windowName," (Gamma)"));
            imshow(J);
            figure(header);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            value = app.Slider.Value;
            
            I = getimage(app.h);
            J = uint8(255 * (double(I)/255).^(1/value) );
            
            app.Image.ImageSource = J;          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IncreaseDecreaseGammaLabel = uilabel(app.UIFigure);
            app.IncreaseDecreaseGammaLabel.Text = 'Increase/Decrease Gamma:';
            app.ExporttootherwindowButton = uibutton(app.UIFigure, 'push');
            app.ExporttootherwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttootherwindowButtonPushed, true);
            app.ExporttootherwindowButton.Text = 'Export to other window';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.Image = uiimage(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 3];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_IncreaseDecreaseGamma(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end