classdef CeuL4_RGBDisplayPatternsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SeparateRGBchannelsLabel   matlab.ui.control.Label
        AlteredchannelButtonGroup  matlab.ui.container.ButtonGroup
        RedchannelButton           matlab.ui.control.RadioButton
        GreenchannelButton         matlab.ui.control.RadioButton
        BluechannelButton          matlab.ui.control.RadioButton
        ExecuteButton              matlab.ui.control.Button
        ImageLabel                 matlab.ui.control.Label
        AllchannelsButton          matlab.ui.control.RadioButton
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            if (length(size(I))==3)
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                if app.RedchannelButton.Value
                   for i=1:2:size(R)
                       R = R - diag(diag(R,i),i);
                       R = R - diag(diag(R,-i),-i);
                   end
                   I(:,:,1)=R;
                end
                if app.GreenchannelButton.Value
                  for i=1:2:size(G)
                       G = G - diag(diag(G,i),i);
                       G = G - diag(diag(G,-i),-i);
                  end  
                  I(:,:,2)=G;
                end
                if app.BluechannelButton.Value
                   for i=1:2:size(B)
                       B = B - diag(diag(B,i),i);
                       B = B - diag(diag(B,-i),-i);
                   end 
                   I(:,:,3)=B;
                end
                if app.AllchannelsButton.Value
                    
                    for i=1:2:size(R)
                       R = R - diag(diag(R,i),i);
                       R = R - diag(diag(R,-i),-i);
                    end
                    
                    for i=1:2:size(G)
                       G = G - diag(diag(G,i),i);
                       G = G - diag(diag(G,-i),-i);
                    end
                    
                    for i=1:2:size(B)
                       B = B - diag(diag(B,i),i);
                       B = B - diag(diag(B,-i),-i);
                    end 
                    
                   I(:,:,1)=R;
                   I(:,:,2)=G;
                   I(:,:,3)=B;
                end
                hr = figure('Name',strcat(windowName, "Reconstructed image"));
                imshow(I);
                app.parentApp.updateImageList();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.SeparateRGBchannelsLabel = uilabel(app.UIFigure);
            app.SeparateRGBchannelsLabel.Text = 'Separate RGB channels';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.AlteredchannelButtonGroup = uibuttongroup(app.UIFigure);
            app.AlteredchannelButtonGroup.Title = 'Altered channel';
            app.RedchannelButton = uiradiobutton(app.AlteredchannelButtonGroup);
            app.RedchannelButton.Text = 'Red channel';
            app.RedchannelButton.Value = true;
            app.GreenchannelButton = uiradiobutton(app.AlteredchannelButtonGroup);
            app.GreenchannelButton.Text = 'Green channel';
            app.BluechannelButton = uiradiobutton(app.AlteredchannelButtonGroup);
            app.BluechannelButton.Text = 'Blue channel';
            app.AllchannelsButton = uiradiobutton(app.AlteredchannelButtonGroup);
            app.AllchannelsButton.Text = 'All channels';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CeuL4_RGBDisplayPatternsApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end