classdef CeuL4_Separate_function_chanelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ApplydifferentfunctiontochannelsLabel  matlab.ui.control.Label
        ExecuteButton              matlab.ui.control.Button
        OriginalImageLabel         matlab.ui.control.Label
        RedChannelDropDownLabel    matlab.ui.control.Label
        RedChannelDropDown         matlab.ui.control.DropDown
        GreenChannelDropDownLabel  matlab.ui.control.Label
        GreenChannelDropDown       matlab.ui.control.DropDown
        BlueChannelDropDownLabel   matlab.ui.control.Label
        BlueChannelDropDown        matlab.ui.control.DropDown
        Image                      matlab.ui.control.Image
    end

    % Author: Carlos Oscar Sorzano    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
%         function Ctransformed = transformChannel(app, C, op, newImage)
%             if strcmp(op,'Identity')
%                 newImage(:,:,1)= (C);
% %                 
%             elseif strcmp(op,'Invert')
%                     newImage(:,:,1)= (255-C);
% %                     
%                 elseif strcmp(op,'Sigmoidal')
%                         sig = 255./(1.0+exp(-1/128*(double(C)-128)));
%                         sig=min(max(sig,0),255);
%                         newImage(:,:,1)= uint8(sig);
% %                         
%                         
%             end
%         return newImage;
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            %app.h2 = h;
            app.OriginalImageLabel.Text=strcat("Original Image: ",h.Name);
            %app.TargetingImageLabel.Text=strcat("Targeting Image: ",h2.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)

            I = getimage(app.h);
            windowName = app.h.Name;
           
            if length(size(I))==3 
                %split into rgb channels
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
                app.parentApp.updateImageList();
            end
            
            newImage = zeros(size(I), "uint8");
            
            redOption = app.RedChannelDropDown.Value;
            if strcmp(redOption,'Identity')
                newImage(:,:,1)= (R);

            elseif strcmp(redOption,'Invert')
                    newImage(:,:,1)= (255-R);

                elseif strcmp(redOption,'Sigmoidal')
                        sig = 255./(1.0+exp(-1/128*(double(R)-128)));
                        sig=min(max(sig,0),255);
                        newImage(:,:,1)= uint8(sig);
                   

            end
            
            greenOption = app.GreenChannelDropDown.Value;
            if strcmp(greenOption,'Identity')
                newImage(:,:,2)= (G);

            elseif strcmp(greenOption,'Invert')
                    newImage(:,:,2)= (255-G);

                elseif strcmp(greenOption,'Sigmoidal')
                        sig = 255./(1.0+exp(-1/128*(double(G)-128)));
                        sig=min(max(sig,0),255);
                        newImage(:,:,2)= uint8(sig);

            end
            
            blueOption = app.BlueChannelDropDown.Value;
            if strcmp(blueOption,'Identity')
                newImage(:,:,3)= (B);
                
            elseif strcmp(blueOption,'Invert')
                    newImage(:,:,3)= (255-B);
                    
                elseif strcmp(blueOption,'Sigmoidal') 
                        sig = 255./(1.0+exp(-1/128*(double(B)-128)));
                        sig=min(max(sig,0),255);
                        newImage(:,:,3)= uint8(sig);
                        
            end
            
              app.Image.ImageSource = newImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ApplydifferentfunctiontochannelsLabel = uilabel(app.UIFigure);
            app.ApplydifferentfunctiontochannelsLabel.Text = 'Apply different function to channels';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image: ';
            app.RedChannelDropDownLabel = uilabel(app.UIFigure);
            app.RedChannelDropDownLabel.Text = 'Red Channel';
            app.RedChannelDropDown = uidropdown(app.UIFigure);
            app.RedChannelDropDown.Items = {'Identity', 'Invert', 'Sigmoidal'};
            app.RedChannelDropDown.Value = 'Identity';
            app.GreenChannelDropDownLabel = uilabel(app.UIFigure);
            app.GreenChannelDropDownLabel.Text = 'Green Channel';
            app.GreenChannelDropDown = uidropdown(app.UIFigure);
            app.GreenChannelDropDown.Items = {'Identity', 'Invert', 'Sigmoidal'};
            app.GreenChannelDropDown.Value = 'Identity';
            app.BlueChannelDropDownLabel = uilabel(app.UIFigure);
            app.BlueChannelDropDownLabel.Text = 'Blue Channel';
            app.BlueChannelDropDown = uidropdown(app.UIFigure);
            app.BlueChannelDropDown.Items = {'Identity', 'Invert', 'Sigmoidal'};
            app.BlueChannelDropDown.Value = 'Identity';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL4_Separate_function_chanelApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end