classdef CeuL5_CMYKEncodingPatternsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CMYKpatternsLabel    matlab.ui.control.Label
        ChangetoButtonGroup  matlab.ui.container.ButtonGroup
        RGBButton            matlab.ui.control.RadioButton
        CMYKButton           matlab.ui.control.RadioButton
        ExecuteButton        matlab.ui.control.Button
        ImageLabel           matlab.ui.control.Label
    end

    % Author: Carlos Oscar Sorzano    
    % Author: Héctor Yebes del Pino 
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)


            %RGB image
            if app.RGBButton.Value
                %I = getimage(app.h);
                %windowName = app.h.Name;
                %figure;
                %imshow(I);
                img = imread('fluorescenceCells.jpg');
                %figure;
                figure('Name','RGB image','NumberTitle','off');
                subplot(2,1,2);
                imshow(img);
                title('RGB image');
                R = img(:,:,1);
                B = img(:,:,2);
                G = img(:,:,3);
                subplot(2,1,1);
                imshow([R,G,B]);
                title(['Red, Green and Blue']);        
            end


            %RGB to CMYK
            if app.CMYKButton.Value  
            %figure;
            figure('Name','CMYK image','NumberTitle','off');
            img = imread('fluorescenceCells.jpg');
            
            R = img(:,:,1);
            B = img(:,:,2);
            G = img(:,:,3);
            C = 1.0 - (double(R)./double(255.0));
            M = 1.0 - (double(G)./double(255.0));
            Y = 1.0 - (double(B)./double(255.0));
            c = uint8(C*255);
            m = uint8(M * 255);
            y = uint8(Y * 255);
            subplot(2,1,1);
            imshow([c,m,y]);
            title('Cyan, Magenta and Yellow');

            
            x = [];
            x(:,:,1) = c;
            x(:,:,2) = m;
            x(:,:,3) = y;
            x = uint8(x);
            
            subplot(2,1,2);     
            imshow(x);        
            title('CMY image');
            
            end


             %inprof = iccread('sRGB.icm');
            %outprof = iccread('USSheetfedCoated.icc');
            %C = makecform('icc',inprof,outprof);
            %I_cmyk = applycform(I,C);
            %figure;
            %imshow(I_cmyk);
            
            
%             
% %MATRIX
%        
% if app.CMYKButton.Value
%     if length(size(I))==3
%                  R = I(:,:,1);
%                  G = I(:,:,2);
%                  B = I(:,:,3);
%     %rgb2cmykMatrix = [min(1-R, 1-G, 1-B), (1-R-K)/(1-K) ,(1-G-K)/(1-K),(1-B-K)/(1-K)]
%     K = min(1-R, 1-G, 1-B);
%     C = (1-R-K)/(1-K);
%     M = (1-G-K)/(1-K);
%     Y = (1-B-K)/(1-K);
%     ICMYK = ([C,M,Y]);
%     imgshow(ICMYK);
%     end
% end    


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CMYKpatternsLabel = uilabel(app.UIFigure);
            app.CMYKpatternsLabel.Text = 'CMYK patterns ';
            app.ChangetoButtonGroup = uibuttongroup(app.UIFigure);
            app.ChangetoButtonGroup.Title = 'Change to:';
            app.RGBButton = uiradiobutton(app.ChangetoButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.CMYKButton = uiradiobutton(app.ChangetoButtonGroup);
            app.CMYKButton.Text = 'CMYK';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL5_CMYKEncodingPatternsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end