classdef CeuL5_OpticalIllusionsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OpticalIllusionsLabel    matlab.ui.control.Label
        ExecuteButton            matlab.ui.control.Button
        ChooseopticalillusionButtonGroup  matlab.ui.container.ButtonGroup
        ParallelBarsButton       matlab.ui.control.RadioButton
        CornsweetillusionButton  matlab.ui.control.RadioButton
        MunkerIllusionButton     matlab.ui.control.RadioButton
        TextArea                 matlab.ui.control.TextArea
    end

    % Author: Alejandro Dantart   
    
    properties (Access = private)
        parentApp % parent app
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
 if app.ParallelBarsButton.Value
    rows = 7;
    cols = 15;
    rows = max(3,ceil(abs(rows(1))));
    cols = max(5,ceil(abs(cols(1))));
    illusion = ones(rows,2*cols);
    illusion(1:2:rows,3:4:2*cols) = 0;
    illusion(:,2:4:2*cols) = 0;
    illusion(2:2:rows,1:4:2*cols) = 0;
    figure('Name','Optical Illusion','Numbertitle','off','Menubar','none');
    axes('Units','normalized','Position',[.05 .05 .9 .85]);
    imagesc(illusion);
    colormap(gray); hold on
    for r = 1/2:rows+1/2
      line([1/2 2*(cols+1)], [r r], 'color', 'b', 'linewidth', 2)
    end
    title(' Do the blue lines look parallel to you? ')
    axis([1/2 2*cols+1/2 1/2 rows+1/2])
    axis off; hold off 
 end
 if app.CornsweetillusionButton.Value
    filename= 'wikiImages/CornsweetEffect.jpg';
    imshow (filename);
    mensaje= "Cover the join of the 2 squares with your finger. Both halves are the same color!!!";
    %uicontrol('Style','text','unit','inches','position',[1 4.25 1 1],'String',mensaje);
    xlabel(mensaje)
 end
 if app.MunkerIllusionButton.Value
     
    fn= 'wikiImages/MunkersIllusion.png';
    [I,map] = imread(fn);
    figure('Name','Munker illusion');
    imshow(I,map);

    R = I(:,:,1);
    G = I(:,:,2);
    B = I(:,:,3);
    hr = figure('Name',strcat("Munkers Illusion"," (red)"));
    imshow(R);
    hg = figure('Name',strcat("Munkers Illusion"," (green)"));
    imshow(G);
    hb = figure('Name',strcat("Munkers Illusion"," (blue)"));
    imshow(B);
     z = zeros(256,1);
    l = ([0:255]')/255;
    figure(hr);
    colormap([l z z]);
    figure(hg);
    colormap([z l z]);
    figure(hb);
    colormap([z z l]); 
 end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpticalIllusionsLabel = uilabel(app.UIFigure);
            app.OpticalIllusionsLabel.Text = 'Optical Illusions';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ChooseopticalillusionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseopticalillusionButtonGroup.Title = 'Choose optical illusion';
            app.ParallelBarsButton = uiradiobutton(app.ChooseopticalillusionButtonGroup);
            app.ParallelBarsButton.Text = {'Parallel Bars'; ''};
            app.ParallelBarsButton.Value = true;
            app.CornsweetillusionButton = uiradiobutton(app.ChooseopticalillusionButtonGroup);
            app.CornsweetillusionButton.Text = {'Cornsweet illusion'; ''};
            app.MunkerIllusionButton = uiradiobutton(app.ChooseopticalillusionButtonGroup);
            app.MunkerIllusionButton.Text = 'Munker Illusion';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'-Parallel Bars. Even they dont look like it, they are parallel. Contrast polarities seem to be the determining factor in the tilt''s direction.'; ''; '-Cornsweet.The brain uses the edges of shapes to impart information to the surrounding areas. This is why we perceive different contrasts in the squares even though they are exactly the same.'; ''; '-Munker´s. There are no blue spirals, all of them are green. This illusion shows how much the perception of color is influenced by the neighborhood.'; '  '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL5_OpticalIllusionsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end