classdef CeuL5_PixelizationChannelsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PixelizationofchannelsandluminanceLabel  matlab.ui.control.Label
        ExecuteButton         matlab.ui.control.Button
        ImageLabel            matlab.ui.control.Label
        PixelizationofColorImagesDropDownLabel  matlab.ui.control.Label
        PixelizationofColorImagesDropDown  matlab.ui.control.DropDown
        PixelizationAllBands  matlab.ui.container.ButtonGroup
        Button_4x             matlab.ui.control.RadioButton
        Button_8x             matlab.ui.control.RadioButton
        Button_16x            matlab.ui.control.RadioButton
        Button_2x             matlab.ui.control.RadioButton
        PixelizationRedChannelButtonGroup  matlab.ui.container.ButtonGroup
        Button_1x_R           matlab.ui.control.RadioButton
        Button_4x_R           matlab.ui.control.RadioButton
        Button_8x_R           matlab.ui.control.RadioButton
        Button_16x_R          matlab.ui.control.RadioButton
        Button_2x_R           matlab.ui.control.RadioButton
        PixelizationGreenChannelButtonGroup  matlab.ui.container.ButtonGroup
        Button_1x_G           matlab.ui.control.RadioButton
        Button_4x_G           matlab.ui.control.RadioButton
        Button_8x_G           matlab.ui.control.RadioButton
        Button_16x_G          matlab.ui.control.RadioButton
        Button_2x_G           matlab.ui.control.RadioButton
        PixelizationBlueChannelButtonGroup  matlab.ui.container.ButtonGroup
        Button_1x_B           matlab.ui.control.RadioButton
        Button_4x_B           matlab.ui.control.RadioButton
        Button_8x_B           matlab.ui.control.RadioButton
        Button_16x_B          matlab.ui.control.RadioButton
        Button_2x_B           matlab.ui.control.RadioButton
        ErrorLabel            matlab.ui.control.Label
    end

    % Author: Susana del Riego 
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            
            app.PixelizationAllBands.Visible=true;
            app.PixelizationRedChannelButtonGroup.Visible=false;
            app.PixelizationGreenChannelButtonGroup.Visible=false;
            app.PixelizationBlueChannelButtonGroup.Visible=false;
            
            app.ErrorLabel.Visible=false;

        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;                   
            option= find(strcmp(app.PixelizationofColorImagesDropDown.Items, ...
                app.PixelizationofColorImagesDropDown.Value));
            pixelize = @(block_struct) repmat(mean(mean(block_struct.data,1),2), ...
                   [block_struct.blockSize(1) block_struct.blockSize(2) 1]);                          
            if option==1
              %All bands equal
               if app.Button_2x.Value
                   s=2;    
               elseif app.Button_4x.Value
                   s=4;        
               elseif app.Button_8x.Value
                   s=8;
               else   
                   s=16;
               end
                Ia=uint8(blockproc(I,[s s],pixelize));
                figure('Name',strcat(windowName,[ ' (' num2str(s) 'x)']),'Position', [750,170,400,350]);
                imshow(Ia);  
     
           
            elseif option==2
                %R, G & B bands
                if length(size(I))==3
                z = zeros(256,1);
                l = ([0:255]')/255;  
                R = I(:,:,1);  
                G = I(:,:,2);
                B = I(:,:,3);
                
            %color RED                
                if app.Button_1x_R.Value
                    r=1;                    
                elseif app.Button_2x_R.Value
                    r=2;
                elseif app.Button_4x_R.Value
                    r=4;
                elseif app.Button_8x_R.Value
                    r=8;    
                elseif app.Button_16x_R.Value
                    r=16;     
                end
                IR=uint8(blockproc(R,[r r],pixelize));
                  

             %color GREEN  
               if app.Button_1x_G.Value
                   g=1; 
               elseif app.Button_2x_G.Value
                   g=2;                
               elseif app.Button_4x_G.Value
                   g=4;
               elseif app.Button_8x_G.Value
                   g=8;
               elseif app.Button_16x_G.Value
                   g=16;     
               end
                IG=uint8(blockproc(G,[g g],pixelize));

                
             %color BLUE  
               if app.Button_1x_B.Value
                   b=1; 
               elseif app.Button_2x_B.Value
                   b=2;       
               elseif app.Button_4x_B.Value
                   b=4;             
               elseif app.Button_8x_B.Value
                   b=8;             
               elseif app.Button_16x_B.Value
                   b=16;                  
               end
                IB=uint8(blockproc(B,[b b],pixelize));

                
                figure('Name',strcat(windowName, [' (red ' num2str(r) 'x, green ' num2str(g) 'x, blue ' num2str(b) 'x)']),'Position', [670 380 600 200]);                
                subplot(1,3,1); imshow(IR);title(['red ' num2str(r) 'x']);
                colormap(gca,[l z z])
                subplot(1,3,2); imshow(IG);title(['green ' num2str(g) 'x']);
                colormap(gca,[z l z])
                subplot(1,3,3); imshow(IB);title(['blue ' num2str(b) 'x']);
                colormap(gca,[z z l])


               I_merge=zeros([size(R,1) size(R,2) 3]);
               I_merge(:,:,1) = IR;
               I_merge(:,:,2) = IG;
               I_merge(:,:,3) = IB;
               figure('Name',strcat(windowName,' (R,G&B bands custom)'), ...
                   'Position',[810,30,400,340]);
               imshow(uint8(I_merge));
    
                else
                   app.ErrorLabel.Visible=true;
                end
         end
        end

        % Value changed function: PixelizationofColorImagesDropDown
        function PixelizationofColorImagesDropDownValues(app, event)
                     
            option = find(strcmp(app.PixelizationofColorImagesDropDown.Items, ...
                                  app.PixelizationofColorImagesDropDown.Value));

                
            if option==1
                %ocultar opcion 2
                app.PixelizationAllBands.Visible=true;
                app.PixelizationRedChannelButtonGroup.Visible=false;
                app.PixelizationGreenChannelButtonGroup.Visible=false;
                app.PixelizationBlueChannelButtonGroup.Visible=false;

            elseif option==2
                %ocultar opcion 1
                app.PixelizationAllBands.Visible=false;
                app.PixelizationRedChannelButtonGroup.Visible=true;
                app.PixelizationGreenChannelButtonGroup.Visible=true;
                app.PixelizationBlueChannelButtonGroup.Visible=true;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PixelizationofchannelsandluminanceLabel = uilabel(app.UIFigure);
            app.PixelizationofchannelsandluminanceLabel.Text = 'Pixelization of channels and luminance';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.PixelizationofColorImagesDropDownLabel = uilabel(app.UIFigure);
            app.PixelizationofColorImagesDropDownLabel.Text = {'Pixelization of Color Images'; ''};
            app.PixelizationofColorImagesDropDown = uidropdown(app.UIFigure);
            app.PixelizationofColorImagesDropDown.Items = {'All bands equal', 'R, G & B bands'};
            app.PixelizationofColorImagesDropDown.ValueChangedFcn = createCallbackFcn(app, @PixelizationofColorImagesDropDownValues, true);
            app.PixelizationofColorImagesDropDown.Value = 'All bands equal';
            app.PixelizationAllBands = uibuttongroup(app.UIFigure);
            app.PixelizationAllBands.Title = 'Pixelization';
            app.Button_4x = uiradiobutton(app.PixelizationAllBands);
            app.Button_4x.Text = '4x';
            app.Button_8x = uiradiobutton(app.PixelizationAllBands);
            app.Button_8x.Text = '8x';
            app.Button_16x = uiradiobutton(app.PixelizationAllBands);
            app.Button_16x.Text = '16x';
            app.Button_2x = uiradiobutton(app.PixelizationAllBands);
            app.Button_2x.Text = '2x';
            app.Button_2x.Value = true;
            app.PixelizationRedChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.PixelizationRedChannelButtonGroup.Title = 'Pixelization Red Channel';
            app.Button_1x_R = uiradiobutton(app.PixelizationRedChannelButtonGroup);
            app.Button_1x_R.Text = '1x';
            app.Button_1x_R.Value = true;
            app.Button_4x_R = uiradiobutton(app.PixelizationRedChannelButtonGroup);
            app.Button_4x_R.Text = '4x';
            app.Button_8x_R = uiradiobutton(app.PixelizationRedChannelButtonGroup);
            app.Button_8x_R.Text = '8x';
            app.Button_16x_R = uiradiobutton(app.PixelizationRedChannelButtonGroup);
            app.Button_16x_R.Text = '16x';
            app.Button_2x_R = uiradiobutton(app.PixelizationRedChannelButtonGroup);
            app.Button_2x_R.Text = '2x';
            app.PixelizationGreenChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.PixelizationGreenChannelButtonGroup.Title = 'Pixelization Green Channel';
            app.Button_1x_G = uiradiobutton(app.PixelizationGreenChannelButtonGroup);
            app.Button_1x_G.Text = '1x';
            app.Button_1x_G.Value = true;
            app.Button_4x_G = uiradiobutton(app.PixelizationGreenChannelButtonGroup);
            app.Button_4x_G.Text = '4x';
            app.Button_8x_G = uiradiobutton(app.PixelizationGreenChannelButtonGroup);
            app.Button_8x_G.Text = '8x';
            app.Button_16x_G = uiradiobutton(app.PixelizationGreenChannelButtonGroup);
            app.Button_16x_G.Text = '16x';
            app.Button_2x_G = uiradiobutton(app.PixelizationGreenChannelButtonGroup);
            app.Button_2x_G.Text = '2x';
            app.PixelizationBlueChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.PixelizationBlueChannelButtonGroup.Title = 'Pixelization Blue Channel';
            app.Button_1x_B = uiradiobutton(app.PixelizationBlueChannelButtonGroup);
            app.Button_1x_B.Text = '1x';
            app.Button_1x_B.Value = true;
            app.Button_4x_B = uiradiobutton(app.PixelizationBlueChannelButtonGroup);
            app.Button_4x_B.Text = '4x';
            app.Button_8x_B = uiradiobutton(app.PixelizationBlueChannelButtonGroup);
            app.Button_8x_B.Text = '8x';
            app.Button_16x_B = uiradiobutton(app.PixelizationBlueChannelButtonGroup);
            app.Button_16x_B.Text = '16x';
            app.Button_2x_B = uiradiobutton(app.PixelizationBlueChannelButtonGroup);
            app.Button_2x_B.Text = '2x';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = {'To execute this option image must'; ' have 3 color channels'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL5_PixelizationChannelsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end