classdef CeuL6_MatrixCorrectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ColorMatrixCorrectionwithColorPairsLabel  matlab.ui.control.Label
        ImageLabel                      matlab.ui.control.Label
        ChoosenumberofpairsDropDown_2Label  matlab.ui.control.Label
        ChoosenumberofpairsDropDown_2   matlab.ui.control.DropDown
        writethevectorssepparatingthecoordinateswithLabel  matlab.ui.control.Label
        FromimageILabel                 matlab.ui.control.Label
        Vector1EditFieldLabel           matlab.ui.control.Label
        Vector1EditField                matlab.ui.control.EditField
        Vector2EditFieldLabel           matlab.ui.control.Label
        Vector2EditField                matlab.ui.control.EditField
        Vector3EditFieldLabel           matlab.ui.control.Label
        Vector3EditField                matlab.ui.control.EditField
        FromimageJLabel                 matlab.ui.control.Label
        Vector4EditFieldLabel           matlab.ui.control.Label
        Vector4EditField                matlab.ui.control.EditField
        Vector5EditFieldLabel           matlab.ui.control.Label
        Vector5EditField                matlab.ui.control.EditField
        Vector6EditFieldLabel           matlab.ui.control.Label
        Vector6EditField                matlab.ui.control.EditField
        executeButton                   matlab.ui.control.Button
        IntroducethenumberofpairsLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
    end

    % Author: Alba Ortiz-Vallejuelo
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        I % Description
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            
            app.I = getimage(app.h);
            image(app.imageAxis, app.I);
            
            app.writethevectorssepparatingthecoordinateswithLabel.Visible = 'off';
                        app.FromimageILabel.Visible = 'off';
                        app.Vector1EditField.Visible = 'off';
                        app.Vector2EditField.Visible = 'off';
                        app.Vector3EditField.Visible = 'off';
                        app.FromimageJLabel.Visible = 'off';
                        app.Vector4EditField.Visible = 'off';
                        app.Vector5EditField.Visible = 'off';
                        app.Vector6EditField.Visible = 'off';
             app.IntroducethenumberofpairsLabel.Visible = 'off';
       
        end

        % Button pushed function: executeButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            windowName = app.h.Name;
            if length(size(I))==3
                R = double(I(:,:,1));
                G = double(I(:,:,2));
                B = double(I(:,:,3));
                
                idx = find(strcmp(app.IntensityDropDown.Items, ...
                                  app.IntensityDropDown.Value));
       
                A=zeros(3,3);% Conversion matrix initialized to zero
                
                if idx==1
                        app.writethevectorssepparatingthecoordinateswithLabel.Visible = 'on';
                        app.FromimageILabel.Visible = 'on';
                        app.Vector1EditField.Visible = 'on';
                        app.Vector2EditField.Visible = 'on';
                        app.Vector3EditField.Visible = 'on';
                        app.FromimageILabel.Visible = 'on';
                        app.Vector4EditField.Visible = 'on';
                        app.Vector5EditField.Visible = 'on';
                        app.Vector6EditField.Visible = 'on';
                        v1 = app.Vector1EditField.ValueChangedFcn;
                        v2 = app.Vector2EditField.Value;
                        v3 = app.Vector3EditField.Value;
                        I = [v1 v2 v3];
                        v4 = app.Vector4EditField.Value;
                        v5 = app.Vector5EditField.Value;
                        v6 = app.Vector6EditField.Value;
                        J = [v4 v5 v6];
                        
                        A = J*inv(I);
                        
                        figure('Name',windowName);
                        image(A);
                        axis off;
                        tlabel(A);
                        app.parentApp.updateImageList();
          
                end
              
                        app.IntroducethenumberofpairsLabel.Visible = 'on';
                        app.FromimageJLabel.Visible = ' on';
                        num=app.EditField.Value
                        N=num/2;
                        for L = 1:N 
                            fig = uifigure;
                            tlabel = uilabel(fig);
                            tlabel.Text = 'Vector from I'
                            ef = uieditfield(fig,'numeric');
                            v=app.ef.Value;
                            I=[I v];
                            
                        end
                           
                         for j = 1:(num/2) 
                            fig = uifigure;
                            tlabel = uilabel(fig);
                            tlabel.Text = 'Vector from J'
                            ef = uieditfield(fig,'numeric');
                            v=app.ef.Value;
                            J=[J v];


                           A=J*transposeI*inv(I*transpose(I));
                           figure('Name',windowName);
                            image(A);
                            axis off;
                            tlabel(A);
                            app.parentApp.updateImageList();
                            
                         end


                app.parentApp.updateImageList();
            end
        end

        % Callback function
        function ChoosenumberofpairsDropDown_2Opening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorMatrixCorrectionwithColorPairsLabel = uilabel(app.UIFigure);
            app.ColorMatrixCorrectionwithColorPairsLabel.Text = 'Color Matrix Correction with Color Pairs';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.ChoosenumberofpairsDropDown_2Label = uilabel(app.UIFigure);
            app.ChoosenumberofpairsDropDown_2Label.Text = 'Choose number of pairs';
            app.ChoosenumberofpairsDropDown_2 = uidropdown(app.UIFigure);
            app.ChoosenumberofpairsDropDown_2.Items = {'3', 'More than 3'};
            app.ChoosenumberofpairsDropDown_2.Value = '3';
            app.writethevectorssepparatingthecoordinateswithLabel = uilabel(app.UIFigure);
            app.writethevectorssepparatingthecoordinateswithLabel.Text = 'write the vectors sepparating the coordinates with " ; " :';
            app.FromimageILabel = uilabel(app.UIFigure);
            app.FromimageILabel.Text = 'From image I:';
            app.Vector1EditFieldLabel = uilabel(app.UIFigure);
            app.Vector1EditFieldLabel.Visible = 'off';
            app.Vector1EditFieldLabel.Text = 'Vector 1';
            app.Vector1EditField = uieditfield(app.UIFigure, 'text');
            app.Vector2EditFieldLabel = uilabel(app.UIFigure);
            app.Vector2EditFieldLabel.Visible = 'off';
            app.Vector2EditFieldLabel.Text = 'Vector 2';
            app.Vector2EditField = uieditfield(app.UIFigure, 'text');
            app.Vector3EditFieldLabel = uilabel(app.UIFigure);
            app.Vector3EditFieldLabel.Visible = 'off';
            app.Vector3EditFieldLabel.Text = 'Vector 3';
            app.Vector3EditField = uieditfield(app.UIFigure, 'text');
            app.FromimageJLabel = uilabel(app.UIFigure);
            app.FromimageJLabel.Text = 'From image J:';
            app.Vector4EditFieldLabel = uilabel(app.UIFigure);
            app.Vector4EditFieldLabel.Visible = 'off';
            app.Vector4EditFieldLabel.Text = 'Vector 4';
            app.Vector4EditField = uieditfield(app.UIFigure, 'text');
            app.Vector5EditFieldLabel = uilabel(app.UIFigure);
            app.Vector5EditFieldLabel.Visible = 'off';
            app.Vector5EditFieldLabel.Text = 'Vector 5';
            app.Vector5EditField = uieditfield(app.UIFigure, 'text');
            app.Vector6EditFieldLabel = uilabel(app.UIFigure);
            app.Vector6EditFieldLabel.Visible = 'off';
            app.Vector6EditFieldLabel.Text = 'Vector 6';
            app.Vector6EditField = uieditfield(app.UIFigure, 'text');
            app.executeButton = uibutton(app.UIFigure, 'push');
            app.executeButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.executeButton.Text = 'execute';
            app.IntroducethenumberofpairsLabel = uilabel(app.UIFigure);
            app.IntroducethenumberofpairsLabel.Visible = 'off';
            app.IntroducethenumberofpairsLabel.Text = 'Introduce the number of pairs';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL6_MatrixCorrectionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end