classdef CeuL7_2DWaveApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ExecuteButton          matlab.ui.control.Button
        RowsEditFieldLabel     matlab.ui.control.Label
        RowsEditField          matlab.ui.control.EditField
        ColumnsEditFieldLabel  matlab.ui.control.Label
        ColumnsEditField       matlab.ui.control.EditField
        KxSliderLabel          matlab.ui.control.Label
        KxSlider               matlab.ui.control.Slider
        KySliderLabel          matlab.ui.control.Label
        KySlider               matlab.ui.control.Slider
        KxEditFieldLabel       matlab.ui.control.Label
        KxEditField            matlab.ui.control.NumericEditField
        KyEditFieldLabel       matlab.ui.control.Label
        KyEditField            matlab.ui.control.NumericEditField
        slidersjusttoseethelimitsoftheKxyaxisLabel  matlab.ui.control.Label
    end

    % Author: Gustavo Gonzalez da Costa   
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
%             app.parentApp = parentApp;
%             app.h = h;
%             app.ImageLabel.Text=strcat("Image: ",h.Name);
        
        
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
R = str2num(app.RowsEditField.Value);
C = str2num(app.ColumnsEditField.Value);

kx = (app.KxEditField.Value);
ky = (app.KyEditField.Value);

x = (app.KxSlider.Value);
y = (app.KySlider.Value);

phase = 1/(tan(kx/ky));
w = sqrt((kx^2)+(ky^2));
[r,c] = meshgrid([0:R],[0:C]);
z = cos((2*pi)*((kx*r/R)+(ky*c/C)));

imagesc(z);


% 
% 
% using UnityEngine;
% using System.Collections;
%  
% public class ValueUpdate : MonoBehaviour {
%  
%     private UnityEngine.UI.Slider my_slider;
%     private UnityEngine.UI.InputField my_field;
%  
%     void Start() {
%         my_slider = gameObject.GetComponent<UnityEngine.UI.Slider>();
%         my_field = gameObject.GetComponent<UnityEngine.UI.InputField>();
%     }
%  
%     public void UpdateValueFromFloat(float value) {
%         Debug.Log("float value changed: " + value);
%         if (my_slider) { my_slider.value = value; }
%         if (my_field) { my_field.text = value.ToString(); }
%     }
%  
%     public void UpdateValueFromString(string value) {
%         Debug.Log("string value changed: " + value);
%         if (my_slider) { my_slider.value = float.Parse(value); }
%         if (my_field) { my_field.text = value; }
%     }
%  
%  
% }
%     
%     
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
          %  R = app.RowsEditField.Value;
           % app.KySlider.Limits = [-floor(R/2) R-floor(R/2)-1];
        end

        % Value changing function: ColumnsEditField
        function ColumnsEditFieldValueChanging(app, event)
          %  S = app.ColumnsEditField.Value;
           % app.KxSlider.Limits = [-floor(S/2) R-floor(S/2)-1];
            
        end

        % Value changed function: KxEditField
        function myEditBox_callback(app, event)
%         %    function myEditBox_callback2(hObject,handles)
%                 val = str2double(get(app.KxEditField.Value,'String'));
%                 set(app.KxSlider,'Value',val);
               % guidata(hObject,handles);
          %  end
        end

        % Callback function: KxSlider, KxSlider
        function slider1_Callback(app, event)
%             function slider1_Callback(hObject,eventdata,handles)
%                 val = get(hObject,'Value');
%                 set(handles.myEditBox,'String',num2str(val));
%                 guidata(hObject,handles);
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.RowsEditFieldLabel = uilabel(app.UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.UIFigure, 'text');
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = '512';
            app.ColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.UIFigure, 'text');
            app.ColumnsEditField.ValueChangingFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanging, true);
            app.ColumnsEditField.Value = '512';
            app.KxSliderLabel = uilabel(app.UIFigure);
            app.KxSliderLabel.Text = 'Kx';
            app.KxSlider = uislider(app.UIFigure);
            app.KxSlider.Limits = [-256 255];
            app.KxSlider.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.KxSlider.ValueChangingFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.KySliderLabel = uilabel(app.UIFigure);
            app.KySliderLabel.Text = 'Ky';
            app.KySlider = uislider(app.UIFigure);
            app.KySlider.Limits = [-256 255];
            app.KxEditFieldLabel = uilabel(app.UIFigure);
            app.KxEditFieldLabel.Text = 'Kx';
            app.KxEditField = uieditfield(app.UIFigure, 'numeric');
            app.KxEditField.ValueChangedFcn = createCallbackFcn(app, @myEditBox_callback, true);
            app.KyEditFieldLabel = uilabel(app.UIFigure);
            app.KyEditFieldLabel.Text = 'Ky';
            app.KyEditField = uieditfield(app.UIFigure, 'numeric');
            app.slidersjusttoseethelimitsoftheKxyaxisLabel = uilabel(app.UIFigure);
            app.slidersjusttoseethelimitsoftheKxyaxisLabel.Text = 'sliders just to see the limits of the K(x,y) axis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL7_2DWaveApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end