classdef CeuL7_PlotFTImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        PSDLabel               matlab.ui.control.Label
        ImaginarypartLabel     matlab.ui.control.Label
        RealpartLabel          matlab.ui.control.Label
        RealIm                 matlab.ui.control.Image
        PSDIm                  matlab.ui.control.Image
        ImaginaryIm            matlab.ui.control.Image
        TabGroup               matlab.ui.container.TabGroup
        PlotsTab               matlab.ui.container.Tab
        AmplitudeCheckBox      matlab.ui.control.CheckBox
        PhaseCheckBox          matlab.ui.control.CheckBox
        PSDCheckBox            matlab.ui.control.CheckBox
        ImaginarypartCheckBox  matlab.ui.control.CheckBox
        RealpartCheckBox       matlab.ui.control.CheckBox
        YaxisscaleTab          matlab.ui.container.Tab
        ScaleButtonGroup       matlab.ui.container.ButtonGroup
        LinearButton           matlab.ui.control.RadioButton
        LogarithmicButton      matlab.ui.control.RadioButton
        Comparison             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       parentApp
       h
       psd 
       linear
       freqs
       ImagFT
       RealFT
    end
    
    methods (Access = public)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp= parentApp;
            app.h=h;
            app.UIFigure.Name= "Lecture 07. Plot components of the Fourier Transform of an image";
            
            I = getimage(app.h);
            gray = rgb2gray(I);
            
            f=double(gray);
            fft=fft2(f);
            FT=fftshift(fft);
            
            % AMPLITUDE AND PHASE
            grayLine = gray(round(size(gray,2)/2), 1:size(gray,2)); % selects the central channel in real time
            app.linear = fftshift(fft2(grayLine)); % to Fourier domain
            app.freqs = (0:(length(app.linear))-1)*2*pi/length(app.linear) - pi;
            app.RealFT=real(FT);
            app.RealIm.ImageSource = repmat(app.RealFT,1,1,3); %REAL PART
            app.ImagFT = imag(FT);
            app.ImaginaryIm.ImageSource = repmat(uint8(app.ImagFT),1,1,3); %IMAGINARY PART
            %PSD
            app.psd = 10*log10(abs(FT).^2 );
            app.PSDIm.ImageSource = repmat(uint8(app.psd),1,1,3);
            
           
            if app.RealpartCheckBox.Value
                line = app.RealFT(round(size(app.RealFT,2)/2), 1:size(app.RealFT,2)); % selects the central channel
                freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                 plot(app.Comparison, freqX, line);
                 %app.Comparison.YLabel = 'real(FT)';
            end
            
            if app.AmplitudeCheckBox.Value
                plot(app.Comparison, app.freqs, abs(app.linear)); 
                %app.Comparison.YLabel = 'Amplitude (dB)';
            end
            
            if app.PhaseCheckBox.Value
                plot(app.Comparison, app.freqs, angle(app.linear));
            end
            
            if app.ImaginarypartCheckBox.Value
                line = app.ImagFT(round(size(app.ImagFT,2)/2), 1:size(app.ImagFT,2)); % selects the central channel
                freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                plot(app.Comparison, freqX, line);
            end
            
            if app.PSDCheckBox.Value
                line = app.psd(round(size(app.psd,2)/2), 1:size(app.psd,2)); % selects the central channel
                freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                plot(app.Comparison, freqX, line);
            end


        end

        % Selection changed function: ScaleButtonGroup
        function ScaleButtonGroupSelectionChanged(app, event)
            
            if app.LinearButton.Value
                app.Comparison.YScale = 'linear';
            else 
                app.Comparison.YScale = 'log';
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            
            if selectedTab == app.PlotsTab
                
                I = getimage(app.h);
                gray = rgb2gray(I);
                
                f=double(gray);
                fft=fft2(f);
                FT=fftshift(fft);
                
                % AMPLITUDE AND PHASE
                grayLine = gray(round(size(gray,2)/2), 1:size(gray,2)); % selects the central channel in real time
                app.linear = fftshift(fft2(grayLine)); % to Fourier domain
                app.freqs = (0:(length(app.linear))-1)*2*pi/length(app.linear) - pi;
                
                if app.RealpartCheckBox.Value
                    line = app.RealFT(round(size(app.RealFT,2)/2), 1:size(app.RealFT,2));
                    freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                    plot(app.Comparison, freqX, line);
                    %app.Comparison.YLabel.set = 'real(FT)';
                end
                
                if app.AmplitudeCheckBox.Value
                    plot(app.Comparison, app.freqs, abs(app.linear)); 
                    %app.Comparison.YLabel = "Amplitude (dB)";
                end
                
                if app.PhaseCheckBox.Value
                    plot(app.Comparison, app.freqs, angle(app.linear));
                end
                
                if app.ImaginarypartCheckBox.Value
                    line = app.ImagFT(round(size(app.ImagFT,2)/2), 1:size(app.ImagFT,2)); % selects the central channel
                    freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                    plot(app.Comparison, freqX, line);
                end
                
                if app.PSDCheckBox.Value
                    line = app.psd(round(size(app.psd,2)/2), 1:size(app.psd,2)); % selects the central channel
                    freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                    plot(app.Comparison, freqX, line);
                end
                
            
            end
            
            
            if selectedTab == app.YaxisscaleTab
                
                if app.LinearButton.Value
                    app.Comparison.YScale = 'linear';
                else 
                    app.Comparison.YScale = 'log';
                end
            end
        end

        % Value changed function: AmplitudeCheckBox
        function AmplitudeCheckBoxValueChanged(app, event)
            
            if app.AmplitudeCheckBox.Value
                plot(app.Comparison, app.freqs, abs(app.linear)); 
                %app.Comparison.YLabel = "Amplitude (dB)";
            else 
                cla(app.Comparison);
            end
        end

        % Value changed function: PhaseCheckBox
        function PhaseCheckBoxValueChanged(app, event)
            if app.PhaseCheckBox.Value
                plot(app.Comparison, app.freqs, angle(app.linear)); 
            else 
                cla(app.Comparison);
            end
            
        end

        % Value changed function: PSDCheckBox
        function PSDCheckBoxValueChanged(app, event)
             if app.PSDCheckBox.Value
                line = app.psd(round(size(app.psd,2)/2), 1:size(app.psd,2)); % selects the central channel
                freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                plot(app.Comparison, freqX, line);
            else 
                cla(app.Comparison);
            end
            
        end

        % Value changed function: ImaginarypartCheckBox
        function ImaginarypartCheckBoxValueChanged(app, event)
            if app.ImaginarypartCheckBox.Value
                line = app.ImagFT(round(size(app.ImagFT,2)/2), 1:size(app.ImagFT,2)); % selects the central channel
                freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                plot(app.Comparison, freqX, line);
            else  
                cla(app.Comparison);
            end
            
        end

        % Value changed function: RealpartCheckBox
        function RealpartCheckBoxValueChanged(app, event)
             if app.RealpartCheckBox.Value
                line = app.RealFT(round(size(app.RealFT,2)/2), 1:size(app.RealFT,2));
                freqX = (0:(length(line))-1)*2*pi/length(line) - pi;
                plot(app.Comparison, freqX, line);
                %app.Comparison.YLabel.set = 'real(FT)';
            else  
                cla(app.Comparison);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Plot the Fourier Transform of an image ';
            app.PSDLabel = uilabel(app.UIFigure);
            app.PSDLabel.Text = 'PSD';
            app.ImaginarypartLabel = uilabel(app.UIFigure);
            app.ImaginarypartLabel.Text = 'Imaginary part';
            app.RealpartLabel = uilabel(app.UIFigure);
            app.RealpartLabel.Text = 'Real part';
            app.RealIm = uiimage(app.UIFigure);
            app.PSDIm = uiimage(app.UIFigure);
            app.ImaginaryIm = uiimage(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.AmplitudeCheckBox = uicheckbox(app.PlotsTab);
            app.AmplitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AmplitudeCheckBoxValueChanged, true);
            app.AmplitudeCheckBox.Text = 'Amplitude';
            app.AmplitudeCheckBox.Value = true;
            app.PhaseCheckBox = uicheckbox(app.PlotsTab);
            app.PhaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaseCheckBoxValueChanged, true);
            app.PhaseCheckBox.Text = 'Phase';
            app.PSDCheckBox = uicheckbox(app.PlotsTab);
            app.PSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @PSDCheckBoxValueChanged, true);
            app.PSDCheckBox.Text = 'PSD';
            app.ImaginarypartCheckBox = uicheckbox(app.PlotsTab);
            app.ImaginarypartCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImaginarypartCheckBoxValueChanged, true);
            app.ImaginarypartCheckBox.Text = 'Imaginary part';
            app.RealpartCheckBox = uicheckbox(app.PlotsTab);
            app.RealpartCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealpartCheckBoxValueChanged, true);
            app.RealpartCheckBox.Text = 'Real part';
            app.YaxisscaleTab = uitab(app.TabGroup);
            app.YaxisscaleTab.Title = 'Y axis scale';
            app.ScaleButtonGroup = uibuttongroup(app.YaxisscaleTab);
            app.ScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScaleButtonGroupSelectionChanged, true);
            app.ScaleButtonGroup.Title = 'Select a Y axis scale';
            app.LinearButton = uiradiobutton(app.ScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarithmicButton = uiradiobutton(app.ScaleButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.Comparison = uiaxes(app.UIFigure);
            title(app.Comparison, 'Gray image properties')
            xlabel(app.Comparison, 'Frequency (Hz)')
            ylabel(app.Comparison, {''; ''})
            zlabel(app.Comparison, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL7_PlotFTImage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end