classdef CeuL8_Blur_EdgeDetectortApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BlurEdgeDetectorAppLabel        matlab.ui.control.Label
        ChooseEdgesButtonGroup          matlab.ui.container.ButtonGroup
        VerticalDifferenceCheckBox      matlab.ui.control.CheckBox
        HorizontalDifferenceCheckBox    matlab.ui.control.CheckBox
        RightDiagonalDifferenceCheckBox  matlab.ui.control.CheckBox
        LeftDiagonalDifferenceCheckBox  matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        ImageLabel                      matlab.ui.control.Label
        BlurButton                      matlab.ui.control.StateButton
        EdgeDetectionButton             matlab.ui.control.StateButton
        ChooseanoptionLabel             matlab.ui.control.Label
        ChooseSizeofKernelforBlurringButtonGroup  matlab.ui.container.ButtonGroup
        SIZEEditFieldLabel              matlab.ui.control.Label
        SIZEEditField                   matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        ItrepresentthesizeofthesquareLabel  matlab.ui.control.Label
        matrixusedasKernelformovingLabel  matlab.ui.control.Label
        averageconvolutionIncreasetheLabel  matlab.ui.control.Label
        valueforablurrierefectLabel     matlab.ui.control.Label
        IntroduceanaturalnumberLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        OriginalImageButton             matlab.ui.control.StateButton
        PleaseselectanoptionLabel       matlab.ui.control.Label
        UpdateImageButton               matlab.ui.control.Button
    end

    % Author: Diego Lopez Pizarro    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        new_I
        
    end
    
    methods (Access = public)

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.ChooseEdgesButtonGroup.Visible='off';
            app.ChooseSizeofKernelforBlurringButtonGroup.Visible='off';
            app.ExecuteButton.Visible='off';
            app.Image.Visible='off';
            app.OriginalImageButton.Visible='off';
            app.PleaseselectanoptionLabel.Visible="off";
            app.IntroduceanaturalnumberLabel.Visible="off";
            app.UpdateImageButton.Visible="off";

            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            is_gray=false;
            if length(size(I))==3
                gray_I=rgb2gray(I);
            else
                gray_I=I;
                is_gray=true;
            end
            if app.BlurButton.Value
                tam=size(I);
                r=tam(1);
                c=tam(2);
                app.new_I=I;
                tam_kernel=app.SIZEEditField.Value;
                if mod(tam_kernel,1)~=0 || tam_kernel<1
                    app.IntroduceanaturalnumberLabel.Visible="on";
                    app.OriginalImageButton.Visible='off';
                    app.Image.Visible="off";
                else
                    app.IntroduceanaturalnumberLabel.Visible="off";
                    kernel=ones(tam_kernel,tam_kernel);
                    tam_w=size(kernel);
                    rows_w=tam_w(1);
                    cols_w=tam_w(2);
                    l=rows_w*cols_w;
                    cpad=floor(cols_w/2);
                    rpad=floor(rows_w/2);
                    hcorig=cpad+1;
                    hrorig=rpad+1;
                    if is_gray
                        m_prueba=gray_I;
                        aux=zeros(r+2*rpad,c+2*cpad);
                        aux(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_prueba;
                        result1=zeros(r,c);
                        aux2=zeros(rows_w,cols_w);
                        suma1=0;
                        for i=hrorig:hrorig+r-1
                            for j=hcorig:hcorig+c-1
                                aux2=aux(i-rpad:i+rpad,j-cpad:j+rpad);                  
                                %convolution
                                for w=1:rows_w
                                    for y=1:cols_w
                                        suma1=(aux2(w,y)*kernel(w,y))+suma1;
                                    end
                                end
                                result1(i-rpad,j-cpad)=round(suma1/l);
                                suma1=0;                    
                            end
                        end
                        app.new_I=mat2gray(result1);
                        app.Image.Visible="on";
                        app.Image.ImageSource=repmat(app.new_I, 1, 1, 3);
                        app.OriginalImageButton.Visible='on';
                        app.UpdateImageButton.Visible="on";
                    else
                        m_R=I(:,:,1);
                        m_G=I(:,:,2);
                        m_B=I(:,:,3);
                        aux_r=zeros(r+2*rpad,c+2*cpad);
                        aux_r(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_R;
                        aux_g=zeros(r+2*rpad,c+2*cpad);
                        aux_g(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_G;
                        aux_b=zeros(r+2*rpad,c+2*cpad);
                        aux_b(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_B;
                        result_r=zeros(r,c);
                        result_g=zeros(r,c);
                        result_b=zeros(r,c);
                        aux2_r=zeros(rows_w,cols_w);
                        aux2_g=zeros(rows_w,cols_w);
                        aux2_b=zeros(rows_w,cols_w);
                        suma_r=0;
                        suma_g=0;
                        suma_b=0;
                        for i=hrorig:hrorig+r-1
                            for j=hcorig:hcorig+c-1
                                aux2_r=aux_r(i-rpad:i+rpad,j-cpad:j+rpad);
                                aux2_g=aux_g(i-rpad:i+rpad,j-cpad:j+rpad);
                                aux2_b=aux_b(i-rpad:i+rpad,j-cpad:j+rpad);
                        
                                %convolution
                                for w=1:rows_w
                                    for y=1:cols_w
                                        suma_r=(aux2_r(w,y)*kernel(w,y))+suma_r;
                                        suma_g=(aux2_g(w,y)*kernel(w,y))+suma_g;
                                        suma_b=(aux2_b(w,y)*kernel(w,y))+suma_b;
                                    end
                                end
                                result_r(i-rpad,j-cpad)=round(suma_r/l);
                                suma_r=0;
                                result_g(i-rpad,j-cpad)=round(suma_g/l);
                                suma_g=0;
                                result_b(i-rpad,j-cpad)=round(suma_b/l);
                                suma_b=0;
                        
                            end
                        end
                    app.new_I(:,:,1)=result_r;
                    app.new_I(:,:,2)=result_g;
                    app.new_I(:,:,3)=result_b;
                    app.Image.Visible="on";
                    app.Image.ImageSource=app.new_I;
                    app.OriginalImageButton.Visible='on';
                    app.UpdateImageButton.Visible="on";
                end    
                end
  
            end
            if app.EdgeDetectionButton.Value
                tam=size(I);
                r=tam(1);
                c=tam(2);
                app.new_I=I;
                kernel=zeros(3,3); 
                count=0;
                if app.VerticalDifferenceCheckBox.Value
                    kernel=kernel+[0,-1,0;0,2,0;0,-1,0];
                    count=1;
                end
                if app.HorizontalDifferenceCheckBox.Value
                    kernel=kernel+[0,0,0;-1,2,-1;0,0,0];
                    count=1;
                end
                if app.RightDiagonalDifferenceCheckBox.Value
                    kernel=kernel+[-1,0,0;0,2,0;0,0,-1];
                    count=1;
                end
                if app.LeftDiagonalDifferenceCheckBox.Value
                    kernel=kernel+[0,0,-1;0,2,0;-1,0,0];
                    count=1;
                end
                if count==0
                    app.PleaseselectanoptionLabel.Visible="on";
                    app.OriginalImageButton.Visible='off';
                    app.Image.Visible="off";
                else
                    app.PleaseselectanoptionLabel.Visible="off";
                    tam_w=size(kernel);
                    rows_w=tam_w(1);
                    cols_w=tam_w(2);
                    l=rows_w*cols_w;
                    cpad=floor(cols_w/2);
                    rpad=floor(rows_w/2);
                    hcorig=cpad+1;
                    hrorig=rpad+1;
                    if is_gray
                        m_prueba=I;
                        aux=zeros(r+2*rpad,c+2*cpad);
                        aux(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_prueba;
                        result=zeros(r,c);
                        aux2=zeros(rows_w,cols_w);
                        suma=0;
                        for i=hrorig:hrorig+r-1
                            for j=hcorig:hcorig+c-1
                                aux2=aux(i-rpad:i+rpad,j-cpad:j+rpad);            
                                %convolution
                                for w=1:rows_w
                                    for y=1:cols_w
                                        suma=(aux2(w,y)*kernel(w,y))+suma;
                                    end
                                end
                                result(i-rpad,j-cpad)=suma;
                                suma=0;
                            end
                        end
                    app.new_I=result;
                    app.new_I=imadjust(app.new_I);
                    app.Image.Visible="on";
                    app.Image.ImageSource=repmat(app.new_I, 1, 1, 3);
                    %imshow(app.new_I,app.Image);
                    app.OriginalImageButton.Visible='on';
                    app.UpdateImageButton.Visible="on";
                    else
                        m_R=I(:,:,1);
                        m_G=I(:,:,2);
                        m_B=I(:,:,3);
                        aux_r=zeros(r+2*rpad,c+2*cpad);
                        aux_r(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_R;
                        aux_g=zeros(r+2*rpad,c+2*cpad);
                        aux_g(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_G;
                        aux_b=zeros(r+2*rpad,c+2*cpad);
                        aux_b(hrorig:hrorig+r-1,hcorig:hcorig+c-1)=m_B;
                        result_r=zeros(r,c);
                        result_g=zeros(r,c);
                        result_b=zeros(r,c);
                        aux2_r=zeros(rows_w,cols_w);
                        aux2_g=zeros(rows_w,cols_w);
                        aux2_b=zeros(rows_w,cols_w);
                        suma_r=0;
                        suma_g=0;
                        suma_b=0;
                        for i=hrorig:hrorig+r-1
                            for j=hcorig:hcorig+c-1    
                                aux2_r=aux_r(i-rpad:i+rpad,j-cpad:j+rpad);
                                aux2_g=aux_g(i-rpad:i+rpad,j-cpad:j+rpad);
                                aux2_b=aux_b(i-rpad:i+rpad,j-cpad:j+rpad);            
                                %convolution
                                for w=1:rows_w
                                    for y=1:cols_w
                                        suma_r=(aux2_r(w,y)*kernel(w,y))+suma_r;
                                        suma_g=(aux2_g(w,y)*kernel(w,y))+suma_g;
                                        suma_b=(aux2_b(w,y)*kernel(w,y))+suma_b;
                                    end
                                end
                                result_r(i-rpad,j-cpad)=suma_r;
                                suma_r=0;
                                result_g(i-rpad,j-cpad)=suma_g;
                                suma_g=0;
                                result_b(i-rpad,j-cpad)=suma_b;
                                suma_b=0;
                            end
                        end
                    app.new_I(:,:,1)=result_r;
                    app.new_I(:,:,2)=result_g;
                    app.new_I(:,:,3)=result_b;
                    app.new_I=rgb2gray(app.new_I);
                    app.new_I=imadjust(app.new_I);
                    app.Image.Visible="on";
                    app.Image.ImageSource=repmat(app.new_I, 1, 1, 3);
                    app.OriginalImageButton.Visible='on';
                    app.UpdateImageButton.Visible="on";
                    end
                end

            end

        end

        % Value changed function: BlurButton
        function BlurButtonValueChanged(app, event)
            value = app.BlurButton.Value;
            app.OriginalImageButton.Visible='off';
            app.PleaseselectanoptionLabel.Visible="off";
            app.IntroduceanaturalnumberLabel.Visible="off";
            if value==0
                app.ChooseSizeofKernelforBlurringButtonGroup.Visible='off';
                app.ExecuteButton.Visible='off';
                app.Image.Visible="off";
                app.UpdateImageButton.Visible="off";
            else
                app.EdgeDetectionButton.Value=0;
                app.ChooseEdgesButtonGroup.Visible='off';
                app.Image.Visible="off";
                app.ExecuteButton.Visible='on';
                app.ChooseSizeofKernelforBlurringButtonGroup.Visible='on';
                app.UpdateImageButton.Visible="off";
            end
        end

        % Value changed function: EdgeDetectionButton
        function EdgeDetectionButtonValueChanged(app, event)
            value = app.EdgeDetectionButton.Value;
            app.OriginalImageButton.Visible='off';
            app.PleaseselectanoptionLabel.Visible="off";
            app.IntroduceanaturalnumberLabel.Visible="off";
            if value==0
                app.ChooseEdgesButtonGroup.Visible='off';
                app.ExecuteButton.Visible='off';
                app.Image.Visible="off";
                app.UpdateImageButton.Visible="off";
            else
                app.BlurButton.Value=0;
                app.ChooseSizeofKernelforBlurringButtonGroup.Visible='off';
                app.ExecuteButton.Visible='on';
                app.ChooseEdgesButtonGroup.Visible='on';
                app.Image.Visible="off";
                app.UpdateImageButton.Visible="off";
            end
        end

        % Value changed function: OriginalImageButton
        function OriginalImageButtonValueChanged(app, event)
            value = app.OriginalImageButton.Value;
            I = getimage(app.h);
            is_gray=true;
            if length(size(I))==3
                is_gray=false;
            end
            is_gray2=true;
            if length(size(app.new_I))==3
                is_gray2=false;
            end
            
            if value==0
                if is_gray2
                    app.Image.ImageSource=repmat(app.new_I, 1, 1, 3);
                else
                    app.Image.ImageSource=app.new_I;
                end
            else                
                if is_gray
                    app.Image.ImageSource=repmat(I, 1, 1, 3);
                else
                    app.Image.ImageSource=I;
                end
            end
        end

        % Button pushed function: UpdateImageButton
        function UpdateImageButtonPushed(app, event)
            windowName = app.h.Name;
            if app.BlurButton.Value
                figure("Name",strcat(windowName,"_Blurred"));
            else
                figure("Name",strcat(windowName,"_EdgeDetected"));
            end
            imshow(app.new_I,[]);
            app.parentApp.updateImageList();
            app.UpdateImageButton.Visible="off";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BlurEdgeDetectorAppLabel = uilabel(app.UIFigure);
            app.BlurEdgeDetectorAppLabel.Text = 'Blur / Edge Detector App';
            app.ChooseEdgesButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseEdgesButtonGroup.Title = 'Choose Edges';
            app.VerticalDifferenceCheckBox = uicheckbox(app.ChooseEdgesButtonGroup);
            app.VerticalDifferenceCheckBox.Text = 'Vertical Difference';
            app.HorizontalDifferenceCheckBox = uicheckbox(app.ChooseEdgesButtonGroup);
            app.HorizontalDifferenceCheckBox.Text = 'Horizontal Difference';
            app.RightDiagonalDifferenceCheckBox = uicheckbox(app.ChooseEdgesButtonGroup);
            app.RightDiagonalDifferenceCheckBox.Text = 'Right Diagonal Difference';
            app.LeftDiagonalDifferenceCheckBox = uicheckbox(app.ChooseEdgesButtonGroup);
            app.LeftDiagonalDifferenceCheckBox.Text = 'Left Diagonal Difference';
            app.Label = uilabel(app.ChooseEdgesButtonGroup);
            app.Label.Text = {'↘'; ''; ''; ''};
            app.Label_2 = uilabel(app.ChooseEdgesButtonGroup);
            app.Label_2.Text = {'↙'; ''; ''};
            app.Label_3 = uilabel(app.ChooseEdgesButtonGroup);
            app.Label_3.Text = {'↔'; ''; ''; ''};
            app.Label_4 = uilabel(app.ChooseEdgesButtonGroup);
            app.Label_4.Text = {'↕'; ''};
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.BlurButton = uibutton(app.UIFigure, 'state');
            app.BlurButton.ValueChangedFcn = createCallbackFcn(app, @BlurButtonValueChanged, true);
            app.BlurButton.Text = 'Blur';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'state');
            app.EdgeDetectionButton.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionButtonValueChanged, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.ChooseanoptionLabel = uilabel(app.UIFigure);
            app.ChooseanoptionLabel.Text = 'Choose an option:';
            app.ChooseSizeofKernelforBlurringButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseSizeofKernelforBlurringButtonGroup.Title = 'Choose Size of Kernel for Blurring';
            app.SIZEEditFieldLabel = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.SIZEEditFieldLabel.Text = 'SIZE';
            app.SIZEEditField = uieditfield(app.ChooseSizeofKernelforBlurringButtonGroup, 'numeric');
            app.Label_5 = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.Label_5.Text = '*';
            app.Label_6 = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.Label_6.Text = '*';
            app.ItrepresentthesizeofthesquareLabel = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.ItrepresentthesizeofthesquareLabel.Text = 'It represent the size of the square  ';
            app.matrixusedasKernelformovingLabel = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.matrixusedasKernelformovingLabel.Text = 'matrix used as Kernel for moving ';
            app.averageconvolutionIncreasetheLabel = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.averageconvolutionIncreasetheLabel.Text = 'average convolution. Increase the';
            app.valueforablurrierefectLabel = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.valueforablurrierefectLabel.Text = 'value for a blurrier efect. ';
            app.IntroduceanaturalnumberLabel = uilabel(app.ChooseSizeofKernelforBlurringButtonGroup);
            app.IntroduceanaturalnumberLabel.Text = 'Introduce a natural number';
            app.Image = uiimage(app.UIFigure);
            app.OriginalImageButton = uibutton(app.UIFigure, 'state');
            app.OriginalImageButton.ValueChangedFcn = createCallbackFcn(app, @OriginalImageButtonValueChanged, true);
            app.OriginalImageButton.Text = 'Original Image';
            app.PleaseselectanoptionLabel = uilabel(app.UIFigure);
            app.PleaseselectanoptionLabel.Text = 'Please select an option';
            app.UpdateImageButton = uibutton(app.UIFigure, 'push');
            app.UpdateImageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateImageButtonPushed, true);
            app.UpdateImageButton.Text = 'Update Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL8_Blur_EdgeDetectortApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end