classdef CeuL8_SpatialConvolutionByShiftingMultiplyAddApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ComputespatialconvolutionbyshiftingmultiplyandaddLabel  matlab.ui.control.Label
        ImageLabel           matlab.ui.control.Label
        KernelDropDownLabel  matlab.ui.control.Label
        KernelDropDown       matlab.ui.control.DropDown
        NxNLabel             matlab.ui.control.Label
        NxNSlider            matlab.ui.control.Slider
        OriginalImage        matlab.ui.control.UIAxes
        kernel               matlab.ui.control.Table
        ShowImage            matlab.ui.control.UIAxes
        ExportButton         matlab.ui.control.Button
    end

    % Author: Carlos Oscar Sorzano
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        normsumImagen
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            
            axis(app.OriginalImage,'off');
            I = getimage(app.h);
            image(app.OriginalImage, I);
            
            axis(app.ShowImage,'off');
            image(app.ShowImage, I);
            app.NxNSlider.Visible=true;
            
            app.NxNSlider.Value=3;
            app.kernel.Data=[1 2 1; 2 4 2; 1 2 1];
            
        end

        % Value changed function: KernelDropDown
        function KernelDropDownValueChanged(app, event)
            
            I = getimage(app.h);
            sumImagen= zeros(size(I));
            idx = find(strcmp(app.KernelDropDown.Items, ...
                app.KernelDropDown.Value));
            if idx==1
                app.NxNSlider.Visible=true;
                app.NxNSlider.Value=3;
                app.kernel.Data=[1 2 1; 2 4 2; 1 2 1];
                kernel = [1 2 1; 2 4 2; 1 2 1];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
            end
            if idx==2
                app.NxNSlider.Visible=false;
                app.kernel.Data=[3 3 2 3 3; 3 2 1 2 3; 2 1 0 1 2; 3 2 1 2 3; 3 3 2 3 3];
                kernel=[3 3 2 3 3; 3 2 1 2 3; 2 1 0 1 2; 3 2 1 2 3; 3 3 2 3 3];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
            end
            if idx==3
                app.NxNSlider.Visible=false;
                app.kernel.Data=[0 0 -1 0 0; 0 -1 -2 -1 0; -1 -2 -3 -2 -1; 0 -1 -2 -1 0; 0 0 -1 0 0];
                kernel = [0 0 -1 0 0; 0 -1 -2 -1 0; -1 -2 -3 -2 -1; 0 -1 -2 -1 0; 0 0 -1 0 0];
                operat(kernel);
                app.normsumImagen=uint8(abs(sumImagen)/max(abs(sumImagen(:)))*255);
                app.normsumImagen=255-app.normsumImagen;
                image(app.ShowImage,app.normsumImagen);
            end
            if idx==4
                app.NxNSlider.Visible=false;
                app.kernel.Data=[1 2 1; 0 0 0; -1 -2 -1];
                kernel = [1 2 1; 0 0 0; -1 -2 -1];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==5
                app.NxNSlider.Visible=false;
                app.kernel.Data=[1 0 -1; 2 0 -2; 1 0 -1];
                kernel = [1 0 -1; 2 0 -2; 1 0 -1];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==6
                app.NxNSlider.Visible=false;
                app.kernel.Data=[0 1 0; -1 0 1; 0 -1 0];
                kernel = [0 1 0; -1 0 1; 0 -1 0];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==7
                app.NxNSlider.Visible=false;
                app.kernel.Data=[0 -1 0; -1 4 -1; 0 -1 0];
                kernel = [0 -1 0; -1 4 -1; 0 -1 0];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==8
                app.NxNSlider.Visible=false;
                app.kernel.Data=[-1 -1 -1; -1 8 -1; -1 -1 -1];
                kernel = [-1 -1 -1; -1 8 -1; -1 -1 -1];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==9
                app.NxNSlider.Visible=false;
                app.kernel.Data=[0 -1 0; -1 10 -1; 0 -1 0];
                kernel = [0 -1 0; -1 10 -1; 0 -1 0];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==10
                app.NxNSlider.Visible=false;
                app.kernel.Data=[-1 0 -1; 0 7 0; -1 0 -1];
                kernel = [-1 0 -1; 0 7 0; -1 0 -1];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==11
                app.NxNSlider.Visible=false;
                app.kernel.Data=[-1 0 0; 0 0 0; 0 0 1];
                kernel = [-1 0 0; 0 0 0; 0 0 1];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==12
                app.NxNSlider.Visible=false;
                app.kernel.Data=[-1 -1 0; -1 0 1; 0 1 1];
                kernel = [-1 -1 0; -1 0 1; 0 1 1];
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==13
                app.NxNSlider.Visible=false;
                app.kernel.Data=[0 0 0; 0 -1 0; 0 0 0];
                kernel = [0 0 0; 0 -1 0; 0 0 0];
                operat(kernel);
                app.normsumImagen=uint8(abs(sumImagen)/max(abs(sumImagen(:)))*255);
                app.normsumImagen=255-app.normsumImagen;
                image(app.ShowImage,app.normsumImagen);
                
            end
            if idx==14
                app.NxNSlider.Visible=true;
                app.NxNSlider.Value=3;
                kernel= randi([0, 10], [3,3]);
                app.kernel.Data=kernel;
                operat(kernel);
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
                
            end
            
            function operat(kernel)
                %take the size of the kernell
                N=size(kernel,1);
                %Divide in two the size of the kernel as we want to move to the right
                   %and to the left.
                N_2=floor(N/2);
                for deltay=-N_2:N_2 
                    for deltax=-N_2:N_2
                        %call the function copImage to get the shifthed image
                        copy= copyImage(I,deltax,deltay);
                        %pass the copied image and the corresponding value of the kernell
                           %for this copy
                        MultiplyAdd(copy,kernel(deltay+N_2+1,deltax+N_2+1));
                    end
                end
            end
            
            
            function Icopy=copyImage(I,deltax,deltay)
                %inicialization of the copied image
                Icopy = zeros(size(I));
                %we take the size of the image
                [Ny,Nx]=size(I);
                %where the copy image is going to go
                %the xto0 and yto0 means where the copied image is begining, the
                   %minimum value to start is 1, so we have to take the max
                   %between 1 and 1+delta, the second one will be taken
                   %when delta is >0
                %the xtoF and ytoF is where the copied image ends, the
                   %maximum value to finish is the size of the original
                   %image as we do not want to go farder. We take the
                   %minimum between ny+deltay (that will be chososen when
                %delta is <0 and Ny
                % So for deltax <0 xt0=1 and xtoF=Nx+deltax
                % So for deltax >0 xt0=1+deltax and xtoF=Nx
                % And the same happends for deltay
                xto0=max(1, 1+ deltax);
                xtoF=min(Nx+deltax,Nx);
                yto0=max(1, 1+ deltay);
                ytoF=min(Ny+deltay,Ny);
                
                %Where the copied image comes from?
                %if deta <0 we are going to take from delta to the end of the original image
                %if delta >0 we start in 1 and we finish in the delta position before the end
                %(End-deltax)(and delta is >0)
                if deltax<0
                    xfrom0=1-deltax;
                    xfromF=Nx;
                else
                    xfrom0=1;
                    xfromF=Nx-deltax;
                end
                
                if deltay<0
                    yfrom0=1-deltay;
                    yfromF=Ny;
                else
                    yfrom0=1;
                    yfromF=(ytoF-yto0+1);
                end
                % We make the copy
                Icopy(yto0:ytoF,xto0:xtoF)= I(yfrom0:yfromF, xfrom0:xfromF);
            end
            
            function MultiplyAdd(I,value)
                %make the multiplication between the interger value in the
                %kernel passed as "value" and the copied image
                mult= I*value;
                %sumImage is a global var so for each for loop of operat we
                %are adding the multiplied image to the other ones
                sumImagen=sumImagen+mult;
            end
        end

        % Value changed function: NxNSlider
        function NxNSliderValueChanged(app, event)
            I = getimage(app.h);
            sumImagen= zeros(size(I));
            idx = find(strcmp(app.KernelDropDown.Items, ...
                app.KernelDropDown.Value));
            app.normsumImagen=zeros(size(I));
            if idx==1
                s=app.NxNSlider.Value;
                if(s<=4)
                    n=3;
                end
                if(s<=6 & s>4)
                    n=5;
                end
                if(s>6)
                    n=7;
                end
                event.Source.Value = n;
                if(n==3)
                    app.kernel.Data=[1 2 1; 2 4 2; 1 2 1];
                    kernel = [1 2 1; 2 4 2; 1 2 1];
                end
                if(n==5)
                    app.kernel.Data= [1 1 2 1 1; 1 2 3 2 1; 2 3 4 3 2; 1 2 3 2 1; 1 1 2 1 1];
                    kernel = [1 1 2 1 1; 1 2 3 2 1; 2 3 4 3 2; 1 2 3 2 1; 1 1 2 1 1];
                end
                if(n==7)
                    app.kernel.Data=[1 1 1 2 1 1 1; 1 1 2 2 2 1 1; 1 2 3 3 3 2 1; 2 2 3 4 3 2 2; 1 2 3 3 3 2 1; 1 1 2 2 2 1 1;1 1 1 2 1 1 1];
                    kernel = [1 1 1 2 1 1 1; 1 1 2 2 2 1 1; 1 2 3 3 3 2 1; 2 2 3 4 3 2 2; 1 2 3 3 3 2 1; 1 1 2 2 2 1 1;1 1 1 2 1 1 1];
                end
                operat(kernel)
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
            end
            if idx==14
                s=app.NxNSlider.Value;
                if(s<=4)
                    n=3;
                end
                if(s<=6 & s>4 )
                    n=5;
                end
                if(s>6)
                    n=7;
                end
                event.Source.Value = n;
                kernel= randi([0, 10], [n,n]);
                app.kernel.Data=kernel;
                operat(kernel)
                app.normsumImagen=uint8(sumImagen/max(sumImagen(:))*255);
                image(app.ShowImage,app.normsumImagen);
            end
            
            function operat(kernel)
                N=size(kernel,1);
                N_2=floor(N/2);
                for deltay=-N_2:N_2
                    for deltax=-N_2:N_2
                        copy= copyImage(I,deltax,deltay);
                        MultiplyAdd(copy,kernel(deltay+N_2+1,deltax+N_2+1));
                    end
                end
            end
            function Icopy=copyImage(I,deltax,deltay)
                Icopy = zeros(size(I));
                [Ny,Nx]=size(I);
                xto0=max(1, 1+ deltax);
                xtoF=min(Nx+deltax,Nx);
                yto0=max(1, 1+ deltay);
                ytoF=min(Ny+deltay,Ny);
                
                if deltax<0
                    xfrom0=1-deltax;
                    xfromF=Nx;
                else
                    xfrom0=1;
                    xfromF=Nx-deltax;
                end
                
                if deltay<0
                    yfrom0=1-deltay;
                    yfromF=Ny;%sumImagenar aqui deltay?
                else
                    yfrom0=1;
                    yfromF=(ytoF-yto0+1);
                end
                
                Icopy(yto0:ytoF,xto0:xtoF)= I(yfrom0:yfromF, xfrom0:xfromF);
            end
            
            function MultiplyAdd(I,value)
                mult= I*value;
                sumImagen=sumImagen+mult;
            end
            
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            figure;
            image(uint8(app.normsumImagen));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComputespatialconvolutionbyshiftingmultiplyandaddLabel = uilabel(app.UIFigure);
            app.ComputespatialconvolutionbyshiftingmultiplyandaddLabel.Text = 'Compute spatial convolution by shifting, multiply and add';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.KernelDropDownLabel = uilabel(app.UIFigure);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.UIFigure);
            app.KernelDropDown.Items = {'NxN BLur', '5x5 Unverted Blur', '5x5 Negative Blur', '3x3 Edge North', '3x3 Edge West', '3x3 Edge Diagonal', '3x3 Laplacian 4', '3x3 Laplacian 8', '3x3 Laplacian 10', '3x3 Laplacian 7', '3x3 Emboss', '3x3 Embos Heavy', '3x3 Negative', 'NxN Random'};
            app.KernelDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelDropDownValueChanged, true);
            app.KernelDropDown.Value = 'NxN BLur';
            app.NxNLabel = uilabel(app.UIFigure);
            app.NxNLabel.Text = 'NxN  ';
            app.NxNSlider = uislider(app.UIFigure);
            app.NxNSlider.Limits = [3 7];
            app.NxNSlider.MajorTicks = [3 5 7];
            app.NxNSlider.MajorTickLabels = {'3', '5', '7'};
            app.NxNSlider.ValueChangedFcn = createCallbackFcn(app, @NxNSliderValueChanged, true);
            app.NxNSlider.MinorTicks = [];
            app.NxNSlider.Value = 3;
            app.OriginalImage = uiaxes(app.UIFigure);
            title(app.OriginalImage, 'Original')
            xlabel(app.OriginalImage, 'X')
            ylabel(app.OriginalImage, 'Y')
            app.kernel = uitable(app.UIFigure);
            app.kernel.ColumnName = {'0'; '1'; '2'; '3'; '4'; '5'; '6'};
            app.kernel.RowName = {'0'; '1'; '2'; '3'; '4'; '5'; '6'};
            app.kernel.ColumnSortable = false;
            app.kernel.ColumnEditable = false;
            app.ShowImage = uiaxes(app.UIFigure);
            title(app.ShowImage, 'Output Image')
            xlabel(app.ShowImage, 'X')
            ylabel(app.ShowImage, 'Y')
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL8_SpatialConvolutionByShiftingMultiplyAddApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end