classdef CeuL9_GaussianFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FilteredImage              matlab.ui.control.Image
        OriginalImage              matlab.ui.control.Image
        FilterPowerSpectrum        matlab.ui.control.Image
        GaussianFilterG2Label      matlab.ui.control.Label
        OriginalimageLabel         matlab.ui.control.Label
        ImpulseresponseLabel       matlab.ui.control.Label
        FilteredimageLabel         matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        FiltertypeTab              matlab.ui.container.Tab
        SelectTypeButtonGroup      matlab.ui.container.ButtonGroup
        HighpassButton             matlab.ui.control.RadioButton
        BandpassButton             matlab.ui.control.RadioButton
        LowpassButton              matlab.ui.control.RadioButton
        ParametersTab              matlab.ui.container.Tab
        sigmaSpinnerLabel          matlab.ui.control.Label
        sigmaSpinner               matlab.ui.control.Spinner
        LowerEditFieldLabel        matlab.ui.control.Label
        LowerEditField             matlab.ui.control.NumericEditField
        HigherEditFieldLabel       matlab.ui.control.Label
        HigherEditField            matlab.ui.control.NumericEditField
        BandpasssigmasLabel        matlab.ui.control.Label
        PSDTab                     matlab.ui.container.Tab
        TypeButtonGroup            matlab.ui.container.ButtonGroup
        AbsoluteButton             matlab.ui.control.RadioButton
        LogarithmicButton          matlab.ui.control.RadioButton
        ModuletypeTab              matlab.ui.container.Tab
        ModuleRep                  matlab.ui.container.ButtonGroup
        LinearModule               matlab.ui.control.RadioButton
        LogarithmicModule          matlab.ui.control.RadioButton
        ExporttootherwindowButton  matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        ModuleH                    matlab.ui.control.UIAxes
        PhaseH                     matlab.ui.control.UIAxes
    end

    % Author: Aïcha Díez de Benito
    
    properties (Access = private)
        parentApp
        h   %figure handler
        sigma  % filter size parameter 
        FTfilter % real-time impulse response
        gaussian
        channel
        wlow        %cutting frequencies
        whi
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;  % Image handler
            app.UIFigure.Name = "Lecture 09. Gaussian filter App";
            app.OriginalImage.ImageSource = app.h.Name;
            
        end

        % Selection changed function: SelectTypeButtonGroup
        function TypeFilterSelectionChanged(app, event)
            app.LowerEditField.Enable = 'Off';
            app.HigherEditField.Enable = 'Off';
            app.sigmaSpinner.Enable = 'On';
            
            I = getimage(app.h);
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
                r = double(R);
                [M, N] = size(R);
                fftR = fft2(r);
                fftR = fftshift(fftR); %centered FFT
                
                g = double(G);
                fftG = fft2(g);
                fftG = fftshift(fftG); %centered FFT
                
                b = double(B);
                fftB = fft2(b);
                fftB = fftshift(fftB); %centered FFT
            
            X = 0 : N-1;
            Y = 0 : M-1;
            [X, Y] = meshgrid(X, Y);
            deltaX = 0.5 * M;
            deltaY = 0.5 * N;
            
            app.sigma  = app.sigmaSpinner.Value;
            distance = (X - deltaX).^2 + (Y - deltaY).^2;
            app.gaussian = exp(-(distance) ./ (2 *app.sigma).^2); % gaussian function formula
           
            
            if app.LowpassButton.Value
                
                Low_filt = app.gaussian;
                app.FTfilter = Low_filt;
               
            % filtering red channel
                redfiltered_image = imgaussfilt(uint8(R), app.sigma);
             % filtering green channel
                greenfiltered_image = imgaussfilt(uint8(G), app.sigma);
             % filtering blue channel
                bluefiltered_image= imgaussfilt(uint8(B), app.sigma);
                
               filtered_image(:,:,1) = redfiltered_image;
               filtered_image(:,:,2) = greenfiltered_image;
               filtered_image(:,:,3) = bluefiltered_image;
               
             app.FilteredImage.ImageSource = uint8(filtered_image);
                  
                  % impulse response representation by default
                    fL = log(1 + abs(app.FTfilter));
                    impulse = fL/max(fL(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                    app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
                  
                app.channel = round(size(Low_filt,2)/2);
                LPFlinear = Low_filt(app.channel, 1:size(Low_filt,2));
                
                linear = fftshift(fft2(LPFlinear)); % for PSD 
                freqsX = (0:(length(linear)-1))*2*pi/length(linear) - pi;
                
                plot(app.ModuleH, freqsX, abs(linear));
                    app.PhaseH.YScale = 'linear';
                plot(app.PhaseH, freqsX, angle(linear));
                
            end
            
            if app.HighpassButton.Value 
    
                Hi_filt = 1-app.gaussian; % filter in real domain
                app.FTfilter = Hi_filt;
                app.channel = round(size(Hi_filt,2)/2);
                
             % filtering red channel
                redfiltered_image = ifftshift(Hi_filt.*fftR);
                redfiltered_image = ifft2(redfiltered_image);
                redfiltered_image = real(redfiltered_image);
                
             % filtering green channel
                greenfiltered_image = ifftshift(Hi_filt.*fftG);
                greenfiltered_image = ifft2(greenfiltered_image);
                greenfiltered_image = real(greenfiltered_image);
                
             % filtering blue channel
                bluefiltered_image = ifftshift(Hi_filt.*fftB);
                bluefiltered_image = ifft2(bluefiltered_image);
                bluefiltered_image = real(bluefiltered_image);


               filtered_image(:,:,1) = redfiltered_image;
               filtered_image(:,:,2) = greenfiltered_image;
               filtered_image(:,:,3) = bluefiltered_image;
             app.FilteredImage.ImageSource = uint8(filtered_image);
                    
                
                % impulse response representation by default
                    fL = log(1 + abs(app.FTfilter));
                    impulse = fL/max(fL(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                    app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
                
                HPFlinear = Hi_filt(app.channel, 1:size(Hi_filt,2));
                linear = fftshift(fft2(HPFlinear)); % for PSD 
                freqsX = (0:(length(linear)-1))*2*pi/length(linear) - pi;
                
                plot(app.ModuleH, freqsX, -1*abs(linear) + max(abs(linear)));
                    app.PhaseH.YScale = 'linear';
                plot(app.PhaseH, freqsX, angle(linear));

            end
            
            if app.BandpassButton.Value % the process will use zero-padding
                app.sigmaSpinner.Enable = 'Off';
                app.LowerEditField.Enable = 'On';
                app.HigherEditField.Enable = 'On';
                    app.wlow = app.LowerEditField.Value;
                    app.whi = app.HigherEditField.Value;
                
                r = double(R);
                [m, n] = size(r);
                fftR = fft2(r, 2*m-1, 2*n-1); % compute 2D FFT of the read image
                fftR = fftshift(fftR); %centered FFT
                
                g = double(G);
                [mg, ng] = size(g);
                fftG = fft2(g, 2*mg-1, 2*ng-1); % compute 2D FFT of the read image
                fftG = fftshift(fftG); %centered FFT
                
                b = double(B);
                [mb, nb] = size(b);
                fftB = fft2(b, 2*mb-1, 2*nb-1); % compute 2D FFT of the read image
                fftB = fftshift(fftB); %centered FFT
                  
                % Filter initialization
                h1 = ones(2*m-1, 2*n-1);
                h2 = ones(2*m-1, 2*n-1);
                h3 = ones(2*m-1, 2*n-1);
                
                for i = 1:2*m-1
                    for j =1:2*n-1
                        dist = sqrt((i-(m+1))^2 + (j-(n+1))^2);
                        h1(i,j) = exp(-dist^2 ./(2*app.whi^2));
                        h2(i,j) = exp(-dist^2 ./(2*app.wlow^2));
                        h3(i,j) = 1.0 - h2(i,j);
                        h3(i,j) = h1(i,j).*h3(i,j);
                    end
                end
                
                app.FTfilter = h3;
                
                % impulse response representation by default
                fL = log(1 + abs(app.FTfilter));
                impulse = fL/max(fL(:));
                app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
                
                
                app.channel = round(size(app.FTfilter,2)/2);
             BPFlinear = app.FTfilter(app.channel, (n+1):size(app.FTfilter,2));
             linear = fftshift(fft2(BPFlinear)); % in FT domain
             
%              real_part = real(linear);
%              real_part(find(real_part ==max(real_part))) = 0;
             
             freqsX = (0:(length(linear)-1))*2*pi/length(linear) - pi;
             
                plot(app.ModuleH, freqsX, abs(linear));
                    app.PhaseH.YScale = 'linear';
                plot(app.PhaseH, freqsX, angle(linear));
             
             
             % filtering red channel
                redfiltered_image = ifftshift(h3.*fftR);
                redfiltered_image = ifft2(redfiltered_image, 2*m-1, 2*n-1);
                redfiltered_image = real(redfiltered_image(1:m, 1:n));
                
             % filtering green channel
                greenfiltered_image = ifftshift(h3.*fftG);
                greenfiltered_image = ifft2(greenfiltered_image, 2*mg-1, 2*ng-1);
                greenfiltered_image = real(greenfiltered_image(1:mg, 1:ng));
                
             % filtering blue channel
                bluefiltered_image = ifftshift(h3.*fftB);
                bluefiltered_image = ifft2(bluefiltered_image, 2*mb-1, 2*nb-1);
                bluefiltered_image = real(bluefiltered_image(1:mb, 1:nb));
                
                
               filtered_image(:,:,1) = redfiltered_image;
               filtered_image(:,:,2) = greenfiltered_image;
               filtered_image(:,:,3) = bluefiltered_image;
             app.FilteredImage.ImageSource = uint8(filtered_image);
                 
             
             end
                
            app.parentApp.updateImageList(); 
                
           
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
       % Filter type selection
            if selectedTab == app.FiltertypeTab

            app.LowerEditField.Enable = 'Off';
            app.HigherEditField.Enable = 'Off';
            app.sigmaSpinner.Enable = 'On';
            
            I = getimage(app.h);
                R = I(:,:,1);
                G = I(:,:,2);
                B = I(:,:,3);
                
                r = double(R);
                [M, N] = size(R);
                fftR = fft2(r);
                fftR = fftshift(fftR); %centered FFT
                
                g = double(G);
                fftG = fft2(g);
                fftG = fftshift(fftG); %centered FFT
                
                b = double(B);
                fftB = fft2(b);
                fftB = fftshift(fftB); %centered FFT
            
            X = 0 : N-1;
            Y = 0 : M-1;
            [X, Y] = meshgrid(X, Y);
            deltaX = 0.5 * M;
            deltaY = 0.5 * N;
            
            app.sigma  = app.sigmaSpinner.Value;
            distance = (X - deltaX).^2 + (Y - deltaY).^2;
            app.gaussian = exp(-(distance) ./ (2 *app.sigma).^2); % gaussian function formula
           
            
            if app.LowpassButton.Value
                
                Low_filt = app.gaussian;
                app.FTfilter = Low_filt;
               
            % filtering red channel
                redfiltered_image = imgaussfilt(uint8(R), app.sigma);
             % filtering green channel
                greenfiltered_image = imgaussfilt(uint8(G), app.sigma);
             % filtering blue channel
                bluefiltered_image= imgaussfilt(uint8(B), app.sigma);
                
               filtered_image(:,:,1) = redfiltered_image;
               filtered_image(:,:,2) = greenfiltered_image;
               filtered_image(:,:,3) = bluefiltered_image;
               
             app.FilteredImage.ImageSource = uint8(filtered_image);
                  
                  % impulse response representation by default
                    fL = log(1 + abs(app.FTfilter));
                    impulse = fL/max(fL(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                    app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
                  
                app.channel = round(size(Low_filt,2)/2);
                LPFlinear = Low_filt(app.channel, 1:size(Low_filt,2));
                
                linear = fftshift(fft2(LPFlinear)); % for PSD 
                freqsX = (0:(length(linear)-1))*2*pi/length(linear) - pi;
                
                plot(app.ModuleH, freqsX, abs(linear));
                    app.PhaseH.YScale = 'linear';
                plot(app.PhaseH, freqsX, angle(linear));
                
            end
            
            if app.HighpassButton.Value 
    
                Hi_filt = 1-app.gaussian; % filter in real domain
                app.FTfilter = Hi_filt;
                app.channel = round(size(Hi_filt,2)/2);
                
             % filtering red channel
                redfiltered_image = ifftshift(Hi_filt.*fftR);
                redfiltered_image = ifft2(redfiltered_image);
                redfiltered_image = real(redfiltered_image);
                
             % filtering green channel
                greenfiltered_image = ifftshift(Hi_filt.*fftG);
                greenfiltered_image = ifft2(greenfiltered_image);
                greenfiltered_image = real(greenfiltered_image);
                
             % filtering blue channel
                bluefiltered_image = ifftshift(Hi_filt.*fftB);
                bluefiltered_image = ifft2(bluefiltered_image);
                bluefiltered_image = real(bluefiltered_image);


               filtered_image(:,:,1) = redfiltered_image;
               filtered_image(:,:,2) = greenfiltered_image;
               filtered_image(:,:,3) = bluefiltered_image;
             app.FilteredImage.ImageSource = uint8(filtered_image);
                    
                
                % impulse response representation by default
                    fL = log(1 + abs(app.FTfilter));
                    impulse = fL/max(fL(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                    app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
                
                HPFlinear = Hi_filt(app.channel, 1:size(Hi_filt,2));
                linear = fftshift(fft2(HPFlinear)); % for PSD 
                freqsX = (0:(length(linear)-1))*2*pi/length(linear) - pi;
                
                plot(app.ModuleH, freqsX, -1*abs(linear) + max(abs(linear)));
                    app.PhaseH.YScale = 'linear';
                plot(app.PhaseH, freqsX, angle(linear));

            end
            
            if app.BandpassButton.Value % the process will use zero-padding
                app.sigmaSpinner.Enable = 'Off';
                app.LowerEditField.Enable = 'On';
                app.HigherEditField.Enable = 'On';
                    app.wlow = app.LowerEditField.Value;
                    app.whi = app.HigherEditField.Value;
                
                r = double(R);
                [m, n] = size(r);
                fftR = fft2(r, 2*m-1, 2*n-1); % compute 2D FFT of the read image
                fftR = fftshift(fftR); %centered FFT
                
                g = double(G);
                [mg, ng] = size(g);
                fftG = fft2(g, 2*mg-1, 2*ng-1); % compute 2D FFT of the read image
                fftG = fftshift(fftG); %centered FFT
                
                b = double(B);
                [mb, nb] = size(b);
                fftB = fft2(b, 2*mb-1, 2*nb-1); % compute 2D FFT of the read image
                fftB = fftshift(fftB); %centered FFT
                  
                % Filter initialization
                h1 = ones(2*m-1, 2*n-1);
                h2 = ones(2*m-1, 2*n-1);
                h3 = ones(2*m-1, 2*n-1);
                
                for i = 1:2*m-1
                    for j =1:2*n-1
                        dist = sqrt((i-(m+1))^2 + (j-(n+1))^2);
                        h1(i,j) = exp(-dist^2 ./(2*app.whi^2));
                        h2(i,j) = exp(-dist^2 ./(2*app.wlow^2));
                        h3(i,j) = 1.0 - h2(i,j);
                        h3(i,j) = h1(i,j).*h3(i,j);
                    end
                end
                
                app.FTfilter = h3;
                
                % impulse response representation by default
                fL = log(1 + abs(app.FTfilter));
                impulse = fL/max(fL(:));
                app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
                
                
                app.channel = round(size(app.FTfilter,2)/2);
             BPFlinear = app.FTfilter(app.channel, (n+1):size(app.FTfilter,2));
             linear = fftshift(fft2(BPFlinear)); % in FT domain
             
%              real_part = real(linear);
%              real_part(find(real_part ==max(real_part))) = 0;
             
             freqsX = (0:(length(linear)-1))*2*pi/length(linear) - pi;
             
                plot(app.ModuleH, freqsX, abs(linear));
                    app.PhaseH.YScale = 'linear';
                plot(app.PhaseH, freqsX, angle(linear));
             
             
             % filtering red channel
                redfiltered_image = ifftshift(h3.*fftR);
                redfiltered_image = ifft2(redfiltered_image, 2*m-1, 2*n-1);
                redfiltered_image = real(redfiltered_image(1:m, 1:n));
                
             % filtering green channel
                greenfiltered_image = ifftshift(h3.*fftG);
                greenfiltered_image = ifft2(greenfiltered_image, 2*mg-1, 2*ng-1);
                greenfiltered_image = real(greenfiltered_image(1:mg, 1:ng));
                
             % filtering blue channel
                bluefiltered_image = ifftshift(h3.*fftB);
                bluefiltered_image = ifft2(bluefiltered_image, 2*mb-1, 2*nb-1);
                bluefiltered_image = real(bluefiltered_image(1:mb, 1:nb));
                
                
               filtered_image(:,:,1) = redfiltered_image;
               filtered_image(:,:,2) = greenfiltered_image;
               filtered_image(:,:,3) = bluefiltered_image;
             app.FilteredImage.ImageSource = uint8(filtered_image);
                 
             
             end
                
            end
            
       % Change filter parameters
            if selectedTab == app.ParametersTab
                app.sigma  = app.sigmaSpinner.Value;
                app.wlow = app.LowerEditField.Value;
                app.whi = app.LowerEditField.Value;
            end
            
       % Filter power spectrum view selection (abs / log)
            if selectedTab == app.PSDTab
                
                if app.LogarithmicButton.Value
                    fL = log(1 + abs(app.FTfilter));
                    impulse = fL/max(fL(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                    app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
                    
                end
                
                if app.AbsoluteButton.Value
                    fA = abs(app.FTfilter);
                    impulse = fA/max(fA(:));
                    app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                    app.Label.Text=strcat(": ", app.AbsoluteButton.Text);
                end
            end    
            
            if selectedTab == app.ModuletypeTab
                
                if app.LinearModule.Value
                    app.ModuleH.YScale = 'linear';
                else 
                    app.ModuleH.YScale = 'log';
                end
                
            end
            
            app.parentApp.updateImageList(); 
        end

        % Button pushed function: ExporttootherwindowButton
        function ExporttootherwindowButtonPushed(app, event)
            windowName = app.h.Name;
            
            fig = figure('Name',strcat(windowName," (Pixel interval matching)"));
            figure(fig);
            imshow(app.FilteredImage.ImageSource)
          
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            
            if app.LogarithmicButton.Value
                fL = log(1 + abs(app.FTfilter));
                impulse = fL/max(fL(:));
                app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                app.Label.Text=strcat(": ", app.LogarithmicButton.Text);
            end
            
            if app.AbsoluteButton.Value
                fA = abs(app.FTfilter);
                impulse = fA/max(fA(:));
                app.FilterPowerSpectrum.ImageSource = repmat(impulse, 1, 1, 3);
                app.Label.Text=strcat(": ", app.AbsoluteButton.Text);
            end
        end

        % Selection changed function: ModuleRep
        function ModuleRepSelectionChanged(app, event)
            
            if app.LinearModule.Value
                app.ModuleH.YScale = 'linear';
            else 
                app.ModuleH.YScale = 'log';
            end
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilteredImage = uiimage(app.UIFigure);
            app.OriginalImage = uiimage(app.UIFigure);
            app.FilterPowerSpectrum = uiimage(app.UIFigure);
            app.GaussianFilterG2Label = uilabel(app.UIFigure);
            app.GaussianFilterG2Label.Text = 'Gaussian Filter:  G(μ,σ^2)';
            app.OriginalimageLabel = uilabel(app.UIFigure);
            app.OriginalimageLabel.Text = 'Original image';
            app.ImpulseresponseLabel = uilabel(app.UIFigure);
            app.ImpulseresponseLabel.Text = 'Impulse response';
            app.FilteredimageLabel = uilabel(app.UIFigure);
            app.FilteredimageLabel.Text = 'Filtered image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FiltertypeTab = uitab(app.TabGroup);
            app.FiltertypeTab.Title = 'Filter type';
            app.SelectTypeButtonGroup = uibuttongroup(app.FiltertypeTab);
            app.SelectTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeFilterSelectionChanged, true);
            app.SelectTypeButtonGroup.Title = 'Select a filter type';
            app.HighpassButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.HighpassButton.Text = 'Highpass';
            app.BandpassButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.BandpassButton.Text = 'Bandpass (specify the frequencies)';
            app.LowpassButton = uiradiobutton(app.SelectTypeButtonGroup);
            app.LowpassButton.Text = 'Lowpass';
            app.LowpassButton.Value = true;
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.sigmaSpinnerLabel = uilabel(app.ParametersTab);
            app.sigmaSpinnerLabel.Text = 'sigma';
            app.sigmaSpinner = uispinner(app.ParametersTab);
            app.sigmaSpinner.LowerLimitInclusive = 'off';
            app.sigmaSpinner.Limits = [0 Inf];
            app.sigmaSpinner.Value = 1;
            app.LowerEditFieldLabel = uilabel(app.ParametersTab);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.ParametersTab, 'numeric');
            app.LowerEditField.Enable = 'off';
            app.LowerEditField.Value = 40;
            app.HigherEditFieldLabel = uilabel(app.ParametersTab);
            app.HigherEditFieldLabel.Text = 'Higher';
            app.HigherEditField = uieditfield(app.ParametersTab, 'numeric');
            app.HigherEditField.Enable = 'off';
            app.HigherEditField.Value = 100;
            app.BandpasssigmasLabel = uilabel(app.ParametersTab);
            app.BandpasssigmasLabel.Text = 'Band-pass sigmas';
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.TypeButtonGroup = uibuttongroup(app.PSDTab);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Select a representation';
            app.AbsoluteButton = uiradiobutton(app.TypeButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.LogarithmicButton = uiradiobutton(app.TypeButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.LogarithmicButton.Value = true;
            app.ModuletypeTab = uitab(app.TabGroup);
            app.ModuletypeTab.Title = 'Module type';
            app.ModuleRep = uibuttongroup(app.ModuletypeTab);
            app.ModuleRep.SelectionChangedFcn = createCallbackFcn(app, @ModuleRepSelectionChanged, true);
            app.ModuleRep.Title = 'Select the representation';
            app.LinearModule = uiradiobutton(app.ModuleRep);
            app.LinearModule.Text = 'Linear';
            app.LinearModule.Value = true;
            app.LogarithmicModule = uiradiobutton(app.ModuleRep);
            app.LogarithmicModule.Text = 'Logarithmic';
            app.ExporttootherwindowButton = uibutton(app.UIFigure, 'push');
            app.ExporttootherwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttootherwindowButtonPushed, true);
            app.ExporttootherwindowButton.Text = 'Export to other window';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ': ';
            app.ModuleH = uiaxes(app.UIFigure);
            title(app.ModuleH, 'Module Impulse response')
            xlabel(app.ModuleH, 'Frequency (rads/sample)')
            ylabel(app.ModuleH, 'Magnitude (dB)')
            app.PhaseH = uiaxes(app.UIFigure);
            title(app.PhaseH, 'Phase Impulse response')
            xlabel(app.PhaseH, 'Frequency (rads/sample)')
            ylabel(app.PhaseH, 'Phase (dB)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL9_GaussianFilterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end