classdef CeuL9_IdealFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        IdealFilterLabel       matlab.ui.control.Label
        ExecuteButton          matlab.ui.control.Button
        ImageLabel             matlab.ui.control.Label
        FilterTypeButtonGroup  matlab.ui.container.ButtonGroup
        LowfilterButton        matlab.ui.control.RadioButton
        HighfilterButton       matlab.ui.control.RadioButton
        BandfilterButton       matlab.ui.control.RadioButton
        Plot_Filter            matlab.ui.control.UIAxes
        UpperFcLabel           matlab.ui.control.Label
        UpperFc_Slider         matlab.ui.control.Slider
        UpdateImageListButton  matlab.ui.control.Button
        Plot_Filter_2          matlab.ui.control.UIAxes
        LowerFcLabel_2         matlab.ui.control.Label
        LowerFc_Slider         matlab.ui.control.Slider
    end

    % Author: Juan Marvin    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
        lfc;      % lower cutoff frequency
        ufc;      % upper cutoff frequency
        size;
        original_image;
        output_image;
        D;      % Euclidean Distance
        FT_img; % 2D fast fourier transform
        FT_imgR;
        FT_imgG;
        FT_imgB;
        filterType;
        type;
        
    end
    
    methods (Access = private)
        
        function showResult(app,G)
            
            if length(size(app.original_image)) == 3
                [I, freqsI] = shiftRGBImage(app,app.original_image);
            else
                [I, freqsI] = shiftBand(app,app.original_image);
            end
            freqsfilterI = (0:(length(G)-1))*2*pi/length(G) - pi;
            
            % PSD          
            figure('Name',"PSD");
            subplot(2,2,1)
            imagesc(freqsI, freqsI, log10(abs(I).^2 ))
            title('Original Image (Module)')
            subplot(2,2,3)
            imagesc(freqsI, freqsI, angle(I))
            title('Original Image (Phase)');
            subplot(2,2,2)
            imagesc(freqsfilterI,freqsfilterI,fftshift(abs(G)));
            title(strcat(app.filterType,' Filtered Image (Module)'))
            subplot(2,2,4)
            imagesc(freqsfilterI,freqsfilterI,fftshift(angle(G)));
            title(strcat(app.filterType,' Filtered Image (Phase)'))
            
            if length(size(app.original_image)) == 3
                figure('Name',strcat(app.filterType,' Filtered Image Bands'));
                subplot(2,2,1)
                imshow(uint8(app.output_image(:,:,1)), [ ]);
                title('Filtered Red Image Band')
                subplot(2,2,2)
                imshow(uint8(app.output_image(:,:,2)), [ ]);
                title('Filtered Blue Image Band')
                subplot(2,2,3)
                imshow(uint8(app.output_image(:,:,3)), [ ]);
                title('Filtered Green Image Band')
                subplot(2,2,4)
                imshow(uint8(app.output_image), [ ]);
                title(strcat(app.filterType," Filtered Image"))
            end
            
            figure('Name',strcat(app.filterType," Filtered Image"));
            subplot(1,2,1)
            imshow(app.original_image);
            title('Original Image')
            subplot(1,2,2)
            imshow(uint8(app.output_image), [ ]);
            title(strcat(app.filterType," Filtered Image"))
        end
        
        function H = filterMask(app)
            lfc = app.LowerFc_Slider.Value;
            ufc = app.UpperFc_Slider.Value;
            lfc=2*lfc^2;
            ufc=2*ufc^2;

            
            if app.LowfilterButton.Value
                H = double(app.D <= ufc);
                
            elseif app.HighfilterButton.Value
                H = double(app.D >= lfc);
                
            elseif app.BandfilterButton.Value
                H0 = double(app.D <= ufc);
                H1 = double(app.D <= lfc);
                H = (H0 - H1);
                
            end
        end
        
        function [shiftImage,freqs] = shiftBand(app,I)
            shiftImage = fftshift(fft2(I));
            freqs = (0:(length(shiftImage)-1))*2*pi/length(shiftImage) - pi;
        end
        
        function showMask(app,H)
            [shiftH,~] = shiftBand(app,H);
            J=(shiftH-min(shiftH(:)))/(max(shiftH(:)-min(shiftH(:))))*255;
            imshow(fftshift(H),[],'Parent',app.Plot_Filter)
            imshow(J,[],'Parent',app.Plot_Filter_2) 
        end
        
        function [I, freqsI] = shiftRGBImage(app,I)
            [shiftImageR,freqsImageR] = shiftBand(app,I(:,:,1));
            [shiftImageG,freqsImageG] = shiftBand(app,I(:,:,2));
            [shiftImageB,freqsImageB] = shiftBand(app,I(:,:,3));
            I = (shiftImageR+shiftImageG+shiftImageB)/3;
            freqsI = (freqsImageR+freqsImageG+freqsImageB)/3;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.original_image = getimage(app.h);
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.type='Bandpass';
            app.UpdateImageListButton.Enable='off';
            
            % 2D fast fourier transform
            if length(size(app.original_image)) == 3
                inputR = app.original_image(:,:,1);
                inputG = app.original_image(:,:,2);
                inputB = app.original_image(:,:,3);
                app.FT_imgR = fft2(double(inputR));
                app.FT_imgG = fft2(double(inputG));
                app.FT_imgB = fft2(double(inputB));
                input_image = rgb2gray(app.original_image);
            else
                input_image = app.original_image; 
                app.FT_img = fft2(double(input_image));
            end
            
            % Saving the size of the input_image in pixels
            [M, N] = size(input_image);
            app.size = max(M,N);
            
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;

            % 2D grid which contains the coordinates of vectors v and u.
            [V, U] = meshgrid(v, u);
            
            % Calculating Euclidean Distance
            app.D = sqrt(U.^2+V.^2);
                 
            app.UpperFc_Slider.Limits=[0,4*pi];
            app.LowerFc_Slider.Limits=[0,4*pi];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            H = filterMask(app);
            % Convolution between the Fourier Transformed
            
            if length(size(app.original_image)) == 3
                GR = H.*app.FT_imgR;
                GG = H.*app.FT_imgG;
                GB = H.*app.FT_imgB;
                app.output_image(:,:,1) = real(ifft2(GR));
                app.output_image(:,:,2) = real(ifft2(GG));
                app.output_image(:,:,3) = real(ifft2(GB));
                G = (GR+GG+GB)/3;
            else
                G = H.*app.FT_img;
                app.output_image = real(ifft2(G));
            end
            
            showMask(app,H)
            app.filterType=strcat("Ideal ",app.type);
            showResult(app,G); 
            app.UpdateImageListButton.Enable='on';
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            if app.LowfilterButton.Value
                app.LowerFc_Slider.Enable='off';
                app.UpperFc_Slider.Enable='on'; 
                app.type='Lowpass';
                
            elseif app.HighfilterButton.Value
                app.UpperFc_Slider.Enable='off';   
                app.LowerFc_Slider.Enable='on';
                app.type='Highpass';
                
            elseif app.BandfilterButton.Value
                app.UpperFc_Slider.Enable='on';   
                app.LowerFc_Slider.Enable='on';
                app.type='Bandpass';
            end
            
            H = filterMask(app); 
            showMask(app,H)
        end

        % Callback function
        function LowerFc_SliderValueChanged(app, event)
            H = filterMask(app);
            showMask(app,H)
        end

        % Value changed function: UpperFc_Slider
        function UpperFc_SliderValueChanged(app, event)
            H = filterMask(app);
            showMask(app,H)
        end

        % Button pushed function: UpdateImageListButton
        function UpdateImageListButtonPushed(app, event)
            figure('Name',strcat(app.filterType," Filtered Image"));
            imshow(app.output_image, [ ]);
            title(strcat(app.filterType," Filtered Image"));
            
            app.parentApp.updateImageList();
            app.UpdateImageListButton.Enable='off';
        end

        % Value changed function: LowerFc_Slider
        function LowerFc_SliderValueChanged2(app, event)
            H = filterMask(app);
            showMask(app,H)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdealFilterLabel = uilabel(app.UIFigure);
            app.IdealFilterLabel.Text = 'Ideal Filter';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowfilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowfilterButton.Text = 'Low filter';
            app.HighfilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighfilterButton.Text = 'High filter';
            app.BandfilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandfilterButton.Text = 'Band filter';
            app.BandfilterButton.Value = true;
            app.Plot_Filter = uiaxes(app.UIFigure);
            title(app.Plot_Filter, 'Convolution Mask FD')
            xlabel(app.Plot_Filter, '')
            ylabel(app.Plot_Filter, '')
            app.UpperFcLabel = uilabel(app.UIFigure);
            app.UpperFcLabel.Text = 'Upper Fc';
            app.UpperFc_Slider = uislider(app.UIFigure);
            app.UpperFc_Slider.Limits = [0 12.5663706143592];
            app.UpperFc_Slider.ValueChangedFcn = createCallbackFcn(app, @UpperFc_SliderValueChanged, true);
            app.UpperFc_Slider.Value = 6;
            app.UpdateImageListButton = uibutton(app.UIFigure, 'push');
            app.UpdateImageListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateImageListButtonPushed, true);
            app.UpdateImageListButton.Text = 'Update Image List';
            app.Plot_Filter_2 = uiaxes(app.UIFigure);
            title(app.Plot_Filter_2, 'Convolution Mask SD')
            xlabel(app.Plot_Filter_2, '')
            ylabel(app.Plot_Filter_2, '')
            app.LowerFcLabel_2 = uilabel(app.UIFigure);
            app.LowerFcLabel_2.Text = 'Lower Fc';
            app.LowerFc_Slider = uislider(app.UIFigure);
            app.LowerFc_Slider.Limits = [0 12.5663706143592];
            app.LowerFc_Slider.ValueChangedFcn = createCallbackFcn(app, @LowerFc_SliderValueChanged2, true);
            app.LowerFc_Slider.Value = 2.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL9_IdealFilterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end