classdef CeuL9_SharpeningbyconvolutionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SharpeningbyconvolutionLabel  matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        ImageLabel                    matlab.ui.control.Label
        AlphaSliderLabel              matlab.ui.control.Label
        AlphaSlider                   matlab.ui.control.Slider
        KernelDropDownLabel           matlab.ui.control.Label
        KernelDropDown                matlab.ui.control.DropDown
        UITable                       matlab.ui.control.Table
    end

    % Author: Pablo Carnero Olavarría    
    
    properties (Access = private)
        parentApp % parent app
        h         % figure handler
    end
    
    methods (Access = public)
        
        function changeValues(app)
            if (strcmp(app.KernelDropDown.Value, "Default")==1)
                values = [-1 -1 -1; -1 8 -1; -1 -1 -1];
                app.UITable.Data = values;
            end
            if (strcmp(app.KernelDropDown.Value, "Vertical edges")==1)
                values = [0 0 0; -1 2 -1; 0 0 0];
                app.UITable.Data = values;
            end
            if (strcmp(app.KernelDropDown.Value, "Horizontal edges")==1)
                values = [0 -1 0; 0 2 0; 0 -1 0];
                app.UITable.Data = values;
            end
            if (strcmp(app.KernelDropDown.Value, "Horizontal + Vertical edges")==1)
                values = [0 -1 0; -1 4 -1; 0 -1 0];
                app.UITable.Data = values;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, h)
            app.parentApp = parentApp;
            app.h = h;
            app.ImageLabel.Text=strcat("Image: ",h.Name);
            app.changeValues();
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            I = getimage(app.h);
            
            values = app.UITable.Data;
            filteredImage = convn(double(I), values, 'same'); 
            figure('Name', "Enhanced");
            imshow(filteredImage); 
            
            alpha = app.AlphaSlider.Value;
            
            J=uint8(imadd(double(I),(double(alpha*filteredImage))));
            figure('Name', "Final image");
            imshow(J)
            

        end

        % Value changed function: KernelDropDown
        function KernelDropDownValueChanged(app, event)
            app.changeValues();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SharpeningbyconvolutionLabel = uilabel(app.UIFigure);
            app.SharpeningbyconvolutionLabel.Text = 'Sharpening by convolution';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image: ';
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [0 1];
            app.KernelDropDownLabel = uilabel(app.UIFigure);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.UIFigure);
            app.KernelDropDown.Items = {'Default', 'Vertical edges', 'Horizontal edges', 'Horizontal + Vertical edges'};
            app.KernelDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelDropDownValueChanged, true);
            app.KernelDropDown.Value = 'Default';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeuL9_SharpeningbyconvolutionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end